classdef pNet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        pNetUIFigure                    matlab.ui.Figure
        APPMenu                         matlab.ui.container.Menu
        ClearSettingMenu                matlab.ui.container.Menu
        LoadResultMenu                  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        HelpDocumentMenu                matlab.ui.container.Menu
        StatisticsButton                matlab.ui.control.Button
        PersonalizedFNButton            matlab.ui.control.Button
        GroupFNButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        DataInputTab                    matlab.ui.container.Tab
        LoadScanListButton              matlab.ui.control.Button
        ScanListandSubjectIDFilesLabel  matlab.ui.control.Label
        ScanListListBox                 matlab.ui.control.ListBox
        SubjectIDListBox                matlab.ui.control.ListBox
        GrayBox1_3                      matlab.ui.control.Label
        fMRIFileLabel                   matlab.ui.control.Label
        BrowsefMRIFileButton            matlab.ui.control.Button
        fMRIFileEditField               matlab.ui.control.EditField
        RemoveSelectedButton            matlab.ui.control.Button
        GrayBox1_2                      matlab.ui.control.Label
        KeepSelectedButton              matlab.ui.control.Button
        ClearListButton                 matlab.ui.control.Button
        BrowseBrainSurfaceButton        matlab.ui.control.Button
        BrainSurfaceTextArea            matlab.ui.control.TextArea
        BrainSurfaceTextAreaLabel       matlab.ui.control.Label
        SummaryTextArea                 matlab.ui.control.TextArea
        SummaryTextAreaLabel            matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        FileNameLabel                   matlab.ui.control.Label
        SearchButton                    matlab.ui.control.Button
        FileNameEditField               matlab.ui.control.EditField
        SearchfMRIFilesLabel            matlab.ui.control.Label
        fMRIFolderLabel                 matlab.ui.control.Label
        BrowsefMRIButton                matlab.ui.control.Button
        fMRIFolderEditField             matlab.ui.control.EditField
        Image4                          matlab.ui.control.Image
        BrowseOverLayButton             matlab.ui.control.Button
        BrowseBrainMaskButton           matlab.ui.control.Button
        GrayBox1_1                      matlab.ui.control.Label
        OverlayImageTextArea            matlab.ui.control.TextArea
        OverlayImageTextAreaLabel       matlab.ui.control.Label
        BrainMaskTextArea               matlab.ui.control.TextArea
        BrainMaskTextAreaLabel          matlab.ui.control.Label
        ScanListTextAreaLabel           matlab.ui.control.Label
        SubjectIDTextAreaLabel          matlab.ui.control.Label
        DataTypeButtonGroup             matlab.ui.container.ButtonGroup
        SurfaceButton                   matlab.ui.control.RadioButton
        VolumeButton                    matlab.ui.control.RadioButton
        DataFormatButtonGroup           matlab.ui.container.ButtonGroup
        MGZSurfacemgzButton             matlab.ui.control.RadioButton
        VolumeniiniigzmatButton         matlab.ui.control.RadioButton
        MGHSurfacemghButton             matlab.ui.control.RadioButton
        HCPSurfaceciftimatButton        matlab.ui.control.RadioButton
        LightGrayBox1_1                 matlab.ui.control.Label
        LightGrayBox1_2                 matlab.ui.control.Label
        LightGrayBox1_3                 matlab.ui.control.Label
        FNComputationTab                matlab.ui.container.Tab
        ParallelComputationButton       matlab.ui.control.Button
        PersonalizedFNButton_2          matlab.ui.control.Button
        GroupFNButton_2                 matlab.ui.control.Button
        LoadNMFResultButton             matlab.ui.control.Button
        ComputeFNTabGroup               matlab.ui.container.TabGroup
        GroupFNTab_2                    matlab.ui.container.Tab
        FileSelectionEditFieldLabel     matlab.ui.control.Label
        Image6_2                        matlab.ui.control.Image
        BootstrapLabel                  matlab.ui.control.Label
        FileSelectionEditField          matlab.ui.control.NumericEditField
        RepetitionEditField             matlab.ui.control.NumericEditField
        RepetitionEditFieldLabel        matlab.ui.control.Label
        BrowseGroupFNButton             matlab.ui.control.Button
        LoadGroupFNFolderEditField      matlab.ui.control.EditField
        ComputeGroupFNCheckBox          matlab.ui.control.CheckBox
        LoadGroupFNFolderLabel          matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.NumericEditField
        BetaEditFieldLabel              matlab.ui.control.Label
        AlphaEditField                  matlab.ui.control.NumericEditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        iterNumEditField                matlab.ui.control.NumericEditField
        iterNumEditFieldLabel           matlab.ui.control.Label
        vxIEditField                    matlab.ui.control.NumericEditField
        vxIEditFieldLabel               matlab.ui.control.Label
        spaREditField                   matlab.ui.control.NumericEditField
        spaREditFieldLabel              matlab.ui.control.Label
        GrayBox1_7                      matlab.ui.control.Label
        GrayBox1_8                      matlab.ui.control.Label
        PersonalizedFNTab_2             matlab.ui.container.Tab
        Image6_3                        matlab.ui.control.Image
        CombinemultiplescansforeachsubjectCheckBox  matlab.ui.control.CheckBox
        alphaLEditField_2               matlab.ui.control.NumericEditField
        alphaLEditField_2Label          matlab.ui.control.Label
        iterNumEditField_3              matlab.ui.control.NumericEditField
        iterNumEditField_3Label         matlab.ui.control.Label
        etaEditField_4                  matlab.ui.control.NumericEditField
        etaEditField_4Label             matlab.ui.control.Label
        ardEditField_2                  matlab.ui.control.NumericEditField
        ardEditField_2Label             matlab.ui.control.Label
        vxIEditField_3                  matlab.ui.control.NumericEditField
        vxIEditField_3Label             matlab.ui.control.Label
        spaREditField_3                 matlab.ui.control.NumericEditField
        spaREditField_3Label            matlab.ui.control.Label
        alphaS21EditField               matlab.ui.control.NumericEditField
        etaEditFieldLabel_3             matlab.ui.control.Label
        GrayBox1_6                      matlab.ui.control.Label
        ParallelComputationTab          matlab.ui.container.Tab
        MemoryGBUsageEstimationEditField  matlab.ui.control.NumericEditField
        MemoryGBUsageEstimationEditFieldLabel  matlab.ui.control.Label
        ofThreadEditField               matlab.ui.control.NumericEditField
        ofThreadEditFieldLabel          matlab.ui.control.Label
        ParallelizationCheckBox         matlab.ui.control.CheckBox
        GrayBox1_5                      matlab.ui.control.Label
        GBRAMLabel                      matlab.ui.control.Label
        ComputerRAMEditField            matlab.ui.control.NumericEditField
        CoreLabel                       matlab.ui.control.Label
        ThiscomputerLabel               matlab.ui.control.Label
        ComputerCoreEditField           matlab.ui.control.NumericEditField
        GrayBox1_4                      matlab.ui.control.Label
        ResultFolderLabel               matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        Image4_2                        matlab.ui.control.Image
        OutputFolderEditField           matlab.ui.control.EditField
        KEditField                      matlab.ui.control.NumericEditField
        KEditFieldLabel                 matlab.ui.control.Label
        RunProcessButton                matlab.ui.control.Button
        ProgressMonitorTextArea         matlab.ui.control.TextArea
        ProgressMonitorTextAreaLabel    matlab.ui.control.Label
        LightGrayBox1_4                 matlab.ui.control.Label
        ProcessLabel                    matlab.ui.control.Label
        NMFProgressSelectionTree        matlab.ui.container.CheckBoxTree
        InitializationNode              matlab.ui.container.TreeNode
        GroupFNNode                     matlab.ui.container.TreeNode
        PersonalizedFNNode              matlab.ui.container.TreeNode
        QualityControlNode              matlab.ui.container.TreeNode
        LightGrayBox1_5                 matlab.ui.control.Label
        SettingLabel                    matlab.ui.control.Label
        GroupFNTab                      matlab.ui.container.Tab
        DefaultViewCenterButton         matlab.ui.control.Button
        DisplayVolumeButtonGroup        matlab.ui.container.ButtonGroup
        VolumeInteractiveButton         matlab.ui.control.RadioButton
        View3Button                     matlab.ui.control.RadioButton
        DisplayButtonGroup              matlab.ui.container.ButtonGroup
        SurfaceInteractiveButton        matlab.ui.control.RadioButton
        View5Button                     matlab.ui.control.RadioButton
        PlayImage                       matlab.ui.control.Image
        InflatedBrainCheckBox           matlab.ui.control.CheckBox
        Slider_3                        matlab.ui.control.Slider
        Image4_3                        matlab.ui.control.Image
        SaveFigureButton                matlab.ui.control.Button
        Slider                          matlab.ui.control.Slider
        FNListBox                       matlab.ui.control.ListBox
        FNListBoxLabel                  matlab.ui.control.Label
        SelectAllButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        PersonalizedFNTab               matlab.ui.container.Tab
        DefaultViewCenterButton_2       matlab.ui.control.Button
        PlayImage_2                     matlab.ui.control.Image
        Slider_4                        matlab.ui.control.Slider
        Image4_4                        matlab.ui.control.Image
        FNListBox_2                     matlab.ui.control.ListBox
        FNListBox_2Label                matlab.ui.control.Label
        SubjectListBox                  matlab.ui.control.ListBox
        SubjectListBoxLabel             matlab.ui.control.Label
        SaveFigureButton_2              matlab.ui.control.Button
        Slider_2                        matlab.ui.control.Slider
        SelectAllButton_2               matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        StatisticsTab                   matlab.ui.container.Tab
        DefaultViewCenterButton_3       matlab.ui.control.Button
        StatisticsTabGroup              matlab.ui.container.TabGroup
        StatisticsTab_2                 matlab.ui.container.Tab
        TestResultListBox               matlab.ui.control.ListBox
        TestResultListBoxLabel          matlab.ui.control.Label
        BehaviorFileEditField           matlab.ui.control.EditField
        Image4_5                        matlab.ui.control.Image
        BehaviorFileLabel               matlab.ui.control.Label
        ComputeStatisticsButton         matlab.ui.control.Button
        pvalueEditField                 matlab.ui.control.NumericEditField
        pvalueEditFieldLabel            matlab.ui.control.Label
        FDRCheckBox                     matlab.ui.control.CheckBox
        TestMethodListBox               matlab.ui.control.ListBox
        TestMethodListBoxLabel          matlab.ui.control.Label
        BrowseBehaviorButton            matlab.ui.control.Button
        DisplayTab                      matlab.ui.container.Tab
        DisplayVolumeButtonGroup_2      matlab.ui.container.ButtonGroup
        VolumeInteractiveButton_2       matlab.ui.control.RadioButton
        View3Button_2                   matlab.ui.control.RadioButton
        DisplayButtonGroup_2            matlab.ui.container.ButtonGroup
        InteractiveButton               matlab.ui.control.RadioButton
        View5Button_2                   matlab.ui.control.RadioButton
        SaveFigureButton_3              matlab.ui.control.Button
        InflatedBrainCheckBox_2         matlab.ui.control.CheckBox
        FNListBox_3Label                matlab.ui.control.Label
        FNListBox_3                     matlab.ui.control.ListBox
        SelectAllButton_3               matlab.ui.control.Button
        PlayImage_3                     matlab.ui.control.Image
        Slider_6                        matlab.ui.control.Slider
        Slider_5                        matlab.ui.control.Slider
        UIAxes_3                        matlab.ui.control.UIAxes
        QualityControlTab               matlab.ui.container.Tab
        PersonalizedFNLabel             matlab.ui.control.Label
        GroupFNLabel                    matlab.ui.control.Label
        SortSubjectCheckBox             matlab.ui.control.CheckBox
        SummaryTextArea_2               matlab.ui.control.TextArea
        SummaryTextArea_2Label          matlab.ui.control.Label
        SubjectListBox_2                matlab.ui.control.ListBox
        SubjectListBox_2Label           matlab.ui.control.Label
        UIAxes_QC_3                     matlab.ui.control.UIAxes
        UIAxes_QC_2                     matlab.ui.control.UIAxes
        UIAxes_QC                       matlab.ui.control.UIAxes
    end

    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % By Yuncong Ma, 1/8/2024
    % pNet
    % Designed to perform NMF computation and visualization
    % NMF computation is based on Homging Li's MATLAB code
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = private)
        Setup % for initialization
        % Each is for one major Tab
        Data_Input % for file list, mask, and template
        FN_Computation % for computing FN
        FNResult % for storing FN results
        Group_FN % for display group FN
        Personalized_FN % for display personalized FN
        Statistics % for correlation between loading of FN and behavior
        QC % for quality control
    end
    
    methods (Access = private)
        
        % Display pregenerated figures All.jpg
        function fDisplay_FN_All(app)
            switch app.Setup.Tab_Axes
                case 'Group_FN'
                    Axes_Label='UIAxes';
                case 'Personalized_FN'
                    Axes_Label='UIAxes_2';
                case 'Statistics'
                    Axes_Label='UIAxes_3';
                otherwise
            end
            cla(app.(Axes_Label),'reset');
            switch app.Setup.Tab_Axes
                case 'Group_FN'
                    if ~exist(fullfile(app.Group_FN.Dir,'All.jpg'),'file')
                        fAPP_Warn_Message(app,['Cannot find the file ',fullfile(app.Group_FN.Dir,'All.jpg')]);
                        return;
                    else
                        app.Group_FN.Figure_Data=imread(fullfile(app.Group_FN.Dir,'All.jpg'));
                    end
                case 'Personalized_FN'
                    if ~exist(fullfile(app.Personalized_FN.Dir,app.SubjectListBox.Value,'All.jpg'),'file')
                        fAPP_Warn_Message(app,['Cannot find the file ',fullfile(app.Personalized_FN.Dir,app.SubjectListBox.Value,'All.jpg')]);
                        return;
                    else
                        app.Personalized_FN.Figure_Data=imread(fullfile(app.Personalized_FN.Dir,app.SubjectListBox.Value,'All.jpg'));
                    end
                case 'Statistics'
                    if ~exist(fullfile(app.Statistics.Test(app.Statistics.Test_ps).Dir,'All.jpg'),'file')
                        fAPP_Warn_Message(app,['Cannot find the file ',fullfile(app.Statistics.Test(app.Statistics.Test_ps).Dir,'All.jpg')]);
                        return;
                    else
                        app.Statistics.Figure_Data=imread(fullfile(app.Statistics.Test(app.Statistics.Test_ps).Dir,'All.jpg'));
                    end
                otherwise
            end
            set(app.(Axes_Label),'outerposition',[11,16,1070,880],'color','k');
            fDisplay_FN_Figure(app);
        end
        
        % Display pregenerated figures [d]+.jpg
        function fDisplay_FN_Selected(app)
            switch app.Setup.Tab_Axes
                case 'Group_FN'
                    Axes_Label='UIAxes';
                    width=1070;
                case 'Personalized_FN'
                    Axes_Label='UIAxes_2'; 
                    width=1070;
                case 'Statistics'
                    Axes_Label='UIAxes_3';
                    width=1038;
                otherwise
            end
            cla(app.(Axes_Label),'reset');           
            set(app.(Axes_Label),'outerposition',[11,16,width,880],'color','k');
            
            N_Image=length(app.FNListBox.Value);
            Image=cell(N_Image,1);
            switch app.Setup.Tab_Axes
                case 'Group_FN'
                    for i=1:N_Image
                        temp=fullfile(app.Group_FN.Dir,[app.FNListBox.Value{i},'.jpg']);
                        if ~exist(temp,'file')
                            fAPP_Warn_Message(app,['Cannot find the file ',temp]);
                            return;
                        else
                            Image{i}=imread(temp);
                        end
                    end
                case 'Personalized_FN'
                    for i=1:N_Image
                        temp=fullfile(app.Personalized_FN.Dir,app.SubjectListBox.Value,[app.FNListBox.Value{i},'.jpg']);
                        if ~exist(temp,'file')
                            fAPP_Warn_Message(app,['Cannot find the file ',temp]);
                            return;
                        else
                            Image{i}=imread(temp);
                        end
                    end
                case 'Statistics'
                    for i=1:N_Image
                        temp=fullfile(app.Statistics.Test(app.Statistics.Test_ps).Dir,[app.FNListBox.Value{i},'.jpg']);
                        if ~exist(temp,'file')
                            fAPP_Warn_Message(app,['Cannot find the file ',temp]);
                            return;
                        else
                            Image{i}=imread(temp);
                        end
                    end
                otherwise
            end
            if N_Image>10
                N_Column=10;
                N_Row=ceil(length(app.FNListBox.Value)/N_Column);
                for i=N_Image+1:(N_Row*N_Column)
                    Image{i}=Image{1}*0;
                end
            else
                N_Column=N_Image;
                N_Row=1;
            end
            switch app.Setup.Tab_Axes
                case 'Group_FN'
                    app.Group_FN.Figure_Data=fAssemble_Image(reshape(Image,[N_Column,N_Row])',{'Interval',[50,5]});
                case 'Personalized_FN'
                    app.Personalized_FN.Figure_Data=fAssemble_Image(reshape(Image,[N_Column,N_Row])',{'Interval',[50,5]});   
                case 'Statistics'
                    app.Statistics.Figure_Data=fAssemble_Image(reshape(Image,[N_Column,N_Row])',{'Interval',[50,5]});
                otherwise
            end
            fDisplay_FN_Figure(app);
        end
        
        % Display precomputed figure
        function fDisplay_FN_Figure(app)
            value=app.Slider.Value;
            switch app.Setup.Tab_Axes
                case 'Group_FN'
                    Axes_Label='UIAxes';
                    x=size(app.Group_FN.Figure_Data,2);% image width
                    y=size(app.Group_FN.Figure_Data,1);
                    w=1070;% panel width
                    h=880;
                case 'Personalized_FN'
                    Axes_Label='UIAxes_2';
                    x=size(app.Personalized_FN.Figure_Data,2);% image width
                    y=size(app.Personalized_FN.Figure_Data,1);
                    w=1070;% panel width
                    h=880;
                case 'Statistics'
                    Axes_Label='UIAxes_3';
                    x=size(app.Statistics.Figure_Data,2);% image width
                    y=size(app.Statistics.Figure_Data,1);
                    w=1038;% panel width
                    h=880;
                otherwise
            end
            

            if length(app.FNListBox.Value)<=10 && (x/y)<(w/h)
                set(app.(Axes_Label),'position',[11,16,round(h*x/y),h]);
                app.Slider.Visible=false;
                app.Slider_2.Visible=false;
                app.Slider_5.Visible=false;
            else
                set(app.(Axes_Label),'position',[11,-(round(w/x*y)-h)+16+(round(w/x*y)-h)*(1-value),w,round(w/x*y)]);
                app.Slider.Visible=true;
                app.Slider_2.Visible=true;
                app.Slider_5.Visible=true;
            end
            switch app.Setup.Tab_Axes
                case 'Group_FN'
                    imagesc(app.(Axes_Label),app.Group_FN.Figure_Data);
                case 'Personalized_FN'
                    imagesc(app.(Axes_Label),app.Personalized_FN.Figure_Data);
                case 'Statistics'
                    imagesc(app.(Axes_Label),app.Statistics.Figure_Data);
                otherwise
            end
            
            axis(app.(Axes_Label),'image');
            app.(Axes_Label).Visible='On';
            app.(Axes_Label).XTick=[];
            app.(Axes_Label).YTick=[];
            zoom(app.(Axes_Label),'off');
        end
        
        % Load precomputed NMF result
        function fLoad_FN_Result(app)
            Message={};
            if ~exist(fullfile(app.FNResult.Dir,'Data_Input','Subject_Folder.txt'),'file')
                Message{end+1}='No Subject_Folder.txt in folder Data_Input';
            end
            if ~exist(fullfile(app.FNResult.Dir,'FN_Computation','Setting.mat'),'file')
                Message{end+1}='No Setting.mat in folder FN_Computation';
            end
            if ~exist(fullfile(app.FNResult.Dir,'Group_FN','FN.mat'),'file')
                Message{end+1}='No result for group-FN';
            end
            if ~exist(fullfile(app.FNResult.Dir,'Personalized_FN'),'dir')
                Message{end+1}='No result for personalized-FN';
            end
            if ~isempty(Message)
                fAPP_Warn_Message(app,Message);
            end
            Flag = fLoad_Initialization(app);
            if Flag
                return
            end
            Flag = fLoad_FN_Computation(app);
            if Flag
                return
            end
            
            if ~exist(fullfile(app.FNResult.Dir,'Group_FN','FN.mat'),'file')
                cla(app.UIAxes,'reset');
                cla(app.UIAxes_2,'reset');
                app.UIAxes.Visible='Off';
                app.UIAxes_2.Visible='Off';
                fApp_Field_Enable(app,{'SelectAllButton','SelectAllButton_2','FNListBox','FNListBox_2','SaveFigureButton','SaveFigureButton_2'},false);
                fApp_Field_Visible(app,{'Slider','Slider_2','Slider_3','Slider_4','DisplayButtonGroup'},false);
            else
                fLoad_Group_FN(app);
                fLoad_Personalized_FN(app);

                % default display
                app.TabGroup.SelectedTab=app.GroupFNTab;
                fAPP_TAB_Button_Color(app)
                fDisplay_FN(app);
            end
            % Statistics
            fLoad_Statistics(app);
        end
        
        
        function fDisplay_FN(app)
            if app.Setup.Debug==1 && ~isdeployed
                %#exclude display
                display([app.Setup.Tab_Axes,':',app.Group_FN.Figure_Selection_Type,':',app.Group_FN.Display_Style]);
            end
            if strcmp(app.Data_Input.Data_Type,'Surface') && strcmp(app.Group_FN.Figure_Selection_Type,'All') && strcmp(app.Group_FN.Display_Style,'5 View')
                fDisplay_FN_All(app);
            elseif strcmp(app.Data_Input.Data_Type,'Surface') && strcmp(app.Group_FN.Figure_Selection_Type,'Select') && strcmp(app.Group_FN.Display_Style,'5 View')
                fDisplay_FN_Selected(app);
            elseif strcmp(app.Data_Input.Data_Type,'Surface') && strcmp(app.Group_FN.Figure_Selection_Type,'All') && strcmp(app.Group_FN.Display_Style,'Interactive')
                fDisplay_FN_3D_Atlas(app);
            elseif strcmp(app.Data_Input.Data_Type,'Surface') && strcmp(app.Group_FN.Figure_Selection_Type,'Select') && strcmp(app.Group_FN.Display_Style,'Interactive')
                fDisplay_FN_3D_FN(app);
            elseif strcmp(app.Data_Input.Data_Type,'Volume') && strcmp(app.Group_FN.Figure_Selection_Type,'All') && strcmp(app.Group_FN.Display_Style,'3 View')
                fDisplay_FN_All(app);
            elseif strcmp(app.Data_Input.Data_Type,'Volume') && strcmp(app.Group_FN.Figure_Selection_Type,'Select') && strcmp(app.Group_FN.Display_Style,'3 View')
                fDisplay_FN_Selected(app);
            elseif strcmp(app.Data_Input.Data_Type,'Volume') && strcmp(app.Group_FN.Figure_Selection_Type,'Select') && strcmp(app.Group_FN.Display_Style,'Interactive')
                fDisplay_Volume_Interactive(app);
            elseif strcmp(app.Data_Input.Data_Type,'Volume') && strcmp(app.Group_FN.Figure_Selection_Type,'All') && strcmp(app.Group_FN.Display_Style,'Interactive')
                fDisplay_Volume_Atlas_Interactive(app);
            else
                error('Unknown setup for display: %s:%s:%s',app.Data_Input.Data_Type,app.Group_FN.Figure_Selection_Type,app.Group_FN.Display_Style);
            end
        end
        
        function fDisplay_FN_3D_FN(app)
            switch app.Setup.Tab_Axes
                case 'Group_FN'
                    Axes_Label='UIAxes';
                case 'Personalized_FN'
                    Axes_Label='UIAxes_2';
                case 'Statistics'
                    Axes_Label='UIAxes_3';
                otherwise
            end
            cla(app.(Axes_Label),'reset');
            switch app.Setup.Tab_Axes
                case 'Group_FN'
                    load(fullfile(app.Group_FN.Dir,'FN.mat'),'FN');
                    app.Group_FN.Figure_Raw_Data=FN;
                    Map=100*app.Group_FN.Figure_Raw_Data(:,str2double(app.FNListBox.Value{1}));
                    threshold=prctile(Map,99);
                    if threshold==0
                        threshold=100;
                    end
                    Color_Function=fColor_Theme('Seed_Map_2',round([threshold/2,threshold]));
                case 'Personalized_FN'
                    load(fullfile(app.Personalized_FN.Dir,app.SubjectListBox.Value,'FN.mat'),'FN');
                    app.Personalized_FN.Figure_Raw_Data=FN;
                    Map=100*app.Personalized_FN.Figure_Raw_Data(:,str2double(app.FNListBox.Value{1}));
                    threshold=prctile(Map,99);
                    if threshold==0
                        threshold=100;
                    end
                    Color_Function=fColor_Theme('Seed_Map_2',round([threshold/2,threshold]));
                case 'Statistics'
                    Statistics=fAPP_Load_MATLAB_Single_Variable(app,fullfile(app.Statistics.Test(app.Statistics.Test_ps).Dir,'Statistics.mat'));
                    ps=str2double(app.FNListBox.Value{1});                
                    Statistics.Result.P_Value(isnan(Statistics.Result.P_Value))=1;
                    if isfield(Statistics.Result,'T_Value') && ~isempty(Statistics.Result.T_Value)
                        Statistics.Result.T_Value(isnan(Statistics.Result.T_Value))=0;
                        Map=Statistics.Result.T_Value(:,ps).*(Statistics.Result.P_Value(:,ps)<=Statistics.PValue);               
                    elseif isfield(Statistics.Result,'Z_Value') && ~isempty(Statistics.Result.Z_Value)
                        Statistics.Result.Z_Value(isnan(Statistics.Result.Z_Value))=0;
                        Map=Statistics.Result.Z_Value(:,ps).*(Statistics.Result.P_Value(:,ps)<=Statistics.PValue);
                    end
                    Max=double(round(10*prctile(abs(Map),99))/10);
                    if Max==0
                        Max=double(round(10*max(Map))/10);
                        if Max==0
                            Max=1;
                        end
                    end
                    Color_Function=fColor_Theme('Seed_Map_3',[0.00001,Max]);
            end

%             set(app.(Axes_Label),'outerposition',[11,16+50,1070,880-100]);           
            set(app.(Axes_Label),'position',[11,16+50,1070,880-50]);
            if strcmp(app.Data_Input.Data_Type,'Surface')
                Map=fHCP_To_32K(Map,app.Data_Input.Brain_Surface.Data.MW.LR);
                if app.Setup.Inflated_Brain==0
                    Shape=app.Data_Input.Brain_Surface.Data.Shape.LR;
                else
                    Shape=app.Data_Input.Brain_Surface.Data.Shape_Inflated.LR;
                end
                app.(Axes_Label).Visible='On';

                Options.Brain_Color=[1,1,1]*0.5;
                Options.Background_Color=[0,0,0];
                Options.CameraViewAngle=6;
                Options.View=[-140,10];
                Options.Material='dull';
                Options.Light=[-70,50;70,50];
                
                Color_Map=squeeze(fColorize(Map,Color_Function));
                ps=(sum(Color_Map,2)==0 | isnan(sum(Color_Map,2)));
                Color_Map(ps,:)=repmat(Options.Brain_Color,[sum(ps),1]);

                axis(app.(Axes_Label),'equal');
                axis(app.(Axes_Label),'off');
                patch(app.(Axes_Label),'vertices',Shape.vertices,'faces',Shape.faces,...
                    'FaceVertexCData',Color_Map,'FaceColor','interp','EdgeColor','none');

                for i=1:size(Options.Light,1)
                    camlight(app.(Axes_Label),Options.Light(i,1),Options.Light(i,2));
                end
                lighting(app.(Axes_Label),'flat');
                material(app.(Axes_Label),Options.Material);
                rotate3d(app.(Axes_Label),'on');
                View=[360-app.Slider_3.Value*360,360*app.Slider.Value];
                set(app.(Axes_Label),'CameraViewAngle',Options.CameraViewAngle,'View',View,'color','k');
            end
        end
        
        function fDisplay_FN_3D_Atlas(app)
            Tab_Axes=app.Setup.Tab_Axes;
            switch Tab_Axes
                case 'Group_FN'
                    Axes_Label='UIAxes';
                case 'Personalized_FN'
                    Axes_Label='UIAxes_2';
                otherwise
            end
            cla(app.(Axes_Label),'reset');
            set(app.(Axes_Label),'position',[11,16+50,1070,880-50]);
            switch Tab_Axes
                case 'Group_FN'
                    load(fullfile(app.Group_FN.Dir,'FN.mat'),'FN');
                    app.(Tab_Axes).Figure_Raw_Data=FN;
                    Map=app.Group_FN.Figure_Raw_Data;
                case 'Personalized_FN'
                    load(fullfile(app.Personalized_FN.Dir,app.SubjectListBox.Value,'FN.mat'),'FN');
                    app.(Tab_Axes).Figure_Raw_Data=FN;
                    Map=app.Personalized_FN.Figure_Raw_Data;
            end
            [~,Map]=max(Map,[],2);

            Map=fHCP_To_32K(Map,app.Data_Input.Brain_Surface.Data.MW.LR);
            if app.Setup.Inflated_Brain==0
                Shape=app.Data_Input.Brain_Surface.Data.Shape.LR;
            else
                Shape=app.Data_Input.Brain_Surface.Data.Shape_Inflated.LR;
            end
            app.(Axes_Label).Visible='On';

            Options.Brain_Color=[1,1,1]*0.5;
            Options.Background_Color=[0,0,0];
            Options.CameraViewAngle=6;
            Options.View=[-140,10];
            Options.Material='dull';
            Options.Light=[-70,50;70,50];

            Color_Function=fColor_Theme('Atlas',app.FNResult.K);

            Color_Map=squeeze(fColorize(Map,Color_Function));
            ps=(sum(Color_Map,2)==0 | isnan(sum(Color_Map,2)));
            Color_Map(ps,:)=repmat(Options.Brain_Color,[sum(ps),1]);

            axis(app.(Axes_Label),'equal');
            axis(app.(Axes_Label),'off');
            patch(app.(Axes_Label),'vertices',Shape.vertices,'faces',Shape.faces,...
                'FaceVertexCData',Color_Map,'FaceColor','interp','EdgeColor','none');

            for i=1:size(Options.Light,1)
                camlight(app.(Axes_Label),Options.Light(i,1),Options.Light(i,2));
            end
            lighting(app.(Axes_Label),'flat');
            material(app.(Axes_Label),Options.Material);
            rotate3d(app.(Axes_Label),'on');
            View=[360-app.Slider_3.Value*360,360*app.Slider.Value];
            set(app.(Axes_Label),'CameraViewAngle',Options.CameraViewAngle,'View',View,'color','k');
        end
        
        function fDisplay_FN_Slider(app)
            switch app.Setup.Tab_Axes
                case 'Group_FN'
                    Axes_Label='UIAxes';
                case 'Personalized_FN'
                    Axes_Label='UIAxes_2';
                case 'Statistics'
                    Axes_Label='UIAxes_3';
                otherwise
            end
            switch app.Group_FN.Display_Style
                case 'Interactive' % Surface
                    View=[360-app.Slider_3.Value*360,360*app.Slider.Value];
                    set(app.(Axes_Label),'View',View,'color','k');
                case {'5 View','3 View'}
                    value=app.Slider.Value;
                    switch app.Setup.Tab_Axes
                        case 'Group_FN'
                            x=size(app.Group_FN.Figure_Data,2);% image width
                            y=size(app.Group_FN.Figure_Data,1);
                            w=1070;% panel width
                            h=880;
                        case 'Personalized_FN'
                            x=size(app.Personalized_FN.Figure_Data,2);% image width
                            y=size(app.Personalized_FN.Figure_Data,1);
                            w=1070;% panel width
                            h=880;
                        case 'Statistics'
                            x=size(app.Statistics.Figure_Data,2);% image width
                            y=size(app.Statistics.Figure_Data,1);
                            w=1038;% panel width
                            h=880;
                    end

                    
                    if length(app.FNListBox.Value)<=10 && (x/y)<(w/h)
                        %set(app.UIAxes,'position',[11,16,round(h*x/y),h]);
                    else
                        set(app.(Axes_Label),'position',[11,-(round(w/x*y)-h)+16+(round(w/x*y)-h)*(1-value),w,round(w/x*y)]);
                    end
                otherwise
                    error('Unknown display style %s\n',app.Group_FN.Display_Style);
            end
        end
        
        function ps=f3D_Slider_Correction(~,ps)
            if abs(ps-0)<0.005 || abs(ps-1)<0.005
                ps=0.005;
            elseif abs(ps-0.25)<0.005
                ps=0.265;
            elseif abs(ps-0.5)<0.005
                ps=0.505;
            elseif abs(ps-0.75)<0.005
                ps=0.755;
            else
            end
            
        end
        
        function fApp_Field_Enable(app,List,Value)
            for i=1:length(List)
                app.(List{i}).Enable=Value;
            end
        end
        
        function fApp_Field_Label_Enable(app,List,Value)
            for i=1:length(List)
                app.([List{i},'Label']).Enable=Value;
            end
        end

        function fApp_Field_Visible(app,List,Value)
            for i=1:length(List)
                app.(List{i}).Visible=Value;
            end
        end
        
        function fData_Input_Next(~)
            
        end
        
        function fAPP_TAB_Button_Color(app)
            Default_Font=0;
            Choose_Font=1;
            Default_Background=0.8;
            Choose_Background=0;
            switch app.Setup.Tab_Axes
                case {'Data_Input','FN_Computation','Quality_Control'}
                    app.GroupFNButton.FontColor=[1,1,1]*Default_Font;
                    app.GroupFNButton.BackgroundColor=[1,1,1]*Default_Background;
                    app.PersonalizedFNButton.FontColor=[1,1,1]*Default_Font;
                    app.PersonalizedFNButton.BackgroundColor=[1,1,1]*Default_Background;
                    app.StatisticsButton.FontColor=[1,1,1]*Default_Font;
                    app.StatisticsButton.BackgroundColor=[1,1,1]*Default_Background;
                case 'Group_FN'
                    app.GroupFNButton.FontColor=[1,1,1]*Choose_Font;
                    app.GroupFNButton.BackgroundColor=[1,1,1]*Choose_Background;
                    app.PersonalizedFNButton.FontColor=[1,1,1]*Default_Font;
                    app.PersonalizedFNButton.BackgroundColor=[1,1,1]*Default_Background;
                    app.StatisticsButton.FontColor=[1,1,1]*Default_Font;
                    app.StatisticsButton.BackgroundColor=[1,1,1]*Default_Background;
                case 'Personalized_FN'
                    app.GroupFNButton.FontColor=[1,1,1]*Default_Font;
                    app.GroupFNButton.BackgroundColor=[1,1,1]*Default_Background;
                    app.PersonalizedFNButton.FontColor=[1,1,1]*Choose_Font;
                    app.PersonalizedFNButton.BackgroundColor=[1,1,1]*Choose_Background;
                    app.StatisticsButton.FontColor=[1,1,1]*Default_Font;
                    app.StatisticsButton.BackgroundColor=[1,1,1]*Default_Background;
                case 'Statistics'
                    app.GroupFNButton.FontColor=[1,1,1]*Default_Font;
                    app.GroupFNButton.BackgroundColor=[1,1,1]*Default_Background;
                    app.PersonalizedFNButton.FontColor=[1,1,1]*Default_Font;
                    app.PersonalizedFNButton.BackgroundColor=[1,1,1]*Default_Background;
                    app.StatisticsButton.FontColor=[1,1,1]*Choose_Font;
                    app.StatisticsButton.BackgroundColor=[1,1,1]*Choose_Background;
            end
        end
        
        function fAPP_Warn_Message(~,Message)
            CreateStruct.Interpreter = 'none';
            CreateStruct.WindowStyle = 'modal';
            CreateStruct.FontSize=16;
            msgbox(Message,'Warning',"Warn",CreateStruct);  
        end

        function fError_Message(~,Message)
            CreateStruct.Interpreter = 'none';
            CreateStruct.WindowStyle = 'modal';
            CreateStruct.FontSize=16;
            msgbox(Message,'Error',"error",CreateStruct);
        end
        
        % Load group FN result
        function fLoad_Group_FN(app)
            app.OutputFolderEditField.Value=app.FNResult.Dir;
            Setting=fAPP_Load_MATLAB_Single_Variable(app,fullfile(app.FNResult.Dir,'FN_Computation','Setting.mat'));
            if isempty(Setting)
                return
            end
            app.FNResult.K=Setting.K;
            app.FN_Computation.K=app.FNResult.K;
            app.KEditField.Value=app.FNResult.K;

            app.Group_FN.Dir=fullfile(app.FNResult.Dir,'Group_FN');
            app.Group_FN.File=fullfile(app.Group_FN.Dir,'FN.mat');
            app.Group_FN.Result=fAPP_Load_MATLAB_Single_Variable(app,app.Group_FN.File);

            
            % Compute FN tab
            app.Image4_2.Enable=true;
            % Group FN tab
            fApp_Field_Enable(app,{'SelectAllButton','FNListBoxLabel','FNListBox','SaveFigureButton'},true);
            switch app.Data_Input.Data_Type
                case 'Surface'
                    fApp_Field_Visible(app,{'Slider','DisplayButtonGroup'},true);
                    fApp_Field_Visible(app,{'Slider_3','PlayImage'},false);
                    app.DisplayButtonGroup.SelectedObject=app.View5Button;
                    fApp_Field_Visible(app,{'DisplayVolumeButtonGroup','DisplayVolumeButtonGroup_2','DefaultViewCenterButton','DefaultViewCenterButton_2','DefaultViewCenterButton_3'},false);
                case 'Volume'
                    app.DisplayVolumeButtonGroup.Position=[1131,285,111,77];
                    fApp_Field_Visible(app,{'DisplayVolumeButtonGroup'},true);
                    app.DisplayVolumeButtonGroup.SelectedObject=app.View3Button;
            end
            app.Setup.Tab_Axes='Group_FN';
            app.Group_FN.Figure_Selection_Type='All';
            app.FNListBox.Items=arrayfun(@(x)num2str(x),1:app.FNResult.K,'Uniformoutput',false);
            app.FNListBox.Value=arrayfun(@(x)num2str(x),1:app.FNResult.K,'Uniformoutput',false);
            app.Slider.Visible=true;
            app.Slider.Value=1;
            if ~isempty(app.Data_Input.Brain_Surface.Data)
                app.SurfaceInteractiveButton.Enable=true;
                app.InflatedBrainCheckBox.Enable=true;
                app.InflatedBrainCheckBox.Visible=true;
            end
            if strcmp(app.Data_Input.Data_Type,'Surface')
                app.Group_FN.Display_Style='5 View';
            else
                app.Group_FN.Display_Style='3 View';
            end
            app.DisplayButtonGroup.SelectedObject=app.View5Button;
            % pFN tab
            app.FNListBox_2.Items=arrayfun(@(x)num2str(x),1:app.FNResult.K,'Uniformoutput',false);
            app.FNListBox_2.Value=app.FNListBox_2.Items;
            % Statistics tab
            app.FNListBox_3.Items=arrayfun(@(x)num2str(x),1:app.FNResult.K,'Uniformoutput',false);
            app.FNListBox_3.Value=app.FNListBox_3.Items;
        end
        
        % Load personalized FN result
        function fLoad_Personalized_FN(app)
            app.Personalized_FN.Dir=fullfile(app.FNResult.Dir,'Personalized_FN');
            Dir=dir(fullfile(app.Personalized_FN.Dir,'**/All.jpg'));
            Dir2=dir(fullfile(app.Personalized_FN.Dir,'**/FN.mat'));
            if isfield(app.Personalized_FN,'Individual')
                app.Personalized_FN=rmfield(app.Personalized_FN,'Individual');
            end
            if isempty(Dir2) || length(Dir)~=length(Dir2) || length(Dir)~=length(app.Data_Input.Subject_Folder_Unique.File_Content)
                if length(Dir)~=length(Dir2)
                    fAPP_Warn_Message(app,'Some personalized FN does have precomputed visualization figures');
                elseif length(Dir)~=length(app.Data_Input.Subject_Folder_Unique.File_Content)
                    fAPP_Warn_Message(app,'Some personalized FN files may be missing');
                end
                app.Personalized_FN.Dir=[];
                cla(app.UIAxes_2,'reset');
                app.UIAxes_2.Visible='Off';
                fApp_Field_Enable(app,{'SelectAllButton_2','FNListBox_2','SubjectListBox','SaveFigureButton_2','Image4_3','Image4_4'},false);
                fApp_Field_Visible(app,{'Slider_2','Slider_4'},false);
            else
                app.Personalized_FN.N_Scan=length(app.Data_Input.Subject_Folder_Unique.File_Content);
                app.Personalized_FN.Individual(1:app.Personalized_FN.N_Scan)=struct('All',[],'FN',[]);
                app.Personalized_FN.Subject_List=app.Data_Input.Subject_Folder_Unique.File_Content;
                app.Personalized_FN.File=cell(app.Personalized_FN.N_Scan,1);
                for i=1:app.Personalized_FN.N_Scan
                    app.Personalized_FN.File{i}=fullfile(app.FNResult.Dir,'Personalized_FN',app.Data_Input.Subject_Folder_Unique.File_Content{i});
                    app.Personalized_FN.Individual(i).All=fullfile(app.Personalized_FN.File{i},'All.jpg');
                    for j=1:app.FNResult.K
                        app.Personalized_FN.Individual(i).FN{j}=fullfile(app.Personalized_FN.File{i},[num2str(j),'.jpg']);
                    end
                end
                
                % Group NMF tab
                app.Image4_3.Enable=true;
                % Individual NMF tab
                fApp_Field_Enable(app,{'SelectAllButton_2','FNListBox_2Label','FNListBox_2','SubjectListBox','SaveFigureButton_2','Image4_4'},true);
                switch app.Data_Input.Data_Type
                    case 'Surface'
                        fApp_Field_Visible(app,{'Slider_2'},true);
                        fApp_Field_Visible(app,{'Slider_4','PlayImage_2'},false);
                    case 'Volume'
                end
                app.Personalized_FN.Figure_Selection_Type='All';
                app.FNListBox_2.Items=arrayfun(@(x)num2str(x),1:app.FNResult.K,'Uniformoutput',false);
                app.FNListBox_2.Value=arrayfun(@(x)num2str(x),1:app.FNResult.K,'Uniformoutput',false);
                app.Slider_2.Value=1;
                app.SubjectListBoxLabel.Enable=true;
                app.SubjectListBox.Items=app.Personalized_FN.Subject_List;
                app.SubjectListBox.Value=app.Personalized_FN.Subject_List{1};
                % Statistics
                app.Statistics.Dir=fullfile(app.FNResult.Dir,'Statistics');
                fApp_Field_Enable(app,{'BehaviorFileEditField','BrowseBehaviorButton','TestMethodListBox','FDRCheckBox','pvalueEditField','ComputeStatisticsButton',...
                    'TestResultListBox'},true);
                % Quality Control
                app.QC.Dir=fullfile(app.FNResult.Dir,'Quality_Control');
                if exist(fullfile(app.QC.Dir,'QC.mat'),'file')
                    fLoad_QC(app);
                else
                    app.QC.Dir=[];
                end
            end
        end
        
        % Manage the TAB botton color in FN_Computation TAB
        function fFN_Computation_TAB_Button_Color(app)
            Default_Font=0;
            Choose_Font=0;
            Default_Background=0.98;
            Choose_Background=.8;
            switch app.Setup.Tab_Axes_FN_Computation
                case 'Group FN'
                    app.GroupFNButton_2.FontColor=[1,1,1]*Choose_Font;
                    app.GroupFNButton_2.BackgroundColor=[1,1,1]*Choose_Background;
                    app.PersonalizedFNButton_2.FontColor=[1,1,1]*Default_Font;
                    app.PersonalizedFNButton_2.BackgroundColor=[1,1,1]*Default_Background;
                    app.ParallelComputationButton.FontColor=[1,1,1]*Default_Font;
                    app.ParallelComputationButton.BackgroundColor=[1,1,1]*Default_Background;
                case 'Personalized FN'
                    app.GroupFNButton_2.FontColor=[1,1,1]*Default_Font;
                    app.GroupFNButton_2.BackgroundColor=[1,1,1]*Default_Background;
                    app.PersonalizedFNButton_2.FontColor=[1,1,1]*Choose_Font;
                    app.PersonalizedFNButton_2.BackgroundColor=[1,1,1]*Choose_Background;
                    app.ParallelComputationButton.FontColor=[1,1,1]*Default_Font;
                    app.ParallelComputationButton.BackgroundColor=[1,1,1]*Default_Background;
                case 'Parallel Computation'
                    app.GroupFNButton_2.FontColor=[1,1,1]*Default_Font;
                    app.GroupFNButton_2.BackgroundColor=[1,1,1]*Default_Background;
                    app.PersonalizedFNButton_2.FontColor=[1,1,1]*Default_Font;
                    app.PersonalizedFNButton_2.BackgroundColor=[1,1,1]*Default_Background;
                    app.ParallelComputationButton.FontColor=[1,1,1]*Choose_Font;
                    app.ParallelComputationButton.BackgroundColor=[1,1,1]*Choose_Background;
            end
        end
        
        function fStatistics_TAB_Button_Color(~)
            
        end
        
        % load and check the brain surface file
        function fLoad_Brain_Surface(app)
            app.Data_Input.Brain_Surface.Data=fAPP_Load_MATLAB_Single_Variable(app,app.Data_Input.Brain_Surface.File);
            Data=app.Data_Input.Brain_Surface.Data;
            if isfield(Data,'MW') && isfield(Data.MW,'LR') && isfield(Data,'Shape') && isfield(Data.Shape,'LR') && isfield(Data,'Shape_Inflated') && isfield(Data.Shape_Inflated,'LR')...
                    && isfield(Data.Shape.LR,'vertices') && isfield(Data.Shape.LR,'faces') && isfield(Data.Shape_Inflated.LR,'vertices') && isfield(Data.Shape_Inflated.LR,'faces')
                
            else
                fError_Message(app,'The brain surface file does not contain necessary components');
                app.Data_Input.Brain_Surface.File=[];
                app.Data_Input.Brain_Surface.Data=[];
                app.BrainSurfaceTextArea.Value='';
                return
            end
            if app.DisplayButtonGroup.Visible
                app.SurfaceInteractiveButton.Enable=true;
                app.InflatedBrainCheckBox.Visible=true;
            end
        end
        
        % Load and check behavior file
        function fLoad_Behavior_File(app,Dir)
            FID=fopen(Dir,'r');
            temp=textscan(FID,'%d\n');
            fclose(FID);
            temp=cell2mat(temp);
            if length(unique(temp))~=2
                fError_Message(app,'The behavior file needs to have only two group labels');
                return
            elseif length(temp(:))~=app.Personalized_FN.N_Scan
                fError_Message(app,'The number of data in behavior file needs to match the scan number in folder Personalized_FN');
                return
            end
            % The first element is reserved for self comparison, 1 sample
            % ttest
            app.Statistics.Behavior=temp;
            [~,app.Statistics.Name]=fileparts(Dir);
            app.BehaviorFileEditField.Value=app.Statistics.Name;
            app.Statistics.Text=app.Statistics.Name;
            app.Statistics.Type='2 Group';
            app.Statistics.Method='2 sample ttest';
            if isempty(app.Statistics.Dir)
                app.Statistics.Dir=fullfile(app.FN_Computation.Dir,'Statistics');
                fMake_Folder(app.Statistics.Dir);
            end
            app.TestMethodListBox.Items={'2 sample ttest','Wilcoxon rank sum test'};
            app.TestMethodListBox.Value=app.Statistics.Method;
            app.FDRCheckBox.Value=0;
            app.pvalueEditField.Value=0.05;
            app.Statistics.Setup.FDR=0;
            app.Statistics.Setup.PValue=0.05;
        end
        
        function fCompute_Statistics(app)
            fMake_Folder(app.Statistics.Dir);
            switch app.Data_Input.Data_Type
                case 'Surface'
                    app.Statistics.Result=fAPP_Statistics(app.Personalized_FN.File,{'Data_Type',app.Data_Input.Data_Type},{'Data_Format',app.Data_Input.Data_Format},...
                        {'Method',app.Statistics.Method},{'FDR',app.Statistics.FDR},{'P_Value',app.Statistics.PValue},{'Behavior',app.Statistics.Behavior});
                case 'Volume'
                    File_Brain_Mask=fullfile(app.FNResult.Dir,'Data_Input','Brain_Mask.mat');
                    app.Statistics.Result=fAPP_Statistics(app.Personalized_FN.File,{'Data_Type',app.Data_Input.Data_Type},{'Data_Format',app.Data_Input.Data_Format},...
                        {'Method',app.Statistics.Method},{'FDR',app.Statistics.FDR},{'P_Value',app.Statistics.PValue},{'Behavior',app.Statistics.Behavior},{'File_Brain_Mask',File_Brain_Mask});
            end
            if app.Setup.Debug==1 && ~isdeployed
                %#exclude display
                display(app.Statistics.Result);
            end
            if ~isempty(app.Statistics.Result)
                app.Statistics.N_Test=app.Statistics.N_Test+1;
                app.Statistics.Test(app.Statistics.N_Test).Result=app.Statistics.Result;
                app.Statistics.Test(end).Name=app.Statistics.Name;
                app.Statistics.Test(end).Behavior=app.Statistics.Behavior;
                app.Statistics.Test(end).Method=app.Statistics.Method;
                app.Statistics.Test(end).FDR=app.Statistics.FDR;
                app.Statistics.Test(end).PValue=app.Statistics.PValue;
                if isempty(app.Statistics.Test(end).Behavior)
                    Title=[app.Statistics.Test(end).Method,',p=',num2str(app.Statistics.Test(end).PValue)];
                else
                    Title=[app.Statistics.Test(end).Name,':',app.Statistics.Test(end).Method,',p=',num2str(app.Statistics.Test(end).PValue)];
                end
                if app.Statistics.Test(end).FDR>0
                    Title=[Title,',FDR'];
                end
                Title=[Title,',Test_',num2str(app.Statistics.N_Test)];
                if isempty(app.TestResultListBox.Items)
                    app.TestResultListBox.Items={};
                end
                app.Statistics.Test(end).Title=Title;
                app.TestResultListBox.Items{app.Statistics.N_Test}=Title;
                app.TestResultListBox.Value=Title;
                app.TestResultListBox.Enable=true;
                % save result
                app.Statistics.Test_ps=app.Statistics.N_Test;
                n_Test=app.Statistics.N_Test;
                Folder_Name=['Test_',num2str(n_Test),'_',app.Statistics.Test(end).Name];
                Folder_Name=fullfile(app.Statistics.Dir,Folder_Name);
                while exist(Folder_Name,'dir')
                    n_Test=n_Test+1;
                    Folder_Name=['Test_',num2str(n_Test),'_',app.Statistics.Test(end).Name];
                    Folder_Name=fullfile(app.Statistics.Dir,Folder_Name);
                end
                app.Statistics.Test(end).Dir=Folder_Name;
                fMake_Folder(Folder_Name);
                fSave_Variable(Folder_Name,{{'Statistics',app.Statistics.Test(end)}});
                % visualization
                fVisualize_Statistics(Folder_Name,{'Data_Type',app.Data_Input.Data_Type},{'Data_Format',app.Data_Input.Data_Format},{'Folder_Result',app.FNResult.Dir});
                % Display tab
                switch app.Data_Input.Data_Type
                    case 'Surface'
                        fApp_Field_Enable(app,{'SelectAllButton_3','FNListBox_3','SaveFigureButton_3'},true);
                        fApp_Field_Visible(app,{'DisplayButtonGroup_2','InflatedBrainCheckBox_2','DisplayButtonGroup_2'},true);
                        app.InflatedBrainCheckBox_2.Enable=true;
                    case 'Volume'
                        fApp_Field_Enable(app,{'SelectAllButton_3','FNListBox_3','SaveFigureButton_3'},true);
                        fApp_Field_Visible(app,{'DisplayVolumeButtonGroup_2'},true);
                end
                app.StatisticsTabGroup.SelectedTab=app.DisplayTab;
                fDisplay_Statistics(app);
            end
        end
 
        function fDisplay_Statistics(app)
            if app.Setup.Debug==1 && ~isdeployed
                %#exclude fprintf
                fprintf([app.Data_Input.Data_Type,',',app.Group_FN.Figure_Selection_Type,',',app.Group_FN.Display_Style,'\n']);
            end
            if strcmp(app.Data_Input.Data_Type,'Surface') && strcmp(app.Group_FN.Figure_Selection_Type,'All') && strcmp(app.Group_FN.Display_Style,'5 View')
                fDisplay_FN_All(app);
            elseif strcmp(app.Data_Input.Data_Type,'Surface') && strcmp(app.Group_FN.Figure_Selection_Type,'Select') && strcmp(app.Group_FN.Display_Style,'5 View')
                fDisplay_FN_Selected(app);
            elseif strcmp(app.Data_Input.Data_Type,'Surface') && strcmp(app.Group_FN.Figure_Selection_Type,'All') && strcmp(app.Group_FN.Display_Style,'Interactive')
                fDisplay_Statistics_3D(app);
            elseif strcmp(app.Data_Input.Data_Type,'Surface') && strcmp(app.Group_FN.Figure_Selection_Type,'Select') && strcmp(app.Group_FN.Display_Style,'Interactive')
                fDisplay_FN_3D_FN(app);
            elseif strcmp(app.Data_Input.Data_Type,'Volume') && strcmp(app.Group_FN.Figure_Selection_Type,'All') && strcmp(app.Group_FN.Display_Style,'3 View')
                fDisplay_FN_All(app);
            elseif strcmp(app.Data_Input.Data_Type,'Volume') && strcmp(app.Group_FN.Figure_Selection_Type,'Select') && strcmp(app.Group_FN.Display_Style,'3 View')
                fDisplay_FN_Selected(app);
            elseif strcmp(app.Data_Input.Data_Type,'Volume') && strcmp(app.Group_FN.Figure_Selection_Type,'All') && strcmp(app.Group_FN.Display_Style,'Interactive')
                fDisplay_Volume_Atlas_Interactive(app);
            elseif strcmp(app.Data_Input.Data_Type,'Volume') && strcmp(app.Group_FN.Figure_Selection_Type,'Select') && strcmp(app.Group_FN.Display_Style,'Interactive')
                fDisplay_Volume_Interactive(app);
            else
                error('Unknown setup for display');
            end
        end
        
        function fLoad_Statistics(app)
            if exist(app.Statistics.Dir,'dir')
                Dir=dir(fullfile(app.Statistics.Dir,'**','Statistics.mat'));
                N_Test=length(Dir);
                if app.Setup.Debug==1 && ~isdeployed
                    %#exclude fprintf
                    fprintf('Find N_Test %d\n',N_Test);
                end
                app.Statistics.N_Test=N_Test;
                app.Statistics.Test_ps=1;
                app.TestResultListBox.Value={};
                app.TestResultListBox.Items={};
                app.Statistics.Test(1:end)=[];
                if N_Test>0
                    for i=1:N_Test
                        Test=fAPP_Load_MATLAB_Single_Variable(app,fullfile(Dir(i).folder,Dir(i).name));
                        app.Statistics.Test(i).Behavior=Test.Behavior;
                        app.Statistics.Test(i).Name=Test.Name;
                        app.Statistics.Test(i).Method=Test.Method;
                        app.Statistics.Test(i).FDR=Test.FDR;
                        app.Statistics.Test(i).PValue=Test.PValue;
                        app.Statistics.Test(i).FDR=Test.FDR;
                        app.Statistics.Test(i).Title=Test.Title;
                        app.Statistics.Test(i).Dir=Dir(i).folder;
                        app.TestResultListBox.Items{i}=Test.Title;
                        if i==1
                            app.TestResultListBox.Value=Test.Title;
                        end
                        
                    end
                end
                switch app.Data_Input.Data_Type
                    case 'Surface'
                        fApp_Field_Enable(app,{'SelectAllButton_3','FNListBox_3','SaveFigureButton_3','InflatedBrainCheckBox_2','Image4_5'},true);
                        fApp_Field_Visible(app,{'DisplayButtonGroup_2','InflatedBrainCheckBox_2','DisplayButtonGroup_2'},true);
                    case 'Volume'
                        fApp_Field_Enable(app,{'SelectAllButton_3','FNListBox_3','SaveFigureButton_3','InflatedBrainCheckBox_2','Image4_5'},true);
                        fApp_Field_Visible(app,{'DisplayVolumeButtonGroup_2'},true);
                end
            end
        end
        
        
        function fPlay_Image(app)
            if strcmp(app.Setup.Group_FN_Play_State,'Stop')
                app.Setup.Group_FN_Play_State='Play';
                app.PlayImage.ImageSource=app.Setup.Group_FN_Play_Icon{1};
                app.PlayImage_2.ImageSource=app.Setup.Group_FN_Play_Icon{1};
                app.PlayImage_3.ImageSource=app.Setup.Group_FN_Play_Icon{1};
            else
                app.Setup.Group_FN_Play_State='Stop';
                app.PlayImage.ImageSource=app.Setup.Group_FN_Play_Icon{2};
                app.PlayImage_2.ImageSource=app.Setup.Group_FN_Play_Icon{2};
                app.PlayImage_3.ImageSource=app.Setup.Group_FN_Play_Icon{2};
                begin=app.Slider_3.Value;
                ps=begin;
                step_size=0.005;
                while 1
                    ps=ps+step_size;
                    if ps>1
                        ps=ps-1;
                    end
                    ps=f3D_Slider_Correction(app,ps);
                    app.Slider_3.Value=ps;
                    app.Slider_4.Value=ps;
                    app.Slider_6.Value=ps;
                    fDisplay_FN_Slider(app);
                    pause(0.05);
                    if ~strcmp(app.Setup.Group_FN_Play_State,'Stop') || sum(strcmp({'Group_FN','Personalized_FN','Statistics'},app.Setup.Tab_Axes))==0 || ~strcmp(app.Group_FN.Display_Style,'Interactive')
                        app.Setup.Group_FN_Play_State='Play';
                        app.PlayImage.ImageSource=app.Setup.Group_FN_Play_Icon{1};
                        break;
                    end
                end
            end
        end
        
        function fDisplay_Statistics_3D(app)
            Axes_Label='UIAxes_3';
            cla(app.(Axes_Label),'reset');
            Statistics=fAPP_Load_MATLAB_Single_Variable(app,fullfile(app.Statistics.Test(app.Statistics.Test_ps).Dir,'Statistics.mat'));
            Statistics.Result.P_Value(isnan(Statistics.Result.P_Value))=0;
            if isfield(Statistics.Result,'T_Value') && ~isempty(Statistics.Result.T_Value)
                Statistics.Result.T_Value(isnan(Statistics.Result.T_Value))=1;
                Map=Statistics.Result.T_Value.*(Statistics.Result.P_Value<=Statistics.PValue);
                Map=max(Map,[],2);
                Max=double(round(10*prctile(abs(Map(:)),99))/10);
            elseif isfield(Statistics.Result,'Z_Value') && ~isempty(Statistics.Result.Z_Value)
                Statistics.Result.Z_Value(isnan(Statistics.Result.Z_Value))=1;
                Map=Statistics.Result.Z_Value.*(Statistics.Result.P_Value<=Statistics.PValue);
                Sign=sign(Map);
                [Map,ps]=max(abs(Map),[],2);
                for i=1:size(Sign,1)
                    Map(i)=Map(i)*Sign(i,ps(i));
                end
                Max=double(round(10*prctile(abs(Map(:)),99))/10);
            end
            if Max==0
                Max=1;
            end
            Color_Function=fColor_Theme('Seed_Map_3',[0.00001,Max]);

            Map=fHCP_To_32K(Map,app.Data_Input.Brain_Surface.Data.MW.LR);
            if app.Setup.Inflated_Brain==0
                Shape=app.Data_Input.Brain_Surface.Data.Shape.LR;
            else
                Shape=app.Data_Input.Brain_Surface.Data.Shape_Inflated.LR;
            end
            app.(Axes_Label).Visible='On';

            Options.Brain_Color=[1,1,1]*0.5;
            Options.Background_Color=[0,0,0];
            Options.CameraViewAngle=6;
            Options.View=[-140,10];
            Options.Material='dull';
            Options.Light=[-70,50;70,50];

            Color_Map=squeeze(fColorize(Map,Color_Function));
            ps=(sum(Color_Map,2)==0 | isnan(sum(Color_Map,2)));
            Color_Map(ps,:)=repmat(Options.Brain_Color,[sum(ps),1]);
            
            set(app.(Axes_Label),'position',[11,16+50,1070,880-50]);
            axis(app.(Axes_Label),'equal');
            axis(app.(Axes_Label),'off');
            patch(app.(Axes_Label),'vertices',Shape.vertices,'faces',Shape.faces,...
                'FaceVertexCData',Color_Map,'FaceColor','interp','EdgeColor','none');

            for i=1:size(Options.Light,1)
                camlight(app.(Axes_Label),Options.Light(i,1),Options.Light(i,2));
            end
            lighting(app.(Axes_Label),'flat');
            material(app.(Axes_Label),Options.Material);
            rotate3d(app.(Axes_Label),'on');
            View=[360-app.Slider_3.Value*360,360*app.Slider.Value];
            set(app.(Axes_Label),'CameraViewAngle',Options.CameraViewAngle,'View',View,'color','k');
        end
        
        % Update Scan_List and provide file summary
        function fLoad_File_List(app,File_List,Subject_ID)
            if app.Setup.Debug==1 && ~isdeployed
                %#exclude display
                display(File_List);
                %#exclude display
                display(Subject_ID);
            end
            if size(File_List,1)>1
                File_List=File_List';
            end
            if iscell(Subject_ID) && size(Subject_ID,1)>1
                Subject_ID=Subject_ID';
            end
            if iscell(Subject_ID)
                app.Data_Input.Scan_List.File_Content(1,end+1:end+length(File_List))=File_List;
                app.Data_Input.Subject_ID.File_Content(1,end+1:end+length(File_List))=Subject_ID;
            else % Subject_ID=='Auto'
                app.Data_Input.Scan_List.File_Content(1,end+1:end+length(File_List))=File_List;
                ps=1;
                Split=split(fileparts(File_List{1}),filesep);
                Prefix=Split{end};
                for i=1:length(File_List)
                    while sum(strcmp(app.Data_Input.Subject_ID.File_Content,[Prefix,filesep,num2str(ps)]))>0
                        ps=ps+1;
                    end
                    app.Data_Input.Subject_ID.File_Content{1,end+1}=[Prefix,filesep,num2str(ps)];
                end
            end
            app.ScanListListBox.Value={};
            app.ScanListListBox.Items=app.Data_Input.Scan_List.File_Content;
            N=length(app.ScanListListBox.Items);
            app.ScanListListBox.ItemsData=1:N;
            app.SubjectIDListBox.Value={};
            app.SubjectIDListBox.Items=app.Data_Input.Subject_ID.File_Content;
            app.SubjectIDListBox.ItemsData=1:N;
            [Unique_Cell_String,Count]=fUnique_Cell_String(app.Data_Input.Subject_ID.File_Content);
            app.SummaryTextArea.Enable=true;
            app.SummaryTextArea.Value={['Find ',num2str(length(Unique_Cell_String)),' subjects']};
            app.SummaryTextArea.Value={['Find ',num2str(length(app.Data_Input.Subject_ID.File_Content)),' fMRI scans']};
            if iscell(Subject_ID)
                app.SummaryTextArea.Value{end+1}=['Find ',num2str(length(Count)),' subjects or subject folders'];
            else
                app.SummaryTextArea.Value{end+1}=['Each file is treated as from a different subject, ',num2str(length(Count)),' in total'];
            end
            app.SummaryTextArea.Value{end+1}=['Subject has ',num2str(min(Count)),' - ',num2str(max(Count)),' fMRI scans'];
            app.SummaryTextArea.Value{end+1}=['Subject has ',num2str(round(10*mean(Count))/10),' fMRI scans on average'];

            N=length(app.Data_Input.Subject_ID.File_Content);
            app.FileSelectionEditField.Limits=[1,max([1,N-1])];
            app.FileSelectionEditField.Value=max([1,min([10,N-1,round(N*0.1)])]);
            app.FN_Computation.GroupFN.BootStrap.File_Selection=app.FileSelectionEditField.Value;
        end
        
        function fDisplay_Volume_Interactive(app)
            Tab_Axes=app.Setup.Tab_Axes;
            switch Tab_Axes
                case 'Group_FN'
                    Axes_Label='UIAxes';
                case 'Personalized_FN'
                    Axes_Label='UIAxes_2';
                case 'Statistics'
                    Axes_Label='UIAxes_3';
                otherwise
            end
            cla(app.(Axes_Label),'reset');
            switch app.Setup.Tab_Axes
                case 'Group_FN'
                    load(fullfile(app.Group_FN.Dir,'FN.mat'),'FN');
                    Map=100*FN(:,:,:,str2double(app.FNListBox.Value{1}));
                    threshold=prctile(Map(app.Data_Input.Brain_Mask.Data>0),99.5);
                    if threshold==0
                        threshold=100;
                    end
                    Color_Function=fColor_Theme('Seed_Map_3_Positive',round([threshold/2,threshold]));
                case 'Personalized_FN'
                    load(fullfile(app.Personalized_FN.Dir,app.SubjectListBox.Value,'FN.mat'),'FN');
                    Map=100*FN(:,:,:,str2double(app.FNListBox.Value{1}));
                    threshold=prctile(Map(app.Data_Input.Brain_Mask.Data>0),99.5);
                    if threshold==0
                        threshold=100;
                    end
                    Color_Function=fColor_Theme('Seed_Map_3_Positive',round([threshold/2,threshold]));
                case 'Statistics'
                    Statistics=fAPP_Load_MATLAB_Single_Variable(app,fullfile(app.Statistics.Test(app.Statistics.Test_ps).Dir,'Statistics.mat'));
                    ps=str2double(app.FNListBox.Value{1});
                    Statistics.Result.P_Value(isnan(Statistics.Result.P_Value))=1;
                    if isfield(Statistics.Result,'T_Value') && ~isempty(Statistics.Result.T_Value)
                        Statistics.Result.T_Value(isnan(Statistics.Result.T_Value))=0;
                        Map=Statistics.Result.T_Value(:,:,:,ps).*(Statistics.Result.P_Value(:,:,:,ps)<=Statistics.PValue);           
                    elseif isfield(Statistics.Result,'Z_Value') && ~isempty(Statistics.Result.Z_Value)
                        Statistics.Result.Z_Value(isnan(Statistics.Result.Z_Value))=0;
                        Map=Statistics.Result.Z_Value(:,:,:,ps).*(Statistics.Result.P_Value(:,:,:,ps)<=Statistics.PValue);
                    end
                    
                    Map_2=fApply_Mask(app.Data_Input.Brain_Mask.Data,Map);
                    Max=double(round(10*prctile(Map_2,99))/10);
                    if Max==0
                        Max=double(round(10*max(Map_2))/10);
                        if Max==0
                            Max=1;
                        end
                    end
                    threshold=Max;
                    Color_Function=fColor_Theme('Seed_Map_Positive',[0,0.00001,Max]);
            end
            
            Map=imresize3(Map,app.Setup.Volume.Upsampling,'nearest');
            app.(Axes_Label).Position=[11,16,1038,880]; % Same for three axes
            if isequal(app.Setup.Volume.Center,[0,0,0])
                Map_Label=bwlabeln(Map>=threshold);
                Size=[];
                for j=1:max(Map_Label(:))
                    Size(j)=sum(Map_Label(:)==j);
                end
                [~,ps]=max(Map_Label);
                if isempty(ps)
                    ps=round(size(Map_Label)/2);
                end
                app.Setup.Volume.Center=round(fMass_Center(Map_Label==ps(1)));
                app.(app.Setup.Tab_Axes).Volume.Center=app.Setup.Volume.Center;
            end
            app.(Tab_Axes).Figure_Raw_Data=Map;
            Image=fVoxel_Map_3View(app.Data_Input.Overlay_Image.Data,app.(Tab_Axes).Figure_Raw_Data,...
                {app.Setup.Volume.Center,app.Setup.Volume.Rotation,app.Setup.Volume.Organization},Color_Function,{'Interval',[0,0]});
            app.(Tab_Axes).Figure_Data=Image;
            if isempty(app.Setup.Volume.Click_Panel) || ~isequal([size(Image,1),size(Image,2)],size(app.Setup.Volume.Click_Panel(:,:,1)))
                app.Setup.Volume.Click_Panel=fVolume_Click_Panel(app,size(Map),app.Setup.Volume.Rotation,app.Setup.Volume.Organization);
                app.Setup.Volume.Click_Panel_Size=size(app.Setup.Volume.Click_Panel);
            end
            app.(Tab_Axes).Volume.Color_Function=Color_Function;
            app.Setup.Volume.Matrix_Size=size(Map);
            imagesc(app.(Axes_Label),Image);
            axis(app.(Axes_Label),'image');
            app.(Axes_Label).Visible='On';
            app.(Axes_Label).XTick=[];
            app.(Axes_Label).YTick=[];
            zoom(app.(Axes_Label),'off');
        end
        
        function Center=fVolume_Mouse_To_Center(app,Mouse_Position)
            if app.Setup.Debug==1 && ~isdeployed
                %#exclude display
                display(Mouse_Position);
            end
            Panel_Size=app.Setup.Volume.Click_Panel_Size;
            Mouse_Position=round(Mouse_Position);
            if isempty(Panel_Size) || ~(1<=Mouse_Position(1) && Mouse_Position(1)<=Panel_Size(2) && 1<=Mouse_Position(2) && Mouse_Position(2)<=Panel_Size(1))
                Center=[];
                return
            end
            Center_Previous=app.Setup.Volume.Center;

            Center=squeeze(app.Setup.Volume.Click_Panel(Mouse_Position(2),Mouse_Position(1),:))';
            if isequal(Center,[0,0,0])
                Center=Center_Previous;
            end
            ps=find(Center==0);
            Center(ps)=Center_Previous(ps);
            if app.Setup.Debug==1 && ~isdeployed
                %#exclude display
                display(Center);
            end
        end
        
        
        function fSet_Default_View_Center(app)
            app.Setup.Volume.Center=app.(app.Setup.Tab_Axes).Volume.Center;
            fDisplay_Volume_Interactive_Center(app);
        end
        
        function Click_Panel=fVolume_Click_Panel(~,Matrix_Size,Rotation,Organization)
            if isequal(Organization,[1,3;2,0]) || isequal(Organization,[2,3;1,0])
                Map=zeros([Matrix_Size(1:2),3],'single');
                Map(:,:,1)=repmat((1:Matrix_Size(1))',[1,Matrix_Size(2)]);
                Map(:,:,2)=repmat((1:Matrix_Size(2)),[Matrix_Size(1),1]);
                Image{1}=rot90(Map,Rotation(1));
                Map=zeros([Matrix_Size([1,3]),3],'single');
                Map(:,:,1)=repmat((1:Matrix_Size(1))',[1,Matrix_Size(3)]);
                Map(:,:,3)=repmat((1:Matrix_Size(3)),[Matrix_Size(1),1]);
                Image{2}=rot90(Map,Rotation(2));
                Map=zeros([Matrix_Size(2:3),3],'single');
                Map(:,:,2)=repmat((1:Matrix_Size(2))',[1,Matrix_Size(3)]);
                Map(:,:,3)=repmat((1:Matrix_Size(3)),[Matrix_Size(2),1]);
                Image{3}=rot90(Map,Rotation(3));

                Figure=cell(2,2);
                for x=1:2
                    for y=1:2
                        i=Organization(x,y);
                        if i==0
                            Figure{x,y}=zeros(Matrix_Size(3),Matrix_Size(3),3);
                        else
                            Figure{x,y}=Image{i};
                        end
                    end
                end
                Click_Panel=fAssemble_Image(Figure);
            end
        end
        
        function fDisplay_Volume_Interactive_Center(app)
            Tab_Axes=app.Setup.Tab_Axes;
            switch Tab_Axes
                case 'Group_FN'
                    Axes_Label='UIAxes';
                case 'Personalized_FN'
                    Axes_Label='UIAxes_2';
                case 'Statistics'
                    Axes_Label='UIAxes_3';
                otherwise
            end
            cla(app.(Axes_Label),'reset');
            if isequal(app.Setup.Volume.Center,[0,0,0])
                app.Setup.Volume.Center=round(app.Setup.Volume.Matrix_Size/2);
            end
            Image=fVoxel_Map_3View(app.Data_Input.Overlay_Image.Data,app.(Tab_Axes).Figure_Raw_Data,{app.Setup.Volume.Center,app.Setup.Volume.Rotation,app.Setup.Volume.Organization},...
                app.(Tab_Axes).Volume.Color_Function,{'Interval',[0,0]});
            imagesc(app.(Axes_Label),Image);
            axis(app.(Axes_Label),'image');
            app.(Axes_Label).Visible='On';
            app.(Axes_Label).XTick=[];
            app.(Axes_Label).YTick=[];
            zoom(app.(Axes_Label),'off');
        end
        
        function fDisplay_Volume_Atlas_Interactive(app)
            Tab_Axes=app.Setup.Tab_Axes;
            switch Tab_Axes
                case 'Group_FN'
                    Axes_Label='UIAxes';
                case 'Personalized_FN'
                    Axes_Label='UIAxes_2';
                case 'Statistics'
                    Axes_Label='UIAxes_3';
                otherwise
            end
            cla(app.(Axes_Label),'reset');
            switch app.Setup.Tab_Axes
                case 'Group_FN'
                    load(fullfile(app.Group_FN.Dir,'FN.mat'),'FN');
                    [Mask,Map]=max(FN,[],4);
                    Map=Map.*Mask;
                    Color_Function=[0,0,0,0;fColor_Theme('Atlas',app.FNResult.K)];
                case 'Personalized_FN'
                    load(fullfile(app.Personalized_FN.Dir,app.SubjectListBox.Value,'FN.mat'),'FN');
                    [Mask,Map]=max(FN,[],4);
                    Map=Map.*Mask;
                    Color_Function=[0,0,0,0;fColor_Theme('Atlas',app.FNResult.K)];
                case 'Statistics'
                    Statistics=fAPP_Load_MATLAB_Single_Variable(app,fullfile(app.Statistics.Test(app.Statistics.Test_ps).Dir,'Statistics.mat'));
                    Statistics.Result.P_Value(isnan(Statistics.Result.P_Value))=1;
                    if isfield(Statistics.Result,'T_Value') && ~isempty(Statistics.Result.T_Value)
                        Statistics.Result.T_Value(isnan(Statistics.Result.T_Value))=0;
                        Map=Statistics.Result.T_Value.*(Statistics.Result.P_Value<=Statistics.PValue);               
                    elseif isfield(Statistics.Result,'Z_Value') && ~isempty(Statistics.Result.Z_Value)
                        Statistics.Result.Z_Value(isnan(Statistics.Result.Z_Value))=0;
                        Map=Statistics.Result.Z_Value.*(Statistics.Result.P_Value<=Statistics.PValue);
                    end
                    Map=max(Map,[],4);
                    Map2=fApply_Mask(app.Data_Input.Brain_Mask.Data,Map);
                    Max=double(round(10*prctile(Map2(:),99))/10);
                    if Max==0
                        Max=double(round(10*max(Map2(:)))/10);
                        if Max==0
                            Max=1;
                        end
                    end
                    app.(Tab_Axes).Figure_Raw_Data=Map;
                    Color_Function=fColor_Theme('Seed_Map_Positive',[0,0.00001,Max]);
            end
            Map=imresize3(Map,app.Setup.Volume.Upsampling,'nearest');
            app.(Tab_Axes).Figure_Raw_Data=Map;
            app.Setup.Volume.Matrix_Size=size(Map);
            app.(Axes_Label).Position=[11,16,1038,880]; % Same for three axes
            if isequal(app.Setup.Volume.Center,[0,0,0])
                app.Setup.Volume.Center=round(app.Setup.Volume.Matrix_Size/2);
                app.(Tab_Axes).Volume.Center=round(app.Setup.Volume.Matrix_Size/2);
            end

            Image=fVoxel_Map_3View(app.Data_Input.Overlay_Image.Data,app.(Tab_Axes).Figure_Raw_Data,...
                {app.Setup.Volume.Center,app.Setup.Volume.Rotation,app.Setup.Volume.Organization},Color_Function,{'Interval',[0,0]});
            app.(Tab_Axes).Figure_Data=Image;
            if isempty(app.Setup.Volume.Click_Panel) || ~isequal([size(Image,1),size(Image,2)],size(app.Setup.Volume.Click_Panel(:,:,1)))
                app.Setup.Volume.Click_Panel=fVolume_Click_Panel(app,size(Map),app.Setup.Volume.Rotation,app.Setup.Volume.Organization);
                app.Setup.Volume.Click_Panel_Size=size(app.Setup.Volume.Click_Panel);
            end
            app.(Tab_Axes).Volume.Color_Function=Color_Function;
            
            imagesc(app.(Axes_Label),Image);
            axis(app.(Axes_Label),'image');
            app.(Axes_Label).Visible='On';
            app.(Axes_Label).XTick=[];
            app.(Axes_Label).YTick=[];
            zoom(app.(Axes_Label),'off');
        end
        
        function fLoad_QC(app)
            app.QC.Result=fAPP_Load_MATLAB_Single_Variable(app,fullfile(app.QC.Dir,'QC.mat'));
            app.QC.Subject_List=app.Personalized_FN.Subject_List;
            app.QC.N_Scan=app.Personalized_FN.N_Scan;
            app.SubjectListBox_2.Items=app.Personalized_FN.Subject_List;
            app.SubjectListBox_2.Value=app.Personalized_FN.Subject_List{1};

            temp0=min(app.QC.Result.Spatial_Correspondence-app.QC.Result.Spatial_Correspondence_Control,[],1);
            temp=[temp0(1:app.QC.N_Scan)',(1:app.QC.N_Scan)'];
            app.QC.Data=temp;
            app.QC.Data_Sorted=sortrows(temp);
            app.QC.N_Failed=sum(temp(:,1)<=0);
            app.QC.Data_Min=min(temp(:,1));
            app.QC.Subject_Index=1;

            fApp_Field_Visible(app,{'GroupFNLabel','PersonalizedFNLabel'},true);
            fApp_Field_Enable(app,{'SubjectListBox_2','SortSubjectCheckBox','SummaryTextArea_2'},true);
        end
        
        function fDisplay_QC(app)
            cla(app.UIAxes_QC,'reset');
     
            if app.SortSubjectCheckBox.Value
                temp=app.QC.Data_Sorted;
            else
                temp=app.QC.Data;
            end
            plot(app.UIAxes_QC,temp(:,1),'LineWidth',2);
            hold(app.UIAxes_QC,'on');
            ps=find(strcmp(app.Personalized_FN.Subject_List,app.SubjectListBox_2.Value));
            ps=find(temp(:,2)==ps(1));
            if app.SortSubjectCheckBox.Value && ps>(round(app.QC.N_Failed*1.5)+1)
                ps=1;
                app.SubjectListBox_2.Value=app.SubjectListBox_2.Items{temp(1,2)};
            end
            plot(app.UIAxes_QC,[1,size(temp,1)],[0,0],'w-');
            hold(app.UIAxes_QC,'on');
            plot(app.UIAxes_QC,ps,temp(ps,1),'r*','MarkerSize',10);
            hold(app.UIAxes_QC,'on');
            plot(app.UIAxes_QC,ps,temp(ps,1),'ro','MarkerSize',10);
            if app.SortSubjectCheckBox.Value
                app.UIAxes_QC.XLabel.String='Subject (Sorted)';
            else
                app.UIAxes_QC.XLabel.String='Subject';
            end
            app.UIAxes_QC.YLabel.String='min\DeltaSim';
            app.UIAxes_QC.YColor='w';
            app.UIAxes_QC.XColor='w';
            app.UIAxes_QC.XLim=[1,size(temp,1)];
            if app.SortSubjectCheckBox.Value       
                app.UIAxes_QC.XLim=[1,round(app.QC.N_Failed*1.5)+1.5];
            end
            app.UIAxes_QC.Color='k';
            app.UIAxes_QC.FontSize=16;
            app.UIAxes_QC.LineWidth=2;
            fApp_Field_Visible(app,{'UIAxes_QC'},true);
        end
        
        function fDisplay_QC_2(app) % for 2 axes and summary text
            cla(app.UIAxes_QC_2,'reset');
            cla(app.UIAxes_QC_3,'reset');
            ps=find(strcmp(app.Personalized_FN.Subject_List,app.SubjectListBox_2.Value));
            Miss_Match=app.QC.Result.Individual(ps).Miss_Match;
            Miss_Match_2=sort(unique(Miss_Match(:)));
            if isempty(Miss_Match)
                fApp_Field_Visible(app,{'UIAxes_QC_2','UIAxes_QC_3'},false);
                app.SummaryTextArea_2.Value='All matched';
                return;
            end
            N_Image=length(Miss_Match_2);
            Image=cell(1,N_Image);
            for i=1:N_Image
                temp=fullfile(app.Group_FN.Dir,[num2str(Miss_Match_2(i)),'.jpg']);
                if ~exist(temp,'file')
                    fAPP_Warn_Message(app,['Cannot find the file ',temp]);
                    return;
                else
                    Image{i}=imread(temp);
                end
            end
            imagesc(app.UIAxes_QC_2,fAssemble_Image(reshape(Image,[1,N_Image]),{'Interval',[50,5]}));
            axis(app.UIAxes_QC_2,'image');
            app.UIAxes_QC_2.XTick=[];
            app.UIAxes_QC_2.YTick=[];
            % second display
            Image=cell(1,N_Image);
            for i=1:N_Image
                temp=fullfile(app.Personalized_FN.Dir,app.SubjectListBox_2.Value,[num2str(Miss_Match_2(i)),'.jpg']);
                if ~exist(temp,'file')
                    fAPP_Warn_Message(app,['Cannot find the file ',temp]);
                    return;
                else
                    Image{i}=imread(temp);
                end
            end
            imagesc(app.UIAxes_QC_3,fAssemble_Image(reshape(Image,[1,N_Image]),{'Interval',[50,5]}));
            axis(app.UIAxes_QC_3,'image');
            app.UIAxes_QC_3.XTick=[];
            app.UIAxes_QC_3.YTick=[];
            fApp_Field_Visible(app,{'UIAxes_QC_2','UIAxes_QC_3'},true);
            % Summary Text
            app.SummaryTextArea_2.Value={'Miss matched FN','Individual - Group'};
            for i=1:size(Miss_Match,1)
                app.SummaryTextArea_2.Value{end+1}=[num2str(Miss_Match(i,1)),' - ',num2str(Miss_Match(i,2))];
            end
        end
        
        function fUpdate_QC_Subject(app)
            Mouse_Position=app.Setup.QC.Mouse_Position;
            if Mouse_Position(1)>0 && Mouse_Position(1)<1+app.QC.N_Scan && Mouse_Position(2)>=app.QC.Data_Min-0.05
%                 ps=min([size(app.QC.Data,1),max([1,round(Mouse_Position(1))])]);
                [~,ps]=min(vecnorm([(1:size(app.QC.Data,1))',app.QC.Data(:,1)]-Mouse_Position,1,2));
                ps=app.QC.Data(ps(1),2);
                app.SubjectListBox_2.Value=app.SubjectListBox_2.Items{ps};
                fDisplay_QC(app);
                fDisplay_QC_2(app);
            end
        end
        
        % Only load the first field in the mat file, return [] if error
        function Data = fAPP_Load_MATLAB_Single_Variable(app,File)
            if ~exist(File,"file")
                fError_Message(app,['Cannot find the file ',File]);
                Data=[];
                return;
            end
            [~,~,EXT]=fileparts(File);
            if ~strcmp(EXT,'.mat')
                fError_Message(app,'This file needs to be in MATLAB data format (.mat)');
                Data=[];
                return;
            end
            temp=load(File);
            FN=fieldnames(temp);
            if length(FN)>1
                fError_Message(app,'This matlab file contains more than a single variable');
                Data=[];
                return;
            else
                Data=temp.(FN{1});
            end
        end
        
        function Flag = fLoad_Initialization(app)
            Flag=0;
            Setting=fAPP_Load_MATLAB_Single_Variable(app,fullfile(app.FNResult.Dir,'Data_Input','Setting.mat'));
            if isempty(Setting)
                return
            end
            app.Data_Input.Data_Type=Setting.Data_Type;
            switch app.Data_Input.Data_Type
                case 'Surface'
                    app.DataTypeButtonGroup.SelectedObject=app.SurfaceButton;
                    DataTypeButtonGroupSelectionChanged(app);
                    app.Data_Input.Brain_Surface.File=fullfile(app.FNResult.Dir,'Data_Input','Brain_Surface.mat');
                    app.BrainSurfaceTextArea.Value=app.Data_Input.Brain_Surface.File;
                    app.Data_Input.Brain_Surface.Data=fAPP_Load_MATLAB_Single_Variable(app,app.Data_Input.Brain_Surface.File);
                    app.DisplayButtonGroup.Visible=true;
                    fApp_Field_Enable(app,{'InteractiveButton','InflatedBrainCheckBox'},true);
                case 'Volume'
                    app.DataTypeButtonGroup.SelectedObject=app.VolumeButton;
                    app.Data_Input.Brain_Mask.File=fullfile(app.FNResult.Dir,'Data_Input','Brain_Mask.mat');
                    app.Data_Input.Brain_Mask.Data=fAPP_Load_MATLAB_Single_Variable(app,app.Data_Input.Brain_Mask.File);
                    app.Data_Input.Overlay_Image.File=fullfile(app.FNResult.Dir,'Data_Input','Overlay_Image.mat');
                    app.Data_Input.Overlay_Image.Data=fAPP_Load_MATLAB_Single_Variable(app,app.Data_Input.Overlay_Image.File);
                    if isequal(size(app.Data_Input.Brain_Mask.Data),size(app.Data_Input.Overlay_Image.Data))
                        app.Setup.Volume.Upsampling=1;
                    elseif isequal(size(app.Data_Input.Overlay_Image.Data),size(imresize3(app.Data_Input.Brain_Mask.Data,round(size(app.Data_Input.Overlay_Image.Data)/size(app.Data_Input.Brain_Mask.Data)))))
                        app.Setup.Volume.Upsampling=round(size(app.Data_Input.Overlay_Image.Data)/size(app.Data_Input.Brain_Mask.Data));
                    else
                        fError_Message(app,'The Overlay_Image should have the same image size as the Brain_Mask or integer times bigger at all dimensions');
                    end
                    DataTypeButtonGroupSelectionChanged(app);
                otherwise
                    app.Data_Input.Data_Type='Surface';
                    fError_Message(app,['Unknow data type: ',Setting.Data_Type]);
                    Flag=1;
                    return
            end
            app.Data_Input.Data_Format=Setting.Data_Format;
            app.DataTypeButtonGroup.Enable="off";
            switch app.Data_Input.Data_Format
                case 'HCP Surface (*.cifti, *.mat)'
                    app.DataFormatButtonGroup.SelectedObject=app.HCPSurfaceciftimatButton;
                case 'MGH Surface (*.mgh)'
                    app.DataFormatButtonGroup.SelectedObject=app.MGHSurfacemghButton;
                case 'MGZ Surface (*.mgz)'
                    app.DataFormatButtonGroup.SelectedObject=app.MGZSurfacemgzButton;
                case 'Volume (*.nii, *.nii.gz, *.mat)'
                    app.DataFormatButtonGroup.SelectedObject=app.VolumeniiniigzmatButton;
                otherwise
                    app.Data_Input.Data_Type=[];
                    fError_Message(app,['Unknow data format: ',Setting.Data_Format]);
                    Flag=1;
                    return
            end
            app.DataFormatButtonGroup.Enable="off";
            
            % Scan_List Subject_ID Subject_Folder
            FID=fopen(fullfile(app.FNResult.Dir,'Data_Input','Subject_Folder.txt'),'r');
            Subject_Folder=textscan(FID,'%s\n');
            Subject_Folder_Unqiue=fUnique_Cell_String(Subject_Folder{1});
            fclose(FID);
            app.Data_Input.Subject_Folder_Unique.File_Content=Subject_Folder_Unqiue;
            FID=fopen(fullfile(app.FNResult.Dir,'Data_Input','Scan_List.txt'),'r');
            Scan_List=textscan(FID,'%s\n');
            fclose(FID);
            app.Data_Input.Scan_List.File_Content=Scan_List{1};
            app.ScanListListBox.Value={};
            app.ScanListListBox.Items=app.Data_Input.Scan_List.File_Content;
            app.ScanListListBox.ItemsData=1:length(app.Data_Input.Scan_List.File_Content);
            FID=fopen(fullfile(app.FNResult.Dir,'Data_Input','Subject_ID.txt'),'r');
            Subject_ID=textscan(FID,'%s\n');
            fclose(FID);
            app.Data_Input.Subject_ID.File_Content=Subject_ID{1};
            app.SubjectIDListBox.Value={};
            app.SubjectIDListBox.Items=app.Data_Input.Subject_ID.File_Content;
            app.SubjectIDListBox.ItemsData=1:length(app.Data_Input.Subject_ID.File_Content);
        end
        
        function Flag = fCheck_FN_Computation(app)
            Flag=0;
            N=length(app.Data_Input.Subject_ID.File_Content);
            if app.FileSelectionEditField.Value>=N
                fAPP_Warn_Message(app,['File Selection should be smaller than the number of fMRI files ( n=',num2str(N),')']);
                app.FileSelectionEditField.Limits=[1,max([1,N-1])];
                app.FileSelectionEditField.Value=max([1,min([10,N-1,round(N*0.1)])]);
            end
            app.FN_Computation.GroupFN.BootStrap.File_Selection=app.FileSelectionEditField.Value;
        end
        
        function Flag = fLoad_FN_Computation(app)
            Flag=0;
            if ~exist(fullfile(app.FNResult.Dir,'FN_Computation','Setting.mat'),'file')
                fAPP_Warn_Message(app,'Cannot find the file Setting.mat in folder FN_Computation');
                Flag=1;
                return
            end
            Setting=fAPP_Load_MATLAB_Single_Variable(app,fullfile(app.FNResult.Dir,'FN_Computation','Setting.mat'));
            if isempty(Setting)
                Flag=1;
                return
            end
            if isfield(Setting,'Method') && strcmp(Setting.Method,'NMF_1.0')
                Field_Name={'Dir','K','Method','GroupFN','PersonalizedFN'};
                for i=1:length(Field_Name)
                    if ~isfield(Setting,Field_Name{i})
                        fAPP_Warn_Message(app,['Cannot find field ',Field_Name{i},' in the Setting.mat in folder FN_Computation']);
                        Flag=1;
                        return
                    else
                        app.FN_Computation.(Field_Name{i})=Setting.(Field_Name{i});
                    end
                end
                % Update Setting panels
                app.KEditField.Value=app.FN_Computation.K;
                app.ComputeGroupFNCheckBox.Value=app.FN_Computation.GroupFN.Compute_Flag;
                app.FileSelectionEditField.Value=app.FN_Computation.GroupFN.BootStrap.File_Selection;
                app.RepetitionEditField.Value=app.FN_Computation.GroupFN.BootStrap.Repetition;
                app.FN_Computation.GroupFN.spaR=10;
                app.FN_Computation.GroupFN.vxI=2;
                app.FN_Computation.GroupFN.iterNum=1000;
                app.FN_Computation.GroupFN.Alpha=2;
                app.FN_Computation.GroupFN.Beta=10;
                app.FN_Computation.PersonalizedFN.Combine_Flag=0;
                app.FN_Computation.PersonalizedFN.spaR=1;
                app.FN_Computation.PersonalizedFN.vxI=0;
                app.FN_Computation.PersonalizedFN.ard=0;
                app.FN_Computation.PersonalizedFN.eta=0;
                app.FN_Computation.PersonalizedFN.iterNum=30;
                app.FN_Computation.PersonalizedFN.alphaS21=2;
                app.FN_Computation.PersonalizedFN.alphaL=10;
                fApp_Field_Enable(app,{'KEditField','ComputeGroupFNCheckBox','FileSelectionEditField','RepetitionEditField','spaREditField','vxIEditField',...
                    'iterNumEditField','AlphaEditField','BetaEditField','spaREditField_3','alphaLEditField_2','iterNumEditField_3',...
                    'ardEditField_2','vxIEditField_3','alphaS21EditField','etaEditFieldLabel_3'},false);
                app.CombinemultiplescansforeachsubjectCheckBox.Enable="off";
            else
                Flag=1;
                fAPP_Warn_Message(app,'The setting file in folder FN_Computation contain incorrect information');
                return
            end
            app.FN_Computation.K=[];
            evalc('N_Core=feature(''numcores'')');
            app.ComputerCoreEditField.Value=N_Core;
            if ispc
                user = memory;
                app.ComputerRAMEditField.Value=round(user.MemUsedMATLAB/1024^3);
            elseif ismac
                [~, cmdout]=system('sysctl hw.memsize | awk ''{print $2}''');
                app.ComputerRAMEditField.Value=round(str2double(cmdout)/1024^3);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add subfolder Private_Function to path
            
            if ~(ismcc || isdeployed) % Run in MATLAB mode
                app.Setup.App_Dir=fileparts(mfilename('fullpath'));
                %#exclude addpath
                addpath(genpath(fullfile(app.Setup.App_Dir,'Private_Function')));
            else % For compilation or compiled software
                % Manually add the APP folder and subfolder into path
                % before compilation
                % Check tech details in the help document
                app.Setup.App_Dir = fullfile(ctfroot,'pNet'); % Location of files related to deployed application 
            end
            
            % Resize the UI window based on the screen size
            % Show UI on the main monitor
            Default_Size=[1280,960];
            set(0,'units','pixels');
            Monitor=get(0,'MonitorPositions');
            Screen_Size=Monitor(1,3:4);
            Monitor_Offset=Monitor(1,1:2);
            if ismac
                if sum(Screen_Size>=Default_Size+[0,100])==2
                    app.pNetUIFigure.Position=[Monitor_Offset+round(Screen_Size/2-Default_Size/2),Default_Size];
                else
                    Window_Size=min([Default_Size;Screen_Size-[0,100]],[],1);
                    app.pNetUIFigure.Position=[Monitor_Offset+round(Screen_Size/2-Window_Size/2),Window_Size];
                end
            elseif isunix
                if sum(Screen_Size>=Default_Size+[0,100])==2
                    app.pNetUIFigure.Position=[Monitor_Offset+round(Screen_Size/2-Default_Size/2),Default_Size];
                else
                    Window_Size=min([Default_Size;Screen_Size-[0,100]],[],1);
                    app.pNetUIFigure.Position=[Monitor_Offset+round(Screen_Size/2-Window_Size/2),Window_Size];
                end
            else % windows
                if sum(Screen_Size>=Default_Size+[0,100])==2
                    app.pNetUIFigure.Position=[Monitor_Offset+round(Screen_Size/2-Default_Size/2),Default_Size];
                else
                    Window_Size=min([Default_Size;Screen_Size-[0,100]],[],1);
                    app.pNetUIFigure.Position=[Monitor_Offset+round(Screen_Size/2-Window_Size/2),Window_Size];
                end
            end
            
            % Setup app icons and welcomepage icon
            app.Image4.ImageSource=fullfile(app.Setup.App_Dir,'Private_Function','App_Icon','Blue_Arrow.jpg');
            app.Image4_2.ImageSource=fullfile(app.Setup.App_Dir,'Private_Function','App_Icon','Blue_Arrow.jpg');
            app.Image6_2.ImageSource=fullfile(app.Setup.App_Dir,'Private_Function','App_Icon','Blue_Arrow.jpg');
            app.Image6_3.ImageSource=fullfile(app.Setup.App_Dir,'Private_Function','App_Icon','Blue_Arrow.jpg');
            app.Image4_3.ImageSource=fullfile(app.Setup.App_Dir,'Private_Function','App_Icon','Blue_Arrow.jpg');
            app.Image4_4.ImageSource=fullfile(app.Setup.App_Dir,'Private_Function','App_Icon','Blue_Arrow_Reverse.jpg');
            app.Image4_5.ImageSource=fullfile(app.Setup.App_Dir,'Private_Function','App_Icon','Blue_Arrow.jpg');
            app.PlayImage.ImageSource=fullfile(app.Setup.App_Dir,'Private_Function','App_Icon','Blue_Play.jpg');
            app.PlayImage_2.ImageSource=fullfile(app.Setup.App_Dir,'Private_Function','App_Icon','Blue_Play.jpg');
            app.PlayImage_3.ImageSource=fullfile(app.Setup.App_Dir,'Private_Function','App_Icon','Blue_Play.jpg');
            app.Setup.Group_FN_Play_Icon={fullfile(app.Setup.App_Dir,'Private_Function','APP_Icon','Blue_Play.jpg'),...
                fullfile(app.Setup.App_Dir,'Private_Function','APP_Icon','Blue_Stop.jpg')};
            app.Setup.Welcome_Page_Icon=fullfile(app.Setup.App_Dir,'Private_Function','APP_Icon','Welcome.jpg');
            
            % Welcome page
            Default_Size=[800,550];
            if ismac
                if sum(Screen_Size>=Default_Size+[100,100])==2
                    Window_Position=[Monitor_Offset+round(Screen_Size/2-Default_Size/2),Default_Size];
                else
                    Window_Size=min([Default_Size;Screen_Size-[100,100]],[],1);
                    Window_Position=[Monitor_Offset+round(Screen_Size/2-Window_Size/2),Window_Size];
                end
            elseif isunix
                if sum(Screen_Size>=Default_Size+[100,100])==2
                    Window_Position=[Monitor_Offset+round(Screen_Size/2-Default_Size/2),Default_Size];
                else
                    Window_Size=min([Default_Size;Screen_Size-[100,100]],[],1);
                    Window_Position=[Monitor_Offset+round(Screen_Size/2-Window_Size/2),Window_Size];
                end
            else % windows
                if sum(Screen_Size>=Default_Size+[100,100])==2
                    Window_Position=[Monitor_Offset+round(Screen_Size/2-Default_Size/2),Default_Size];
                else
                    Window_Size=min([Default_Size;Screen_Size-[100,100]],[],1);
                    Window_Position=[Monitor_Offset+round(Screen_Size/2-Window_Size/2),Window_Size];
                end
            end
            Welcome_Window=uifigure('Name','Welcome','WindowStyle','alwaysontop','MenuBar','none','ToolBar','none','color','k','Position',Window_Position);
            Welcome_Window.Children=uiaxes(Welcome_Window,'InnerPosition',[1,1,Window_Position(3:4)],'Box','off','XTickLabel',[],'YTickLabel',[]);
            imagesc(Welcome_Window.Children,imread(app.Setup.Welcome_Page_Icon));
            Welcome_Window.Children.Toolbar.Visible='off';
            axis(Welcome_Window.Children,'image');

            % Setup
            % system and hardware
            app.Setup.System='';
            if ismac
                app.Setup.System='macOS';
            elseif ispc
                app.Setup.System='Windows';
            elseif isunix
                app.Setup.System='Linux';
            else
                fError_Message(app,'Unsupported operation system. Only support macOS, Windows and Linux');
            end
            evalc('N_Core=feature(''numcores'')');
            app.Setup.Hardware.N_Core=N_Core;
            app.ComputerCoreEditField.Value=app.Setup.Hardware.N_Core;
            if ispc
                [~,System] = memory;
                app.Setup.Hardware.Memory_Size=round(System.PhysicalMemory.Total/1024^3);
            elseif ismac
                [~, cmdout]=system('sysctl hw.memsize | awk ''{print $2}''');
                app.Setup.Hardware.Memory_Size=round(str2double(cmdout)/1024^3);
            else % Linux
                [~, cmdout]=system('grep MemTotal /proc/meminfo');
                temp=regexp(cmdout,'\d+','match');
                app.Setup.Hardware.Memory_Size=round(str2double(temp{1})/1024^2);
            end

            if ~(ismcc || isdeployed)
                app.Setup.Debug=1;
            else
                app.Setup.Debug=0;
            end
            app.Setup.App_Version='1.0';
            % display setup
            app.Setup.Group_FN_Play_State='Play';
            app.Setup.Tab_Axes='Load Data';
            app.Setup.Tab_Axes_FN_Computation='Group FN';
            app.Setup.Tab_Axes_Statistics='Statistics';
            app.Setup.Inflated_Brain=0;
            app.Setup.Volume.Center=[0,0,0];
            app.Setup.Volume.Mouse_Position=[1,1];
            app.Setup.Volume.Upsampling=1;
            app.Setup.Volume.Rotation=[1,1,1];
            app.Setup.Volume.Organization=[2,3;1,0];
            app.Setup.Volume.Click_Panel=[];
            app.Setup.Volume.Click_Panel_Size=[];
            app.Setup.QC.Mouse_Position=[0,0];

            % Data
            app.Data_Input.Data_Type='Surface'; % Surface, Volume
            app.Data_Input.Data_Format='HCP Surface (*.cifti, *.mat)';% HCP_Surface, FreeSurfer, Volume
            app.Data_Input.Brain_Mask.File='';
            app.Data_Input.Brain_Mask.Data=[];
            app.Data_Input.Overlay_Image.File='';
            app.Data_Input.Overlay_Image.Data=[];
            app.Data_Input.Brain_Surface.File='';
            app.Data_Input.Brain_Surface.Data=[];
            app.Data_Input.gNB.File='';
            app.Data_Input.gNB.Data=[];
            app.Data_Input.Subject_ID.File_Content={};
            app.Data_Input.Scan_List.File_Content={};
            app.Data_Input.Subject_Folder.File_Content={};
            app.Data_Input.Subject_Folder_Unique.File_Content={};

            % Compute FN
            app.FN_Computation.K=[];
            app.ComputerCoreEditField.Value=app.Setup.Hardware.N_Core;
            app.ComputerRAMEditField.Value=app.Setup.Hardware.Memory_Size;
            app.FN_Computation.Dir=[];
            app.FN_Computation.K=17;
            app.FN_Computation.Method='NMF_1.0';
            app.FN_Computation.GroupFN.Compute_Flag=1;
            app.FN_Computation.GroupFN.Dir=[];
            app.FN_Computation.GroupFN.BootStrap.File_Selection=5;
            app.FN_Computation.GroupFN.BootStrap.Repetition=10;
            app.FN_Computation.GroupFN.spaR=10;
            app.FN_Computation.GroupFN.vxI=2;
            app.FN_Computation.GroupFN.iterNum=1000;
            app.FN_Computation.GroupFN.Alpha=2;
            app.FN_Computation.GroupFN.Beta=10;
            app.FN_Computation.PersonalizedFN.Combine_Flag=0;
            app.FN_Computation.PersonalizedFN.spaR=1;
            app.FN_Computation.PersonalizedFN.vxI=0;
            app.FN_Computation.PersonalizedFN.ard=0;
            app.FN_Computation.PersonalizedFN.eta=0;
            app.FN_Computation.PersonalizedFN.iterNum=30;
            app.FN_Computation.PersonalizedFN.alphaS21=2;
            app.FN_Computation.PersonalizedFN.alphaL=10;
            app.FN_Computation.Parallel.Flag=0;
            app.FN_Computation.Parallel.N_Thread=1;
            app.FN_Computation.Parallel.Memory_Usage_gNMF_Per_Core=0;
            app.FN_Computation.Parallel.Memory_Usage_iNMF_Per_Core=0;

            % FN Result
            app.FNResult.Dir='';
            app.FNResult.K=[];

            % gFN
            app.Group_FN.Dir=[];
            app.Group_FN.Figure_Selection_Type='All';
            app.Group_FN.Display_Style='5 View';
            app.Group_FN.File=[];
            app.Group_FN.Result=[];
            app.Group_FN.Figure_Raw_Data=[];
            app.Group_FN.Figure_Data=[];
            app.Group_FN.Volume.Center=[0,0,0];
            app.Group_FN.Volume.Color_Function=[];

            % pFN
            app.Personalized_FN.Dir=[];
            app.Personalized_FN.Figure_Selection_Type='All';
            app.Personalized_FN.Subject_List={};
            app.Personalized_FN.N_Scan=0; % works for both single and mulitiple scans per subject
            app.Personalized_FN.File={};
            app.Personalized_FN.Result=[];
            app.Personalized_FN.Figure_Raw_Data=[];
            app.Personalized_FN.Figure_Data=[];
            app.Personalized_FN.Volume.Center=[0,0,0];
            app.Personalized_FN.Volume.Color_Function=[];

            % Statistics
            app.Statistics.Dir=[];
            app.Statistics.N_Test=0;
            app.Statistics.Test_ps=1;
            app.Statistics.Test(1)=struct('Dir',[],'Behavior',[],'Name',[],'Method',[],'FDR',[],'PValue',[],'Result',[],'Title',[]);
            app.Statistics.Figure_Selection_Type='All';
            app.Statistics.Behavior=[];
            app.Statistics.Method='1 sample ttest';
            app.Statistics.PValue=0.05;
            app.Statistics.FDR=1;
            app.Statistics.Name='Significance';
            app.Statistics.Text=[];
            app.Statistics.Type=[];
            app.Statistics.Result=[];
            app.Statistics.Figure_Raw_Data=[];
            app.Statistics.Figure_Data=[];
            app.Statistics.Volume.Center=[0,0,0];
            app.Statistics.Volume.Color_Function=[];

            % Quality Control
            app.QC.Dir=[];
            app.QC.Result=[];
            app.QC.Data=[];
            app.QC.Data_Sorted=[];
            app.QC.Subject_List={};
            app.QC.N_Scan=0;
            app.QC.N_Failed=0;
            app.QC.Figure_Data=[];
            app.QC.Subject_Index=0;

            %%%% UI setting %%%%
            app.TabGroup.SelectedTab=app.DataInputTab;
            app.ComputeFNTabGroup.SelectedTab=app.GroupFNTab_2;
            app.StatisticsTabGroup.SelectedTab=app.StatisticsTab_2;
            fAPP_TAB_Button_Color(app);
            fFN_Computation_TAB_Button_Color(app)
            fStatistics_TAB_Button_Color(app)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes_2,'reset');
            cla(app.UIAxes_3,'reset');
            app.UIAxes.Visible='Off';
            app.UIAxes_2.Visible='Off';
            app.UIAxes_3.Visible='Off';
            fApp_Field_Enable(app,{'SelectAllButton','SelectAllButton_2','FNListBox','FNListBox_2','SaveFigureButton','SaveFigureButton_2'},false);
            fApp_Field_Visible(app,{'Slider','Slider_2','Slider_3','Slider_4','DisplayButtonGroup','DisplayButtonGroup_2',...
                'DisplayVolumeButtonGroup','DisplayVolumeButtonGroup_2','PlayImage','PlayImage_2','PlayImage_3'},false);

            if app.Setup.Debug
                fprintf('NMF APP Debug mode enabled\n');
            end
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.Setup.Tab_Axes='Group_FN';
            app.Group_FN.Figure_Selection_Type='All';
            app.Personalized_FN.Figure_Selection_Type='All';
            app.Statistics.Figure_Selection_Type='All';
            app.FNListBox.Value=arrayfun(@(x)num2str(x),1:app.FNResult.K,'Uniformoutput',false);
            app.FNListBox_2.Value=arrayfun(@(x)num2str(x),1:app.FNResult.K,'Uniformoutput',false);
            app.FNListBox_3.Value=arrayfun(@(x)num2str(x),1:app.FNResult.K,'Uniformoutput',false);
            app.Setup.Volume.Center=[0,0,0];
            fDisplay_FN(app);
        end

        % Value changed function: FNListBox
        function FNListBoxValueChanged(app, event)
            app.Setup.Tab_Axes='Group_FN';
            app.Group_FN.Figure_Selection_Type='Select';
            app.Personalized_FN.Figure_Selection_Type='Select';
            app.Statistics.Figure_Selection_Type='Select';
            if strcmp(app.Group_FN.Display_Style,'Interactive') && strcmp(app.Group_FN.Figure_Selection_Type,'Select')
                app.FNListBox.Value=app.FNListBox.Value{end};
            end
            app.FNListBox_2.Value=app.FNListBox.Value;
            app.FNListBox_3.Value=app.FNListBox.Value;
            app.Setup.Volume.Center=[0,0,0];
            fDisplay_FN(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Slider_2.Value=app.Slider.Value;
            app.Slider_3.Value=app.Slider.Value;
            app.Setup.Tab_Axes='Group_FN';
            fDisplay_FN_Slider(app);
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            app.Setup.Tab_Axes='Group_FN';
            if strcmp(app.Group_FN.Figure_Selection_Type,'All')
                Figure_Name='Group_All.jpg';
            else
                temp=join(app.FNListBox.Value,'_');
                Figure_Name=['Group_FN_',temp{1},'.jpg'];
            end
            File_Name=uiputfile({'*.jpg'; '*.png'; '*.tiff'; '*.fig'},'Save this figure',Figure_Name);
            if ~isnumeric(File_Name)
                if app.View5Button.Value==true
                    imwrite(app.Group_FN.Figure_Data,File_Name);
                else
                    exportgraphics(app.UIAxes,File_Name,'Resolution',300);
                end
            end
        end

        % Button down function: GroupFNTab
        function GroupFNTabButtonDown(app, event)
            app.Setup.Tab_Axes='Group_FN';
            if app.Setup.Debug==1 && ~isdeployed
                %#exclude fprintf
                fprintf('GroupNMFTabButtonDown at %s\n',char(datetime('now')));
            end
            if ~isempty(app.Group_FN.Dir)   
                fDisplay_FN(app);
            end
        end

        % Button pushed function: SelectAllButton_2
        function SelectAllButton_2Pushed(app, event)
            app.Setup.Tab_Axes='Personalized_FN';
            app.Group_FN.Figure_Selection_Type='All';
            app.Personalized_FN.Figure_Selection_Type='All';
            app.Statistics.Figure_Selection_Type='All';
            app.FNListBox.Value=arrayfun(@(x)num2str(x),1:app.FNResult.K,'Uniformoutput',false);
            app.FNListBox_2.Value=arrayfun(@(x)num2str(x),1:app.FNResult.K,'Uniformoutput',false);
            app.FNListBox_3.Value=arrayfun(@(x)num2str(x),1:app.FNResult.K,'Uniformoutput',false);
            fDisplay_FN(app);
        end

        % Button down function: PersonalizedFNTab
        function PersonalizedFNTabButtonDown(app, event)
            app.Setup.Tab_Axes='Personalized_FN';
            if app.Setup.Debug==1 && ~isdeployed
                %#exclude fprintf
                fprintf('IndividualNMFTabButtonDown at %s\n',char(datetime('now')));
            end
            if ~isempty(app.Personalized_FN.Dir)
                fDisplay_FN(app);
            end
        end

        % Value changed function: FNListBox_2
        function FNListBox_2ValueChanged(app, event)
            app.Setup.Tab_Axes='Personalized_FN';
            app.Group_FN.Figure_Selection_Type='Select';
            app.Personalized_FN.Figure_Selection_Type='Select';
            app.Statistics.Figure_Selection_Type='Select';
            if strcmp(app.Group_FN.Display_Style,'Interactive') && strcmp(app.Group_FN.Figure_Selection_Type,'Select')
                app.FNListBox_2.Value=app.FNListBox_2.Value{end};
            end
            app.FNListBox.Value=app.FNListBox_2.Value;
            app.FNListBox_3.Value=app.FNListBox_2.Value;
            app.Setup.Volume.Center=[0,0,0];
            fDisplay_FN(app);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.Setup.Tab_Axes='Personalized_FN';
            app.Slider.Value=app.Slider_2.Value;
            app.Slider_3.Value=app.Slider_2.Value;
            fDisplay_FN_Slider(app);
        end

        % Value changed function: SubjectListBox
        function SubjectListBoxValueChanged(app, event)
            app.Setup.Tab_Axes='Personalized_FN';
            app.Setup.Volume.Center=[0,0,0];
            fDisplay_FN(app);
        end

        % Button down function: DataInputTab
        function DataInputTabButtonDown(app, event)
            app.Setup.Tab_Axes='Data_Input';
            app.TabGroup.SelectedTab=app.DataInputTab;
            fAPP_TAB_Button_Color(app);
        end

        % Button pushed function: SaveFigureButton_2
        function SaveFigureButton_2Pushed(app, event)
            app.Setup.Tab_Axes='Personalized_FN';
            if strcmp(app.Personalized_FN.Figure_Selection_Type,'All')
                Figure_Name=['Subject_',app.SubjectListBox.Value,'_All.jpg'];
            else
                temp=join(app.FNListBox_2.Value,'_');
                Figure_Name=['Subject_',app.SubjectListBox.Value,'_FN_',temp{1},'.jpg'];
            end
            File_Name=uiputfile({'*.jpg'; '*.png'; '*.tiff'; '*.fig'},'Save this figure',Figure_Name);
            if ~isnumeric(File_Name)
                if app.View5Button.Value==true
                    imwrite(app.Personalized_FN.Figure_Data,File_Name);
                else
                    exportgraphics(app.UIAxes,File_Name,'Resolution',300);
                end
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            Dir=uigetdir(pwd,'Choose a folder to save NMF results');
            %Dir=GUI_Get_Directory(pwd,'Choose a folder for output NMF results',false);
            if ~isempty(Dir) && ischar(Dir)
                app.FNResult.Dir=Dir;
                app.FN_Computation.Dir=Dir;
                app.OutputFolderEditField.Value=app.FNResult.Dir;
                app.RunProcessButton.Enable=true;
                app.ProcessLabel.Enable=true;
                app.NMFProgressSelectionTree.Enable=true;
                app.RunProcessButton.Enable=true;
            end
        end

        % Button pushed function: LoadScanListButton
        function LoadScanListButtonPushed(app, event)
            [File_Name,File_Path]=uigetfile('*.txt','Select the files containing file directory and subject ID','MultiSelect','on');
%             File=GUI_Get_Directory(pwd,'Select the file containing file directory of all scans',false);
            if ~isequal(File_Name,0)
                if ischar(File_Name)
                    FID=fopen(fullfile(File_Path,File_Name),'r');
                    File_List=textscan(FID,'%s\n');
                    fclose(FID);
                    File_List=File_List{1};
                    fLoad_File_List(app,File_List,'Auto');
                elseif iscell(File_Name) && length(File_Name)==2
                    FID=fopen(fullfile(File_Path,File_Name{1}),'r');
                    File_List=textscan(FID,'%s\n');
                    fclose(FID);
                    File_List=File_List{1};
                    FID=fopen(fullfile(File_Path,File_Name{2}),'r');
                    Subject_ID=textscan(FID,'%s\n');
                    fclose(FID);
                    Subject_ID=Subject_ID{1};
                    if contains(Subject_ID{1},{'.nii','.gii','.gz','.mat'})
                        temp=File_List;
                        File_List=Subject_ID;
                        Subject_ID=temp;
                    end
                    if length(Subject_ID)~=length(File_List)
                        fError_Message(app,'The number of fMRI scans and subject ID needs to be the same');
                        return
                    else
                        fLoad_File_List(app,File_List,Subject_ID);
                    end
                else
                    fError_Message(app,'Choose either a single file for fMRI scan directory, or a second file for subject ID');
                end
            end
            fData_Input_Next(app);
        end

        % Button pushed function: RunProcessButton
        function RunProcessButtonPushed(app, event)
            if app.Setup.Debug
                fprintf('Run Process\n');
            end
            app.ProgressMonitorTextAreaLabel.Enable=true;
            app.ProgressMonitorTextArea.Enable=true;
            app.ProgressMonitorTextArea.Value='';
            Display=0;% Just for display a typical processes of NMF
            if Display==1
                pause(0.1);
                app.ProgressMonitorTextArea.Value={['Start Initialization at ',char(datetime('now'))]};
                pause(0.4);
                if isfield(app.Data_Input,'Subject_ID') && isfield(app.Data_Input.Subject_ID,'File_Content') && ~isempty(app.Data_Input.Subject_ID.File_Content)
                    app.ProgressMonitorTextArea.Value{end+1}='Subject ID file : checked';
                else
                    app.ProgressMonitorTextArea.Value{end+1}='Subject ID file: skipped';
                end
                pause(0.5);
                app.ProgressMonitorTextArea.Value{end+1}='Scan file: checked';
                pause(0.2);
                app.ProgressMonitorTextArea.Value{end+1}='NMF parameter: checked';
                pause(0.5);
                app.ProgressMonitorTextArea.Value{end+1}='Data format: checked';
                pause(0.1);
                app.ProgressMonitorTextArea.Value{end+1}='';
                app.ProgressMonitorTextArea.Value{end+1}=['Group-NMF at ',char(datetime('now'))];
                pause(2);
                N_BS=app.RepetitionEditField.Value;
                app.ProgressMonitorTextArea.Value{end+1}='Finish 1 boostrapping';
                for i=2:N_BS
                    app.ProgressMonitorTextArea.Value{end}=['Finish ',num2str(i),' boostrapping'];
                    pause(rand(1)/5);
                end
                app.ProgressMonitorTextArea.Value{end+1}='Compute Group-FN';
                pause(0.5);
                app.ProgressMonitorTextArea.Value{end+1}='Visualization';
                pause(0.3);
                app.ProgressMonitorTextArea.Value{end+1}='Done';
                app.ProgressMonitorTextArea.Value{end+1}='';
                app.ProgressMonitorTextArea.Value{end+1}=['Individual-FN at ',char(datetime('now'))];
                pause(1);
                N_Scan=length(app.Data_Input.Scan_List.File_Content);
                app.ProgressMonitorTextArea.Value{end+1}='Finish 1 personalized-FN';
                for i=2:min([round(N_Scan/100),10]):N_Scan
                    app.ProgressMonitorTextArea.Value{end}=['Finish ',num2str(i),' personalized-FN'];
                    pause(rand(1)/500);
                end
                app.ProgressMonitorTextArea.Value{end}=['Finish ',num2str(N_Scan),' personalized-FN'];
                app.ProgressMonitorTextArea.Value{end+1}='Visualization';
                pause(0.5);
                app.ProgressMonitorTextArea.Value{end+1}='Done';
                app.ProgressMonitorTextArea.Value{end+1}='';
                pause(0.5);
                app.ProgressMonitorTextArea.Value{end+1}='Load Result';
                app.ProgressMonitorTextArea.Value{end+1}='';
                app.ProgressMonitorTextArea.Value{end+1}=['All done at ',char(datetime('now'))];

            else
                app.ProgressMonitorTextArea.Value={['Start Initialization at ',char(datetime('now'))]};
                % Prepare the result folder
                Dir=app.FNResult.Dir;
                fMake_Folder(fullfile(Dir,'Data_Input'));
                fMake_Folder(fullfile(Dir,'FN_Computation'));
                fMake_Folder(fullfile(Dir,'Group_FN'));
                fMake_Folder(fullfile(Dir,'Personalized_FN'));
                fMake_Folder(fullfile(Dir,'Quality_Control'));
                %-1 Data Format and Type
                app.ProgressMonitorTextArea.Value{end+1}='-Generate Setting.txt in Data_Input';
                fData_Input_Setting('Write',fullfile(Dir,'Data_Input','Setting.txt'),app.Data_Input);
                Setting=app.Data_Input;
                save(fullfile(Dir,'Data_Input','Setting.mat'),'Setting');
                %-2 Scan_List, Subject_ID, Subject_Folder
                switch app.Data_Input.Data_Format
                    case {'HCP Surface (*.cifti, *.mat)','Volume (*.nii, *.nii.gz, *.mat)','MGZ Surface (*.mgz)'}
                        app.ProgressMonitorTextArea.Value{end+1}='-Generate Scan_List.txt in Data_Input';
                        app.Data_Input.Scan_List.File=fullfile(Dir,'Data_Input','Scan_List.txt');
                        FID=fopen(app.Data_Input.Scan_List.File,'w');
                        for i=1:length(app.Data_Input.Scan_List.File_Content)
                            fprintf(FID,'%s\n',app.Data_Input.Scan_List.File_Content{i});
                        end
                        fclose(FID);
                        app.ProgressMonitorTextArea.Value{end+1}='-Generate Subject_ID.txt in Data_Input';
                        app.Data_Input.Subject_ID.File=fullfile(Dir,'Data_Input','Subject_ID.txt');
                        FID=fopen(app.Data_Input.Subject_ID.File,'w');
                        for i=1:length(app.Data_Input.Subject_ID.File_Content)
                            fprintf(FID,'%s\n',app.Data_Input.Subject_ID.File_Content{i});
                        end
                        fclose(FID);
                    case 'MGH Surface (*.mgh)'
                        app.ProgressMonitorTextArea.Value{end+1}='-Generate Scan_List.txt in Data_Input';
                        app.Data_Input.Scan_List.File=fullfile(Dir,'Data_Input','Scan_List.txt');
                        FID=fopen(app.Data_Input.Scan_List.File,'w');
                        for i=1:2:length(app.Data_Input.Scan_List.File_Content)
                            fprintf(FID,'%s;',app.Data_Input.Scan_List.File_Content{i});
                            fprintf(FID,'%s',app.Data_Input.Scan_List.File_Content{i+1});
                            fprintf(FID,'\n');
                        end
                        fclose(FID);
                        app.ProgressMonitorTextArea.Value{end+1}='-Generate Subject_ID.txt in Data_Input';
                        app.Data_Input.Subject_ID.File=fullfile(Dir,'Data_Input','Subject_ID.txt');
                        FID=fopen(app.Data_Input.Subject_ID.File,'w');
                        for i=1:2:length(app.Data_Input.Subject_ID.File_Content)
                            fprintf(FID,'%s\n',app.Data_Input.Subject_ID.File_Content{i});
                        end
                        fclose(FID);
                        app.Data_Input.Subject_ID.File_Content=app.Data_Input.Subject_ID.File_Content(1:2:end);

                end
                app.ProgressMonitorTextArea.Value{end+1}='-Generate Subject_Folder.txt in Data_Input';
                Unique_Cell_String=fUnique_Cell_String(app.Data_Input.Subject_ID.File_Content);
                N_Subject=length(Unique_Cell_String);
                if N_Subject==length(app.Data_Input.Subject_ID.File_Content)
                    copyfile(fullfile(Dir,'Data_Input','Subject_ID.txt'),fullfile(Dir,'Data_Input','Subject_Folder.txt'));
                elseif app.FN_Computation.PersonalizedFN.Combine_Flag==1
                    FID=fopen(fullfile(Dir,'Data_Input','Subject_Folder.txt'),'w');
                    for i=1:N_Subject
                        fprintf(FID,'%s\n',Unique_Cell_String{i});
                    end
                    fclose(FID);
                else
                    Count_Subject=zeros(1,N_Subject);
                    FID=fopen(fullfile(Dir,'Data_Input','Subject_Folder.txt'),'w');
                    for i=1:length(app.Data_Input.Subject_ID.File_Content)
                        ps=find(strcmp(Unique_Cell_String,app.Data_Input.Subject_ID.File_Content{i}));
                        Count_Subject(ps)=Count_Subject(ps)+1;
                        fprintf(FID,'%s\n',fullfile(app.Data_Input.Subject_ID.File_Content{i},num2str(Count_Subject(ps))));
                    end
                    fclose(FID);
                end
                %-3 Surface file
                if strcmp(app.Data_Input.Data_Type,'Surface')
                    app.ProgressMonitorTextArea.Value{end+1}='-Generate Brain_Surface.mat in Data_Input';
                    if isempty(app.Data_Input.Brain_Surface.File)
                        fError_Message(app,'Cannot find the brain surface file that defines the brain surface shape');
                        return
                    else
                        app.Data_Input.Brain_Surface.Data=fAPP_Load_MATLAB_Single_Variable(app,app.Data_Input.Brain_Surface.File);
                        if isempty(app.Data_Input.Brain_Surface.Data)
                            fError_Message(app,'Brain surface file loading failed');
                            return
                        else
                            Brain_Surface=app.Data_Input.Brain_Surface.Data;
                            app.Data_Input.Brain_Surface.File=fullfile(app.FNResult.Dir,'Data_Input','Brain_Surface.mat');
                            save(app.Data_Input.Brain_Surface.File,'-v7.3','Brain_Surface');
                            app.BrainSurfaceTextArea.Value=app.Data_Input.Brain_Surface.File;
                        end
                    end
                else % Volume
                    app.ProgressMonitorTextArea.Value{end+1}='-Generate Brain_Mask.mat in Data_Input';
                    if isempty(app.Data_Input.Brain_Mask.File)
                        fError_Message(app,'Cannot find the brain mask file');
                        return
                    else
                        app.Data_Input.Brain_Mask.Data=fAPP_Load_MATLAB_Single_Variable(app,app.Data_Input.Brain_Mask.File);
                        if isempty(app.Data_Input.Brain_Mask.Data)
                            fError_Message(app,'Brain mask file loading failed');
                            return
                        else
                            Brain_Mask=app.Data_Input.Brain_Mask.Data;
                            app.Data_Input.Brain_Mask.File=fullfile(app.FNResult.Dir,'Data_Input','Brain_Mask.mat');
                            save(app.Data_Input.Brain_Mask.File,'-v7.3','Brain_Mask');
                            app.BrainMaskTextArea.Value=app.Data_Input.Brain_Mask.File;
                        end
                        app.Data_Input.Overlay_Image.Data=fAPP_Load_MATLAB_Single_Variable(app,app.Data_Input.Overlay_Image.File);
                        if isempty(app.Data_Input.Overlay_Image.Data)
                            fError_Message(app,'Overlay image file loading failed');
                            return
                        else
                            Overlay_Image=app.Data_Input.Overlay_Image.Data;
                            app.Data_Input.Overlay_Image.File=fullfile(app.FNResult.Dir,'Data_Input','Overlay_Image.mat');
                            save(app.Data_Input.Overlay_Image.File,'-v7.3','Overlay_Image');
                            app.OverlayImageTextArea.Value=app.Data_Input.Overlay_Image.File;
                        end
                    end
                end
                % Check Compute FN Setting
                Flag=fCheck_FN_Computation(app);
                if Flag
                    return
                end
                % Compute FN
                %-1 Setting
                fFN_Computation_Setting('Write',fullfile(Dir,'FN_Computation','Setting.txt'),app.FN_Computation);
                Setting=app.FN_Computation;
                save(fullfile(Dir,'FN_Computation','Setting.mat'),'Setting');
                %-2 gNb
                [Flag,Message]=fGenerate_gNb(app.Data_Input.Data_Format,Dir);
                if Flag==1
                    fError_Message(app,Message);
                    return
                end
                app.ProgressMonitorTextArea.Value{end+1}='-Generate gNb in FN_Computation';
                % Run group FN if needed
                app.ProgressMonitorTextArea.Value{end+1}='';
                app.ProgressMonitorTextArea.Value{end+1}=['Group-FN at ',char(datetime('now'))];
                if app.ComputeGroupFNCheckBox.Value==0
                    copyfile(app.FN_Computation.GroupFN.Dir,fullfile(Dir,'Group_FN'));
                    app.ProgressMonitorTextArea.Value{end+1}='-Precomputed results are stored in Group_FN';
                else
                    app.ProgressMonitorTextArea.Value{end+1}='-Start computation';
                    fCompute_gFN(app.Setup.App_Dir,Dir);
                    app.ProgressMonitorTextArea.Value{end+1}='  Done';
                    app.ProgressMonitorTextArea.Value{end+1}='-Start visualization';
                    fVisualize_gFN(app.Setup.App_Dir,Dir);
                    app.ProgressMonitorTextArea.Value{end+1}='  Done';
                end
                % Run personalized FN
                app.ProgressMonitorTextArea.Value{end+1}='';
                app.ProgressMonitorTextArea.Value{end+1}=['Individual-FN at ',char(datetime('now'))];
                app.ProgressMonitorTextArea.Value{end+1}='-Start computation';
                [Flag,Message]=fCompute_pFN(app.Setup.App_Dir,Dir);
                if Flag==1
                    fError_Message(app,Message);
                    return
                end
                app.ProgressMonitorTextArea.Value{end+1}='  Done';
                    app.ProgressMonitorTextArea.Value{end+1}='-Start visualization';
                [Flag,Message]=fVisualize_pFN(app.Setup.App_Dir,Dir);
                if Flag==1
                    fError_Message(app,Message);
                    return
                end
                app.ProgressMonitorTextArea.Value{end+1}='  Done';
                % Quality Control
                app.ProgressMonitorTextArea.Value{end+1}='';
                app.ProgressMonitorTextArea.Value{end+1}=['Quality control at ',char(datetime('now'))];
                fCompute_QC(app.Setup.App_Dir,Dir);
                app.ProgressMonitorTextArea.Value{end+1}='  Done';
                app.ProgressMonitorTextArea.Value{end+1}='';
                app.ProgressMonitorTextArea.Value{end+1}='-- All done --';
            end

            if app.FN_Computation.Parallel.Flag
                CPU=gcp('nocreate');
                if ~isempty(CPU)
                    delete(CPU);
                end
            end

            app.Image4_2.Enable=true;
            fLoad_FN_Result(app);
        end

        % Menu selected function: HelpDocumentMenu
        function HelpDocumentMenuSelected(app, event)
            open(fullfile(fileparts(mfilename('fullpath')),'Help_Document.pdf'));
        end

        % Value changed function: ParallelizationCheckBox
        function ParallelizationCheckBoxValueChanged(app, event)
            value = app.ParallelizationCheckBox.Value;
            if value==false
                app.FN_Computation.Parallel.Flag=0;
                app.ofThreadEditField.Enable=false;
            else
                app.FN_Computation.Parallel.Flag=1;
                app.ofThreadEditField.Enable=true;
            end
        end

        % Value changed function: ofThreadEditField
        function ofThreadEditFieldValueChanged(app, event)
            value = app.ofThreadEditField.Value;
            app.ofThreadEditField.Value=min([app.ComputerCoreEditField.Value,max([1,round(value)])]);
            app.FN_Computation.Parallel.N_Thread=app.ofThreadEditField.Value;
            app.MemoryGBUsageEstimationEditField.Value=round(3*app.ofThreadEditField.Value);
            if app.MemoryGBUsageEstimationEditField.Value>app.ComputerRAMEditField.Value
                app.MemoryGBUsageEstimationEditField.FontColor='r';
            else
                app.MemoryGBUsageEstimationEditField.FontColor='k';
            end
        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonGroupSelectionChanged(app, event)
            app.Setup.Tab_Axes='Group_FN';
            app.DisplayButtonGroup_2.SelectedObject=app.View5Button_2;
            if app.DisplayButtonGroup.SelectedObject==app.View5Button
                app.Group_FN.Display_Style='5 View';
                app.Slider.Value=1;
                app.Slider_2.Value=1;
                app.Slider_6.Value=1;
                fApp_Field_Visible(app,{'Slider','Slider_2','Slider_5'},true);
                fApp_Field_Visible(app,{'Slider_3','Slider_4','Slider_6'},false);
                app.InflatedBrainCheckBox.Enable=false;
                fApp_Field_Visible(app,{'PlayImage','PlayImage_2','PlayImage_3'},false);
            else
                app.Group_FN.Display_Style='Interactive';
                if strcmp(app.Group_FN.Figure_Selection_Type,'Select')
                    app.FNListBox.Value=app.FNListBox.Value(end);
                    app.FNListBox_2.Value=app.FNListBox.Value;
                end
                fApp_Field_Visible(app,{'Slider','Slider_2','Slider_5'},true);
                fApp_Field_Visible(app,{'Slider_3','Slider_4','Slider_6'},true);
                app.Slider.Value=0.5;
                app.Slider_2.Value=0.5;
                app.Slider_5.Value=0.5;
                app.Slider_3.Value=0.203;
                app.Slider_4.Value=0.203;
                app.Slider_6.Value=0.203;
                app.InflatedBrainCheckBox.Enable=true;
                app.PlayImage.ImageSource=app.Setup.Group_FN_Play_Icon{1};
                app.PlayImage_2.ImageSource=app.Setup.Group_FN_Play_Icon{1};
                app.PlayImage_3.ImageSource=app.Setup.Group_FN_Play_Icon{1};
                fApp_Field_Visible(app,{'PlayImage','PlayImage_2','PlayImage_3'},true);
            end
            if app.Setup.Debug==1 && ~isdeployed
                %#exclude fprintf
                fprintf(['DisplayButtonGroupSelectionChanged:',app.Group_FN.Display_Style,'\n']);
            end
            fDisplay_FN(app);
        end

        % Button pushed function: BrowseOverLayButton
        function BrowseOverLayButtonPushed(app, event)
            [File,Path]=uigetfile('*.mat;*.nii;*.nii.gz','Choose an overlay image file for volume data type');
            if ischar(File)
                app.Data_Input.Overlay_Image.File=fullfile(Path,File);
                app.OverlayImageTextArea.Value=fullfile(Path,File);
            end
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            app.TabGroup.SelectedTab=app.FNComputationTab;
            app.BrowseButton.Enable=true;
            app.Setup.Tab_Axes='FN_Computation';
            app.OutputFolderEditField.Enable=true;

            app.FileSelectionEditField.Value=max([1,min([app.FileSelectionEditField.Value,length(app.Data_Input.Scan_List.File_Content)])]);
        end

        % Image clicked function: Image4_2
        function Image4_2Clicked(app, event)
            app.Setup.Tab_Axes='Group_FN';
            app.TabGroup.SelectedTab=app.GroupFNTab;
            fAPP_TAB_Button_Color(app)
            fDisplay_FN(app);
        end

        % Image clicked function: Image4_3
        function Image4_3Clicked(app, event)
            app.Setup.Tab_Axes='Personalized_FN';
            app.TabGroup.SelectedTab=app.PersonalizedFNTab;
            fAPP_TAB_Button_Color(app)
            fDisplay_FN(app);
        end

        % Image clicked function: Image4_4
        function Image4_4Clicked(app, event)
            app.Setup.Tab_Axes='Group_FN';
            app.TabGroup.SelectedTab=app.GroupFNTab;
            fAPP_TAB_Button_Color(app)
            fDisplay_FN(app);
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            app.Slider_3.Value=f3D_Slider_Correction(app,app.Slider_3.Value);
            app.Slider_4.Value = app.Slider_3.Value;
            app.Slider_6.Value = app.Slider_3.Value;
            app.Setup.Tab_Axes='Group_FN';
            %display(app.Slider_3.Value);
            fDisplay_FN_Slider(app);
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            app.Slider_4.Value=f3D_Slider_Correction(app,app.Slider_4.Value);
            app.Slider_3.Value = app.Slider_4.Value;
            app.Slider_6.Value = app.Slider_4.Value;
            app.Setup.Tab_Axes='Personalized_FN';
            fDisplay_FN_Slider(app);
        end

        % Value changed function: InflatedBrainCheckBox
        function InflatedBrainCheckBoxValueChanged(app, event)
            app.Setup.Tab_Axes='Group_FN';
            app.Setup.Inflated_Brain=app.InflatedBrainCheckBox.Value;
            app.InflatedBrainCheckBox_2.Value=app.Setup.Inflated_Brain;
            fDisplay_FN(app);
        end

        % Image clicked function: PlayImage
        function PlayImageClicked(app, event)
            fPlay_Image(app)
        end

        % Image clicked function: PlayImage_2
        function PlayImage_2Clicked(app, event)
            fPlay_Image(app);
        end

        % Button pushed function: LoadNMFResultButton
        function LoadNMFResultButtonPushed(app, event)
            if isempty(app.FNResult.Dir) || isempty(app.OutputFolderEditField.Value)
                app.FNResult.Dir=uigetdir(pwd,'Select the folder containing NMF results');
                if ~isempty(app.FNResult.Dir) && ischar(app.FNResult.Dir)
                    app.FN_Computation.Dir=app.FNResult.Dir;
                    fLoad_FN_Result(app);
                else
                    fAPP_Warn_Message(app,'Choose an existing folder');
                    app.FNResult.Dir='';
                end
            else
                fLoad_FN_Result(app);
            end
        end

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
            app.FN_Computation.K = app.KEditField.Value;
        end

        % Value changed function: ComputeGroupFNCheckBox
        function ComputeGroupFNCheckBoxValueChanged(app, event)
            if app.ComputeGroupFNCheckBox.Value
                app.FN_Computation.GroupFN.Compute_Flag=1;
                app.FN_Computation.Group_FN.Compute=true;
                app.LoadGroupFNFolderEditField.Enable=false;
                app.LoadGroupFNFolderLabel.Enable=false;
                app.BrowseGroupFNButton.Enable=false;
                List={'FileSelectionEditField','RepetitionEditField','spaREditField','vxIEditField','iterNumEditField','AlphaEditField','BetaEditField'};
                fApp_Field_Enable(app,List,true);
                fApp_Field_Label_Enable(app,List,true);
            else
                app.FN_Computation.GroupFN.Compute_Flag=0;
                app.LoadGroupFNFolderEditField.Enable=true;
                app.LoadGroupFNFolderLabel.Enable=true;
                app.BrowseGroupFNButton.Enable=true;
                app.FileSelectionEditField.Enable=true;
                app.RepetitionEditField.Enable=true;
                List={'FileSelectionEditField','RepetitionEditField','spaREditField','vxIEditField','iterNumEditField','AlphaEditField','BetaEditField'};
                fApp_Field_Enable(app,List,false);
                fApp_Field_Label_Enable(app,List,false);
            end
            
        end

        % Value changed function: OutputFolderEditField
        function OutputFolderEditFieldValueChanged(app, event)
            value = app.OutputFolderEditField.Value;
            if exist(value,'dir')
                app.FNResult.Dir=value;
                app.FN_Computation.Dir=value;
                app.OutputFolderEditField.Value=app.FNResult.Dir;
                app.RunProcessButton.Enable=true;
                app.ProcessLabel.Enable=true;
                app.NMFProgressSelectionTree.Enable=true;
                app.RunProcessButton.Enable=true;
            else
                app.FNResult.Dir='';
                app.FN_Computation.Dir='';
                fAPP_Warn_Message(app,'Choose an existing folder');
            end
        end

        % Button pushed function: BrowsefMRIButton
        function BrowsefMRIButtonPushed(app, event)
            Dir=uigetdir(pwd,'Choose a folder for output NMF results');
            if ~isempty(Dir) && ischar(Dir)
                app.Data_Input.Dir_Raw=Dir;
                app.fMRIFolderEditField.Value=Dir;
                app.FileNameEditField.Enable=true;
                if ~isempty(app.FileNameEditField.Value)
                    app.SearchButton.Enable=true;
                end
            end
        end

        % Value changed function: fMRIFolderEditField
        function fMRIFolderEditFieldValueChanged(app, event)
            value = app.fMRIFolderEditField.Value;
            if exist(value,'dir')
                app.Data_Input.Dir_Raw=value;
                app.fMRIFolderEditField.Enable=true;
                if ~isempty(app.FileNameEditField.Value)
                    app.SearchButton.Enable=true;
                end
            end
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            if ~isempty(app.FileNameEditField.Value) && ~isempty(app.fMRIFolderEditField.Value)
                app.SearchButton.Enable=true;
            else
                app.SearchButton.Enable=false;
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            Dir=dir(fullfile(app.Data_Input.Dir_Raw,['**',filesep,app.FileNameEditField.Value]));
            if isempty(Dir)
                fAPP_Warn_Message(app,'Find no matched files');
                return;
            else
                File_List=cell(1,length(Dir));
                Subject_ID=cell(1,length(Dir));
                Flag=1;
                for i=1:length(Dir)
                    File_List{i}=fullfile(Dir(i).folder,Dir(i).name);
                    temp=File_List{i}(length(app.Data_Input.Dir_Raw)+2:end);
                    Folder=fileparts(temp);
                    Split=split(Folder,filesep);
                    if ~isempty(Split)
                        Subject_ID{i}=Split{1};
                    else
                        Flag=0;
                    end
                end
                if Flag==0
                    Subject_ID='Auto';
                end
                fLoad_File_List(app,File_List,Subject_ID);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [File,Path]=uiputfile('Scan_List.txt','Choose a folder to save the fMRI scan list');
            if ischar(File)
                app.Data_Input.Scan_List.File=fullfile(Path,File);
                FID=fopen(app.Data_Input.Scan_List.File,'w');
                for i=1:length(app.Data_Input.Scan_List.File_Content)
                    fprintf(FID,'%s\n',app.Data_Input.Scan_List.File_Content{i});
                end
                fclose(FID);
            end
            [File,Path]=uiputfile('Subject_ID.txt','Choose a folder to save the subject ID list');
            if ischar(File)
                app.Data_Input.Subject_ID.File=fullfile(Path,File);
                FID=fopen(app.Data_Input.Subject_ID.File,'w');
                for i=1:length(app.Data_Input.Subject_ID.File_Content)
                    fprintf(FID,'%s\n',app.Data_Input.Subject_ID.File_Content{i});
                end
                fclose(FID);
            end
        end

        % Button pushed function: GroupFNButton
        function GroupFNButtonPushed(app, event)
            app.Setup.Tab_Axes='Group_FN';
            app.TabGroup.SelectedTab=app.GroupFNTab;
            fAPP_TAB_Button_Color(app);
            if ~isempty(app.Group_FN.Dir)   
                fDisplay_FN(app);
            end
        end

        % Button pushed function: PersonalizedFNButton
        function PersonalizedFNButtonPushed(app, event)
            app.Setup.Tab_Axes='Personalized_FN';
            app.TabGroup.SelectedTab=app.PersonalizedFNTab;
            fAPP_TAB_Button_Color(app);
            if ~isempty(app.Personalized_FN.Dir)
                fDisplay_FN(app);
            end
        end

        % Button down function: FNComputationTab
        function FNComputationTabButtonDown(app, event)
            app.Setup.Tab_Axes='FN_Computation';
            app.TabGroup.SelectedTab=app.FNComputationTab;
            fAPP_TAB_Button_Color(app);

            app.FileSelectionEditField.Value=max([1,min([app.FileSelectionEditField.Value,length(app.Data_Input.Scan_List.File_Content)])]);
        end

        % Button pushed function: StatisticsButton
        function StatisticsButtonPushed(app, event)
            app.Setup.Tab_Axes='Statistics';
            fAPP_TAB_Button_Color(app);
            app.TabGroup.SelectedTab=app.StatisticsTab;
            if app.Statistics.N_Test>0
                fDisplay_Statistics(app);
            end
        end

        % Image clicked function: Image6_2
        function Image6_2Clicked(app, event)
            app.ComputeFNTabGroup.SelectedTab=app.PersonalizedFNTab_2;
        end

        % Image clicked function: Image6_3
        function Image6_3Clicked(app, event)
            app.ComputeFNTabGroup.SelectedTab=app.ParallelComputationTab;
        end

        % Selection changed function: DataTypeButtonGroup
        function DataTypeButtonGroupSelectionChanged(app, event)
            if app.DataTypeButtonGroup.SelectedObject==app.SurfaceButton % Surface
                if app.DataFormatButtonGroup.SelectedObject==app.VolumeniiniigzmatButton
                    app.DataFormatButtonGroup.SelectedObject=app.HCPSurfaceciftimatButton;
                end
                fApp_Field_Enable(app,{'BrainMaskTextArea','OverlayImageTextArea','BrowseBrainMaskButton','BrowseOverLayButton'},false);
                fApp_Field_Label_Enable(app,{'BrainMaskTextArea','OverlayImageTextArea'},false);
                fApp_Field_Enable(app,{'BrainSurfaceTextArea','BrowseBrainSurfaceButton'},true);
                fApp_Field_Label_Enable(app,{'BrainSurfaceTextArea'},true);
            else % Volume
                if app.DataFormatButtonGroup.SelectedObject~=app.VolumeniiniigzmatButton
                    app.DataFormatButtonGroup.SelectedObject=app.VolumeniiniigzmatButton;
                end
                fApp_Field_Enable(app,{'BrainMaskTextArea','OverlayImageTextArea','BrowseBrainMaskButton','BrowseOverLayButton'},true);
                fApp_Field_Label_Enable(app,{'BrainMaskTextArea','OverlayImageTextArea'},true);
                fApp_Field_Enable(app,{'BrainSurfaceTextArea','BrowseBrainSurfaceButton'},false);
                fApp_Field_Label_Enable(app,{'BrainSurfaceTextArea'},false);
            end
            app.Data_Input.Data_Type=app.DataTypeButtonGroup.SelectedObject.Text;
            app.Data_Input.Data_Format=app.DataFormatButtonGroup.SelectedObject.Text;
        end

        % Selection changed function: DataFormatButtonGroup
        function DataFormatButtonGroupSelectionChanged(app, event)
            if app.DataFormatButtonGroup.SelectedObject~=app.VolumeniiniigzmatButton % Surface
                if app.DataTypeButtonGroup.SelectedObject==app.VolumeButton
                    app.DataTypeButtonGroup.SelectedObject=app.SurfaceButton;
                end
                fApp_Field_Enable(app,{'BrainMaskTextArea','OverlayImageTextArea','BrowseBrainMaskButton','BrowseOverLayButton'},false);
                fApp_Field_Label_Enable(app,{'BrainMaskTextArea','OverlayImageTextArea'},false);
                fApp_Field_Enable(app,{'BrainSurfaceTextArea','BrowseBrainSurfaceButton'},true);
                fApp_Field_Label_Enable(app,{'BrainSurfaceTextArea'},true);
            elseif  app.DataFormatButtonGroup.SelectedObject==app.VolumeniiniigzmatButton % Volume
                if app.DataTypeButtonGroup.SelectedObject==app.SurfaceButton
                    app.DataTypeButtonGroup.SelectedObject=app.VolumeButton;
                end
                fApp_Field_Enable(app,{'BrainMaskTextArea','OverlayImageTextArea','BrowseBrainMaskButton','BrowseOverLayButton'},true);
                fApp_Field_Label_Enable(app,{'BrainMaskTextArea','OverlayImageTextArea'},true);
                fApp_Field_Enable(app,{'BrainSurfaceTextArea','BrowseBrainSurfaceButton'},false);
                fApp_Field_Label_Enable(app,{'BrainSurfaceTextArea'},false);
            end
            app.Data_Input.Data_Type=app.DataTypeButtonGroup.SelectedObject.Text;
            app.Data_Input.Data_Format=app.DataFormatButtonGroup.SelectedObject.Text;
        end

        % Menu selected function: ClearSettingMenu
        function ClearSettingMenuSelected(app, event)
            startupFcn(app);
        end

        % Button pushed function: BrowseGroupFNButton
        function BrowseGroupFNButtonPushed(app, event)
            Path=uigetdir('*.mat','Choose a precomputed group-FN result folder');
            if ischar(Path)
                app.FN_Computation.GroupFN.Dir=Path;
                app.LoadGroupFNFolderEditField.Value=Path;
            end
        end

        % Value changed function: LoadGroupFNFolderEditField
        function LoadGroupFNFolderEditFieldValueChanged(app, event)
            Dir=app.LoadGroupFNFolderEditField.Value;
            if ~exist(Dir,'file')
                fError_Message(app,['Cannot find the file ',Dir]);
            else
                [~,~,extension]=fileparts(Dir);
                if strcmp(extension,'.mat')
                    fError_Message(app,'group-FN file needs to be in MATLAB format');
                else
                    app.FN_Computation.GroupFN.Dir=Dir;
                end
            end
        end

        % Value changed function: FileSelectionEditField
        function FileSelectionEditFieldValueChanged(app, event)
            value = app.FileSelectionEditField.Value;
            if isempty(app.Data_Input.Subject_ID.File_Content) && value>=length(app.Data_Input.Scan_List.File_Content)
                fAPP_Warn_Message(app,['File Selection should be smaller than the number of fMRI files ( n=',num2str(length(app.Data_Input.Scan_List.File_Content)),')']);
                app.FileSelectionEditField.Value=length(app.Data_Input.Scan_List.File_Content);
            end
            app.FN_Computation.GroupFN.BootStrap.File_Selection=app.FileSelectionEditField.Value;
        end

        % Value changed function: RepetitionEditField
        function RepetitionEditFieldValueChanged(app, event)
            app.FN_Computation.GroupFN.BootStrap.Repetition=app.RepetitionEditField.Value;
        end

        % Value changed function: spaREditField
        function spaREditFieldValueChanged(app, event)
            app.FN_Computation.GroupFN.spaR=app.spaREditField.Value;
        end

        % Value changed function: vxIEditField
        function vxIEditFieldValueChanged(app, event)
            app.FN_Computation.GroupFN.vxI=app.vxIEditField.Value;
        end

        % Value changed function: iterNumEditField
        function iterNumEditFieldValueChanged(app, event)
            app.FN_Computation.GroupFN.iterNum=app.iterNumEditField.Value;
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            app.FN_Computation.GroupFN.Alpha=app.AlphaEditField.Value;
        end

        % Value changed function: BetaEditField
        function BetaEditFieldValueChanged(app, event)
            app.FN_Computation.GroupFN.Beta=app.BetaEditField.Value;
        end

        % Value changed function: 
        % CombinemultiplescansforeachsubjectCheckBox
        function CombinemultiplescansforeachsubjectCheckBoxValueChanged(app, event)
            value = app.CombinemultiplescansforeachsubjectCheckBox.Value;
            if value
                app.FN_Computation.PersonalizedFN.Combine_Flag=1;
            else
                app.FN_Computation.PersonalizedFN.Combine_Flag=0;
            end
        end

        % Value changed function: spaREditField_3
        function spaREditField_3ValueChanged(app, event)
            app.FN_Computation.PersonalizedFN.spaR=app.spaREditField_3.Value;
        end

        % Value changed function: vxIEditField_3
        function vxIEditField_3ValueChanged(app, event)
            app.FN_Computation.PersonalizedFN.vxI=app.vxIEditField_3.Value;
        end

        % Value changed function: ardEditField_2
        function ardEditField_2ValueChanged(app, event)
            app.FN_Computation.PersonalizedFN.ard=app.ardEditField_3.Value;
        end

        % Value changed function: etaEditField_4
        function etaEditField_4ValueChanged(app, event)
            app.FN_Computation.PersonalizedFN.eta=app.alphaS21EditField.Value;
        end

        % Value changed function: iterNumEditField_3
        function iterNumEditField_3ValueChanged(app, event)
            app.FN_Computation.PersonalizedFN.iterNum=app.iterNumEditField_3.Value;
        end

        % Value changed function: alphaS21EditField
        function alphaS21EditFieldValueChanged(app, event)
            app.FN_Computation.PersonalizedFN.alphaS21=app.alphaS21EditField.Value;
        end

        % Value changed function: alphaLEditField_2
        function alphaLEditField_2ValueChanged(app, event)
            app.FN_Computation.PersonalizedFN.alphaL=app.alphaLEditField_3.Value;
        end

        % Callback function
        function PreparationButtonPushed(app, event)
            app.Setup.Tab_Axes_FN_Computation='Preparation';
            app.ComputeFNTabGroup.SelectedTab=app.PreparationTab;
            fFN_Computation_TAB_Button_Color(app);
        end

        % Button pushed function: GroupFNButton_2
        function GroupFNButton_2Pushed(app, event)
            app.Setup.Tab_Axes_FN_Computation='Group FN';
            app.ComputeFNTabGroup.SelectedTab=app.GroupFNTab_2;
            fFN_Computation_TAB_Button_Color(app);
        end

        % Button pushed function: PersonalizedFNButton_2
        function PersonalizedFNButton_2Pushed(app, event)
            app.Setup.Tab_Axes_FN_Computation='Personalized FN';
            app.ComputeFNTabGroup.SelectedTab=app.PersonalizedFNTab_2;
            fFN_Computation_TAB_Button_Color(app);
        end

        % Button pushed function: ParallelComputationButton
        function ParallelComputationButtonPushed(app, event)
            app.Setup.Tab_Axes_FN_Computation='Parallel Computation';
            app.ComputeFNTabGroup.SelectedTab=app.ParallelComputationTab;
            fFN_Computation_TAB_Button_Color(app);
        end

        % Button pushed function: BrowseBrainMaskButton
        function BrowseBrainMaskButtonPushed(app, event)
            [File,Path]=uigetfile('*.mat;*.nii;*.nii.gz','Choose an image mask file for volume data type');
            if ischar(File)
                app.Data_Input.Brain_Mask.File=fullfile(Path,File);
                app.BrainMaskTextArea.Value=fullfile(Path,File);
            end
        end

        % Value changed function: BrainMaskTextArea
        function BrainMaskTextAreaValueChanged(app, event)
            Dir=app.BrainMaskTextArea.Value;
            [~,~,extension]=fileparts(Dir);
            if ~exist(Dir,'file')
                app.BrainMaskTextArea.Value=[];
                fError_Message(app,'Cannot find the file');
            elseif sum(cell2mat(strcmp({'.mat','.nii','.gz'},extension)))==0
                app.BrainMaskTextArea.Value=[];
                fError_Message(app,'The file is required to be in *.mat, *.nii or *.nii.gz format');
            else
                app.Data_Input.Brain_Mask.File=app.BrainMaskTextArea.Value;
            end
        end

        % Button pushed function: BrowseBrainSurfaceButton
        function BrowseBrainSurfaceButtonPushed(app, event)
            [File,Path]=uigetfile('*.mat','Select the template file for 3D visualization');
            if ischar(File)
                app.BrainSurfaceTextArea.Value=fullfile(Path,File);
                app.Data_Input.Brain_Surface.File=fullfile(Path,File);
                fLoad_Brain_Surface(app);
            end
        end

        % Value changed function: OverlayImageTextArea
        function OverlayImageTextAreaValueChanged(app, event)
            Dir=app.OverlayImageTextArea.Value;
            [~,~,extension]=fileparts(Dir);
            if ~exist(Dir,'file')
                app.OverlayImageTextArea.Value=[];
                fError_Message(app,'Cannot find the file');
            elseif sum(cell2mat(strcmp({},extension)))==0
                app.OverlayImageTextArea.Value=[];
                fError_Message(app,'The file is required to be in *.mat, *.nii or *.nii.gz format');
            else
                app.Data_Input.Overlay_Image.File=app.OverlayImageTextArea.Value;
            end
        end

        % Value changed function: BrainSurfaceTextArea
        function BrainSurfaceTextAreaValueChanged(app, event)
            Dir=app.BrainSurfaceTextArea.Value;
            [~,~,extension]=fileparts(Dir);
            if ~exist(Dir,'file')
                app.BrainSurfaceTextArea.Value=[];
                fError_Message(app,'Cannot find the file');
            elseif sum(cell2mat(strcmp({'.mat'},extension)))==0
                app.BrainSurfaceTextArea.Value=[];
                fError_Message(app,'The file is required to be in *.mat format');
            else
                app.Data_Input.Brain_Surface.File=app.Brain_Surface.Value;
                fLoad_Brain_Surface(app);
            end
        end

        % Button pushed function: BrowseBehaviorButton
        function BrowseBehaviorButtonPushed(app, event)
            [File,Path]=uigetfile('*.txt','Choose a behavior file in *.txt format');
            if ischar(File)
                Dir=fullfile(Path,File);
                fLoad_Behavior_File(app,Dir);
            else
                app.Statistics.Method='1 sample ttest';
                app.Statistics.Behavior=[];
                app.Statistics.Name='Significance';
                app.TestMethodListBox.Value='1 sample ttest';
                app.TestMethodListBox.Items={'1 sample ttest','Wilcoxon singed rank test'};
            end
        end

        % Value changed function: BehaviorFileEditField
        function BehaviorFileEditFieldValueChanged(app, event)
            Dir = app.BehaviorFileEditField.Value;
            if ~isempty(Dir)
                fLoad_Behavior_File(app,Dir);
            else
                app.Statistics.Method='1 sample ttest';
                app.Statistics.Behavior=[];
                app.Statistics.Name='Significance';
                app.TestMethodListBox.Value='1 sample ttest';
                app.TestMethodListBox.Items={'1 sample ttest','Wilcoxon singed rank test'};
            end
        end

        % Value changed function: TestMethodListBox
        function TestMethodListBoxValueChanged(app, event)
            app.Statistics.Method=app.TestMethodListBox.Value;
        end

        % Button pushed function: ComputeStatisticsButton
        function ComputeStatisticsButtonPushed(app, event)
            app.Setup.Tab_Axes='Statistics';
            fCompute_Statistics(app);
        end

        % Value changed function: pvalueEditField
        function pvalueEditFieldValueChanged(app, event)
            app.Statistics.PValue=app.pvalueEditField.Value;
            app.Statistics.PValue
        end

        % Button pushed function: SelectAllButton_3
        function SelectAllButton_3Pushed(app, event)
            app.Setup.Tab_Axes='Statistics';
            app.Statistics.Figure_Selection_Type='All';
            app.Group_FN.Figure_Selection_Type='All';
            app.Personalized_FN.Figure_Selection_Type='All';
            app.Statistics.Figure_Selection_Type='All';
            app.FNListBox_3.Value=arrayfun(@(x)num2str(x),1:app.FNResult.K,'Uniformoutput',false);
            app.FNListBox.Value = app.FNListBox_3.Value;
            app.FNListBox_2.Value = app.FNListBox_3.Value;
            app.Setup.Volume.Center=[0,0,0];
            fDisplay_Statistics(app);
        end

        % Value changed function: FNListBox_3
        function FNListBox_3ValueChanged(app, event)
            app.Setup.Tab_Axes='Statistics';
            app.Group_FN.Figure_Selection_Type='Select';
            app.Personalized_FN.Figure_Selection_Type='Select';
            if strcmp(app.Group_FN.Display_Style,'Interactive') && strcmp(app.Group_FN.Figure_Selection_Type,'Select')
                app.FNListBox_3.Value=app.FNListBox_3.Value{end};
            end
            app.FNListBox.Value=app.FNListBox_3.Value;
            app.FNListBox_2.Value=app.FNListBox_3.Value;
            app.Setup.Volume.Center=[0,0,0];
            fDisplay_Statistics(app);
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            app.Setup.Tab_Axes='Statistics';
            app.Slider.Value=app.Slider_5.Value;
            app.Slider_2.Value=app.Slider_5.Value;
            fDisplay_FN_Slider(app);
        end

        % Value changed function: InflatedBrainCheckBox_2
        function InflatedBrainCheckBox_2ValueChanged(app, event)
            app.Setup.Tab_Axes='Statistics';
            app.Setup.Inflated_Brain=app.InflatedBrainCheckBox_2.Value;
            app.InflatedBrainCheckBox.Value=app.Setup.Inflated_Brain;
            fDisplay_Statistics(app);
        end

        % Selection changed function: DisplayButtonGroup_2
        function DisplayButtonGroup_2SelectionChanged(app, event)
            app.Setup.Tab_Axes='Statistics';
            app.DisplayButtonGroup.SelectedObject=app.View5Button;
            if app.DisplayButtonGroup_2.SelectedObject==app.View5Button_2
                app.Group_FN.Display_Style='5 View';
                app.Slider.Value=1;
                app.Slider_2.Value=1;
                app.Slider_5.Value=1;
                fApp_Field_Visible(app,{'Slider','Slider_2','Slider_5'},true);
                fApp_Field_Visible(app,{'Slider_3','Slider_4','Slider_6'},false);
                app.InflatedBrainCheckBox.Enable=false;
                fApp_Field_Visible(app,{'PlayImage','PlayImage_2'},false);
            else
                app.Group_FN.Display_Style='Interactive';
                if strcmp(app.Group_FN.Figure_Selection_Type,'Select')
                    app.FNListBox_3.Value=app.FNListBox_3.Value(end);
                    app.FNListBox.Value=app.FNListBox_3.Value;
                    app.FNListBox_2.Value=app.FNListBox_3.Value;
                end
                fApp_Field_Visible(app,{'Slider','Slider_2','Slider_5'},true);
                fApp_Field_Visible(app,{'Slider_3','Slider_4','Slider_6'},true);
                app.Slider.Value=0.5;
                app.Slider_2.Value=0.5;
                app.Slider_5.Value=0.5;
                app.Slider_3.Value=0.203;
                app.Slider_4.Value=0.203;
                app.Slider_6.Value=0.203;
                app.InflatedBrainCheckBox.Enable=true;
                app.PlayImage.ImageSource=app.Setup.Group_FN_Play_Icon{1};
                app.PlayImage_2.ImageSource=app.Setup.Group_FN_Play_Icon{1};
                fApp_Field_Visible(app,{'PlayImage','PlayImage_2','PlayImage_3'},true);
            end
            if app.Setup.Debug==1 && ~isdeployed
                %#exclude fprintf
                fprintf(['DisplayButtonGroupSelectionChanged:',app.Group_FN.Display_Style,'\n']);
            end
            switch app.Setup.Tab_Axes
                case {'Group_FN','Personalized_FN'}
                    fDisplay_FN(app);
                case 'Statistics'
                    fDisplay_Statistics(app);
            end
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            app.Slider_6.Value=f3D_Slider_Correction(app,app.Slider_6.Value);
            app.Slider_3.Value = app.Slider_6.Value;
            app.Slider_4.Value = app.Slider_6.Value;
            fDisplay_FN_Slider(app);
        end

        % Image clicked function: PlayImage_3
        function PlayImage_3Clicked(app, event)
            fPlay_Image(app);
        end

        % Value changed function: FDRCheckBox
        function FDRCheckBoxValueChanged(app, event)
            app.Statistics.FDR = app.FDRCheckBox.Value;            
        end

        % Value changed function: TestResultListBox
        function TestResultListBoxValueChanged(app, event)
            temp=app.TestResultListBox.Items;
            app.Statistics.Test_ps=find(strcmp(temp(:),app.TestResultListBox.Value));
            app.Statistics.Test_ps=app.Statistics.Test_ps(end);
            fDisplay_Statistics(app);
        end

        % Button pushed function: BrowsefMRIFileButton
        function BrowsefMRIFileButtonPushed(app, event)
            [File,Path]=uigetfile('*.nii;*.gz;*.gii;*.mat','Select multiple fMRI files','MultiSelect','on');
            if ischar(File) || iscell(File)
                if ~iscell(File)
                    File={File};
                end
                File_List=cell(1,length(File));
                for i=1:length(File)
                    File_List{i}=fullfile(Path,File{i});
                end
                fLoad_File_List(app,File_List,'Auto');
            end
        end

        % Value changed function: fMRIFileEditField
        function fMRIFileEditFieldValueChanged(app, event)
            File = app.fMRIFileEditField.Value;
            if exist(value,'file')
                [~,~,extension]=fileparts(File);
                if sum(contains({'.nii','.gii','.gz'},extension))>0
                    File={File};
                    fLoad_File_List(app,File,'Auto');
                else
                    fError_Message(app,'Only support fMRI data in .nii, .nii.gz, .gii formats');
                    return;
                end
            end
        end

        % Button pushed function: ClearListButton
        function ClearListButtonPushed(app, event)
            app.Data_Input.Scan_List.File_Content={};
            app.Data_Input.Subject_ID.File_Content={};
            app.ScanListListBox.Value={};
            app.ScanListListBox.Items={};
            app.ScanListListBox.ItemsData=[];
            app.SubjectIDListBox.Value={};
            app.SubjectIDListBox.Items={};
            app.SubjectIDListBox.ItemsData=[];
        end

        % Value changed function: SubjectIDListBox
        function SubjectIDListBoxValueChanged(app, event)
            app.ScanListListBox.Value = app.SubjectIDListBox.Value;
            
        end

        % Value changed function: ScanListListBox
        function ScanListListBoxValueChanged(app, event)
            app.SubjectIDListBox.Value = app.ScanListListBox.Value;
            
        end

        % Button pushed function: KeepSelectedButton
        function KeepSelectedButtonPushed(app, event)
            ps=sort(app.ScanListListBox.Value);
            if ~isempty(ps)
                app.Data_Input.Scan_List.File_Content=app.Data_Input.Scan_List.File_Content(ps);
                app.Data_Input.Subject_ID.File_Content=app.Data_Input.Subject_ID.File_Content(ps);
                app.ScanListListBox.Value=[];
                app.ScanListListBox.Items=app.ScanListListBox.Items(ps);
                app.ScanListListBox.ItemsData=app.ScanListListBox.ItemsData(ps);
                app.SubjectIDListBox.Value=[];
                app.SubjectIDListBox.Items=app.SubjectIDListBox.Items(ps);
                app.SubjectIDListBox.ItemsData=app.SubjectIDListBox.ItemsData(ps);
            end
        end

        % Button pushed function: RemoveSelectedButton
        function RemoveSelectedButtonPushed(app, event)
            ps=sort(setdiff(1:length(app.Data_Input.Scan_List.File_Content),app.ScanListListBox.Value));
            display(ps);
            if ~isempty(ps)
                app.Data_Input.Scan_List.File_Content=app.Data_Input.Scan_List.File_Content(ps);
                app.Data_Input.Subject_ID.File_Content=app.Data_Input.Subject_ID.File_Content(ps);
                app.ScanListListBox.Value=[];
                app.ScanListListBox.Items=app.ScanListListBox.Items(ps);
                app.ScanListListBox.ItemsData=app.ScanListListBox.ItemsData(ps);
                app.SubjectIDListBox.Value=[];
                app.SubjectIDListBox.Items=app.SubjectIDListBox.Items(ps);
                app.SubjectIDListBox.ItemsData=app.SubjectIDListBox.ItemsData(ps);
            end
        end

        % Selection changed function: DisplayVolumeButtonGroup
        function DisplayVolumeButtonGroupSelectionChanged(app, event)
            if app.DisplayVolumeButtonGroup.SelectedObject==app.View3Button
                app.DisplayVolumeButtonGroup_2.SelectedObject=app.View3Button_2;
                app.Group_FN.Display_Style='3 View';
                fApp_Field_Visible(app,{'DefaultViewCenterButton','DefaultViewCenterButton_2','DefaultViewCenterButton_3'},false);
            else
                app.DisplayVolumeButtonGroup_2.SelectedObject=app.VolumeInteractiveButton_2;
                app.Group_FN.Display_Style='Interactive';
                fApp_Field_Visible(app,{'Slider','Slider_2','Slider_5'},false);
                fApp_Field_Visible(app,{'DefaultViewCenterButton','DefaultViewCenterButton_2','DefaultViewCenterButton_3'},true);
            end
            fDisplay_FN(app);
        end

        % Window button down function: pNetUIFigure
        function AxesButtonDown(app, event)
            % UI click callback for volume axes
            if strcmp(app.Setup.Tab_Axes,'Quality_Control')
                app.Setup.QC.Mouse_Position=app.UIAxes_QC.CurrentPoint(1,1:2);
                if app.Setup.Debug==1 && ~isdeployed
                    %#exclude display
                    display(app.Setup.QC.Mouse_Position);
                end
                fUpdate_QC_Subject(app);
            else
                switch app.Data_Input.Data_Type
                    case 'Volume'
                        if ~strcmp(app.Group_FN.Display_Style,'Interactive')
                            return
                        end
                        switch app.Setup.Tab_Axes
                            case 'Group_FN'
                                Axes_Label='UIAxes';
                            case 'Personalized_FN'
                                Axes_Label='UIAxes_2';
                            case 'Statistics'
                                Axes_Label='UIAxes_3';
                            otherwise
                                return
                        end
                        app.Setup.Volume.Mouse_Position=app.(Axes_Label).CurrentPoint(1,1:2);
                        if app.Setup.Debug==1 && ~isdeployed
                            %#exclude fprintf
                            display(app.Setup.Volume.Mouse_Position);
                        end
                        Center=fVolume_Mouse_To_Center(app,app.Setup.Volume.Mouse_Position);
                        if ~isempty(Center)
                            app.Setup.Volume.Center=Center;
                            fDisplay_Volume_Interactive_Center(app);
                        end
                end
            end
        end

        % Button pushed function: DefaultViewCenterButton
        function DefaultViewCenterButtonPushed(app, event)
            fSet_Default_View_Center(app);
        end

        % Button pushed function: DefaultViewCenterButton_2
        function DefaultViewCenterButton_2Pushed(app, event)
            fSet_Default_View_Center(app);
        end

        % Button pushed function: DefaultViewCenterButton_3
        function DefaultViewCenterButton_3Pushed(app, event)
            fSet_Default_View_Center(app);
        end

        % Button down function: QualityControlTab
        function QualityControlTabButtonDown(app, event)
            app.Setup.Tab_Axes='Quality_Control';
            app.TabGroup.SelectedTab=app.QualityControlTab;
            fAPP_TAB_Button_Color(app);
            fDisplay_QC(app);
            fDisplay_QC_2(app);
        end

        % Selection changed function: DisplayVolumeButtonGroup_2
        function DisplayVolumeButtonGroup_2SelectionChanged(app, event)
            if app.DisplayVolumeButtonGroup_2.SelectedObject==app.View3Button_2
                app.DisplayVolumeButtonGroup.SelectedObject=app.View3Button;
                app.Group_FN.Display_Style='3 View';
                fApp_Field_Visible(app,{'DefaultViewCenterButton','DefaultViewCenterButton_2','DefaultViewCenterButton_3'},false);
            else
                app.DisplayVolumeButtonGroup.SelectedObject=app.VolumeInteractiveButton;
                app.Group_FN.Display_Style='Interactive';
                fApp_Field_Visible(app,{'Slider','Slider_2','Slider_5'},false);
                fApp_Field_Visible(app,{'DefaultViewCenterButton','DefaultViewCenterButton_2','DefaultViewCenterButton_3'},true);
            end
            fDisplay_Statistics(app);
        end

        % Value changed function: SubjectListBox_2
        function SubjectListBox_2ValueChanged(app, event)
            fDisplay_QC(app);
            fDisplay_QC_2(app);
        end

        % Value changed function: SortSubjectCheckBox
        function SortSubjectCheckBoxValueChanged(app, event)
            fDisplay_QC(app);
            fDisplay_QC_2(app);
        end

        % Menu selected function: LoadResultMenu
        function LoadResultMenuSelected(app, event)
            app.FNResult.Dir=uigetdir(pwd,'Select the folder containing NMF results');
            if ~isempty(app.FNResult.Dir) && ischar(app.FNResult.Dir)
                app.FN_Computation.Dir=app.FNResult.Dir;
                fLoad_FN_Result(app);
            else
                fAPP_Warn_Message(app,'Choose an existing folder');
                app.FNResult.Dir='';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.pNetUIFigure = uifigure('Visible', 'off');
            app.pNetUIFigure.Name = 'pNet (Personalized Functional Network Modeling)';
            app.pNetUIFigure.Icon = '/Volumes/Scratch_0/pNet/Private_Function/APP_Icon/APP_Icon.jpg';
            app.pNetUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @AxesButtonDown, true);
            app.APPMenu = uimenu(app.pNetUIFigure);
            app.APPMenu.Text = 'APP';
            app.ClearSettingMenu = uimenu(app.APPMenu);
            app.ClearSettingMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearSettingMenuSelected, true);
            app.ClearSettingMenu.Text = 'Clear Setting';
            app.LoadResultMenu = uimenu(app.APPMenu);
            app.LoadResultMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadResultMenuSelected, true);
            app.LoadResultMenu.Text = 'Load Result';
            app.HelpMenu = uimenu(app.pNetUIFigure);
            app.HelpMenu.Text = 'Help';
            app.HelpDocumentMenu = uimenu(app.HelpMenu);
            app.HelpDocumentMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpDocumentMenuSelected, true);
            app.HelpDocumentMenu.Text = 'Help Document';
            app.TabGroup = uitabgroup(app.pNetUIFigure);
            app.DataInputTab = uitab(app.TabGroup);
            app.DataInputTab.Title = 'Data Input';
            app.DataInputTab.ButtonDownFcn = createCallbackFcn(app, @DataInputTabButtonDown, true);
            app.LightGrayBox1_3 = uilabel(app.DataInputTab);
            app.LightGrayBox1_3.Text = '';
            app.LightGrayBox1_2 = uilabel(app.DataInputTab);
            app.LightGrayBox1_2.Text = '';
            app.LightGrayBox1_1 = uilabel(app.DataInputTab);
            app.LightGrayBox1_1.Text = '';
            app.DataFormatButtonGroup = uibuttongroup(app.DataInputTab);
            app.DataFormatButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataFormatButtonGroupSelectionChanged, true);
            app.DataFormatButtonGroup.Title = 'Data Format';
            app.HCPSurfaceciftimatButton = uiradiobutton(app.DataFormatButtonGroup);
            app.HCPSurfaceciftimatButton.Text = 'HCP Surface (*.cifti, *.mat)';
            app.HCPSurfaceciftimatButton.Value = true;
            app.MGHSurfacemghButton = uiradiobutton(app.DataFormatButtonGroup);
            app.MGHSurfacemghButton.Text = 'MGH Surface (*.mgh)';
            app.VolumeniiniigzmatButton = uiradiobutton(app.DataFormatButtonGroup);
            app.VolumeniiniigzmatButton.Text = 'Volume (*.nii, *.nii.gz, *.mat)';
            app.MGZSurfacemgzButton = uiradiobutton(app.DataFormatButtonGroup);
            app.MGZSurfacemgzButton.Text = 'MGZ Surface (*.mgz)';
            app.DataTypeButtonGroup = uibuttongroup(app.DataInputTab);
            app.DataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataTypeButtonGroupSelectionChanged, true);
            app.DataTypeButtonGroup.Title = 'Data Type';
            app.VolumeButton = uiradiobutton(app.DataTypeButtonGroup);
            app.VolumeButton.Text = 'Volume';
            app.SurfaceButton = uiradiobutton(app.DataTypeButtonGroup);
            app.SurfaceButton.Text = 'Surface';
            app.SurfaceButton.Value = true;
            app.SubjectIDTextAreaLabel = uilabel(app.DataInputTab);
            app.SubjectIDTextAreaLabel.Text = 'Subject ID';
            app.ScanListTextAreaLabel = uilabel(app.DataInputTab);
            app.ScanListTextAreaLabel.Text = 'Scan List';
            app.BrainMaskTextAreaLabel = uilabel(app.DataInputTab);
            app.BrainMaskTextAreaLabel.Enable = 'off';
            app.BrainMaskTextAreaLabel.Text = 'Brain Mask';
            app.BrainMaskTextArea = uitextarea(app.DataInputTab);
            app.BrainMaskTextArea.ValueChangedFcn = createCallbackFcn(app, @BrainMaskTextAreaValueChanged, true);
            app.BrainMaskTextArea.Enable = 'off';
            app.OverlayImageTextAreaLabel = uilabel(app.DataInputTab);
            app.OverlayImageTextAreaLabel.Enable = 'off';
            app.OverlayImageTextAreaLabel.Text = 'Overlay Image';
            app.OverlayImageTextArea = uitextarea(app.DataInputTab);
            app.OverlayImageTextArea.ValueChangedFcn = createCallbackFcn(app, @OverlayImageTextAreaValueChanged, true);
            app.OverlayImageTextArea.Enable = 'off';
            app.GrayBox1_1 = uilabel(app.DataInputTab);
            app.GrayBox1_1.Text = '';
            app.BrowseBrainMaskButton = uibutton(app.DataInputTab, 'push');
            app.BrowseBrainMaskButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseBrainMaskButtonPushed, true);
            app.BrowseBrainMaskButton.Enable = 'off';
            app.BrowseBrainMaskButton.Text = 'Browse';
            app.BrowseOverLayButton = uibutton(app.DataInputTab, 'push');
            app.BrowseOverLayButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseOverLayButtonPushed, true);
            app.BrowseOverLayButton.Enable = 'off';
            app.BrowseOverLayButton.Text = 'Browse';
            app.Image4 = uiimage(app.DataInputTab);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.Enable = 'off';
            app.fMRIFolderEditField = uieditfield(app.DataInputTab, 'text');
            app.fMRIFolderEditField.ValueChangedFcn = createCallbackFcn(app, @fMRIFolderEditFieldValueChanged, true);
            app.BrowsefMRIButton = uibutton(app.DataInputTab, 'push');
            app.BrowsefMRIButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefMRIButtonPushed, true);
            app.BrowsefMRIButton.Text = 'Browse';
            app.fMRIFolderLabel = uilabel(app.DataInputTab);
            app.fMRIFolderLabel.Text = 'fMRI Folder';
            app.SearchfMRIFilesLabel = uilabel(app.DataInputTab);
            app.SearchfMRIFilesLabel.Text = 'Search fMRI Files';
            app.FileNameEditField = uieditfield(app.DataInputTab, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameEditField.Tooltip = {'eg. *dtseries.nii *.nii.gz *.gii'};
            app.FileNameEditField.Value = '*.nii';
            app.SearchButton = uibutton(app.DataInputTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Enable = 'off';
            app.SearchButton.Text = 'Search';
            app.FileNameLabel = uilabel(app.DataInputTab);
            app.FileNameLabel.Text = 'File Name';
            app.SaveButton = uibutton(app.DataInputTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SummaryTextAreaLabel = uilabel(app.DataInputTab);
            app.SummaryTextAreaLabel.Text = 'Summary';
            app.SummaryTextArea = uitextarea(app.DataInputTab);
            app.SummaryTextArea.Editable = 'off';
            app.SummaryTextArea.Enable = 'off';
            app.BrainSurfaceTextAreaLabel = uilabel(app.DataInputTab);
            app.BrainSurfaceTextAreaLabel.Text = 'Brain Surface';
            app.BrainSurfaceTextArea = uitextarea(app.DataInputTab);
            app.BrainSurfaceTextArea.ValueChangedFcn = createCallbackFcn(app, @BrainSurfaceTextAreaValueChanged, true);
            app.BrowseBrainSurfaceButton = uibutton(app.DataInputTab, 'push');
            app.BrowseBrainSurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseBrainSurfaceButtonPushed, true);
            app.BrowseBrainSurfaceButton.Text = 'Browse';
            app.ClearListButton = uibutton(app.DataInputTab, 'push');
            app.ClearListButton.ButtonPushedFcn = createCallbackFcn(app, @ClearListButtonPushed, true);
            app.ClearListButton.Text = 'Clear List';
            app.KeepSelectedButton = uibutton(app.DataInputTab, 'push');
            app.KeepSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @KeepSelectedButtonPushed, true);
            app.KeepSelectedButton.Text = 'Keep Selected';
            app.GrayBox1_2 = uilabel(app.DataInputTab);
            app.GrayBox1_2.Text = '';
            app.RemoveSelectedButton = uibutton(app.DataInputTab, 'push');
            app.RemoveSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedButtonPushed, true);
            app.RemoveSelectedButton.Text = 'Remove Selected';
            app.fMRIFileEditField = uieditfield(app.DataInputTab, 'text');
            app.fMRIFileEditField.ValueChangedFcn = createCallbackFcn(app, @fMRIFileEditFieldValueChanged, true);
            app.fMRIFileEditField.Value = './Scan_1.nii';
            app.BrowsefMRIFileButton = uibutton(app.DataInputTab, 'push');
            app.BrowsefMRIFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefMRIFileButtonPushed, true);
            app.BrowsefMRIFileButton.Text = 'Browse';
            app.fMRIFileLabel = uilabel(app.DataInputTab);
            app.fMRIFileLabel.Text = 'fMRI File';
            app.GrayBox1_3 = uilabel(app.DataInputTab);
            app.GrayBox1_3.Text = '';
            app.SubjectIDListBox = uilistbox(app.DataInputTab);
            app.SubjectIDListBox.Items = {};
            app.SubjectIDListBox.Multiselect = 'on';
            app.SubjectIDListBox.ValueChangedFcn = createCallbackFcn(app, @SubjectIDListBoxValueChanged, true);
            app.SubjectIDListBox.Value = {};
            app.ScanListListBox = uilistbox(app.DataInputTab);
            app.ScanListListBox.Items = {};
            app.ScanListListBox.Multiselect = 'on';
            app.ScanListListBox.ValueChangedFcn = createCallbackFcn(app, @ScanListListBoxValueChanged, true);
            app.ScanListListBox.Value = {};
            app.ScanListandSubjectIDFilesLabel = uilabel(app.DataInputTab);
            app.ScanListandSubjectIDFilesLabel.Text = 'Scan List and Subject ID Files';
            app.LoadScanListButton = uibutton(app.DataInputTab, 'push');
            app.LoadScanListButton.ButtonPushedFcn = createCallbackFcn(app, @LoadScanListButtonPushed, true);
            app.LoadScanListButton.Text = 'Browse';
            app.FNComputationTab = uitab(app.TabGroup);
            app.FNComputationTab.Title = 'FN Computation';
            app.FNComputationTab.ButtonDownFcn = createCallbackFcn(app, @FNComputationTabButtonDown, true);
            app.SettingLabel = uilabel(app.FNComputationTab);
            app.SettingLabel.Text = 'Setting';
            app.LightGrayBox1_5 = uilabel(app.FNComputationTab);
            app.LightGrayBox1_5.Text = '';
            app.NMFProgressSelectionTree = uitree(app.FNComputationTab, 'checkbox');
            app.NMFProgressSelectionTree.Editable = 'on';
            app.InitializationNode = uitreenode(app.NMFProgressSelectionTree);
            app.InitializationNode.Text = 'Initialization';
            app.GroupFNNode = uitreenode(app.NMFProgressSelectionTree);
            app.GroupFNNode.Text = 'Group FN';
            app.PersonalizedFNNode = uitreenode(app.NMFProgressSelectionTree);
            app.PersonalizedFNNode.Text = 'Personalized FN';
            app.QualityControlNode = uitreenode(app.NMFProgressSelectionTree);
            app.QualityControlNode.Text = 'Quality Control';
            % Assign Checked Nodes
            app.NMFProgressSelectionTree.CheckedNodes = [app.InitializationNode, app.GroupFNNode, app.PersonalizedFNNode, app.QualityControlNode];
            app.ProcessLabel = uilabel(app.FNComputationTab);
            app.ProcessLabel.Text = 'Process';
            app.LightGrayBox1_4 = uilabel(app.FNComputationTab);
            app.LightGrayBox1_4.Text = '';
            app.ProgressMonitorTextAreaLabel = uilabel(app.FNComputationTab);
            app.ProgressMonitorTextAreaLabel.Enable = 'off';
            app.ProgressMonitorTextAreaLabel.Text = 'Progress Monitor';
            app.ProgressMonitorTextArea = uitextarea(app.FNComputationTab);
            app.ProgressMonitorTextArea.Editable = 'off';
            app.ProgressMonitorTextArea.Enable = 'off';
            app.RunProcessButton = uibutton(app.FNComputationTab, 'push');
            app.RunProcessButton.ButtonPushedFcn = createCallbackFcn(app, @RunProcessButtonPushed, true);
            app.RunProcessButton.Enable = 'off';
            app.RunProcessButton.Text = 'Run Process';
            app.KEditFieldLabel = uilabel(app.FNComputationTab);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.FNComputationTab, 'numeric');
            app.KEditField.Limits = [2 10000];
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.KEditField.Value = 17;
            app.OutputFolderEditField = uieditfield(app.FNComputationTab, 'text');
            app.OutputFolderEditField.ValueChangedFcn = createCallbackFcn(app, @OutputFolderEditFieldValueChanged, true);
            app.Image4_2 = uiimage(app.FNComputationTab);
            app.Image4_2.ImageClickedFcn = createCallbackFcn(app, @Image4_2Clicked, true);
            app.Image4_2.Enable = 'off';
            app.BrowseButton = uibutton(app.FNComputationTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ResultFolderLabel = uilabel(app.FNComputationTab);
            app.ResultFolderLabel.Text = 'Result Folder';
            app.ComputeFNTabGroup = uitabgroup(app.FNComputationTab);
            app.GroupFNTab_2 = uitab(app.ComputeFNTabGroup);
            app.GroupFNTab_2.Title = 'Group FN';
            app.GrayBox1_8 = uilabel(app.GroupFNTab_2);
            app.GrayBox1_8.Text = '';
            app.GrayBox1_7 = uilabel(app.GroupFNTab_2);
            app.GrayBox1_7.Text = '';
            app.spaREditFieldLabel = uilabel(app.GroupFNTab_2);
            app.spaREditFieldLabel.Text = 'spaR';
            app.spaREditField = uieditfield(app.GroupFNTab_2, 'numeric');
            app.spaREditField.Limits = [0.0001 10000];
            app.spaREditField.ValueChangedFcn = createCallbackFcn(app, @spaREditFieldValueChanged, true);
            app.spaREditField.Value = 10;
            app.vxIEditFieldLabel = uilabel(app.GroupFNTab_2);
            app.vxIEditFieldLabel.Text = 'vxI';
            app.vxIEditField = uieditfield(app.GroupFNTab_2, 'numeric');
            app.vxIEditField.Limits = [0.0001 10000];
            app.vxIEditField.ValueChangedFcn = createCallbackFcn(app, @vxIEditFieldValueChanged, true);
            app.vxIEditField.Value = 2;
            app.iterNumEditFieldLabel = uilabel(app.GroupFNTab_2);
            app.iterNumEditFieldLabel.Text = 'iterNum';
            app.iterNumEditField = uieditfield(app.GroupFNTab_2, 'numeric');
            app.iterNumEditField.Limits = [2 10000];
            app.iterNumEditField.ValueChangedFcn = createCallbackFcn(app, @iterNumEditFieldValueChanged, true);
            app.iterNumEditField.Value = 1000;
            app.AlphaEditFieldLabel = uilabel(app.GroupFNTab_2);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.GroupFNTab_2, 'numeric');
            app.AlphaEditField.Limits = [0.0001 10000];
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AlphaEditField.Value = 2;
            app.BetaEditFieldLabel = uilabel(app.GroupFNTab_2);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.GroupFNTab_2, 'numeric');
            app.BetaEditField.Limits = [0.0001 10000];
            app.BetaEditField.ValueChangedFcn = createCallbackFcn(app, @BetaEditFieldValueChanged, true);
            app.BetaEditField.Value = 10;
            app.LoadGroupFNFolderLabel = uilabel(app.GroupFNTab_2);
            app.LoadGroupFNFolderLabel.Text = 'Load Group FN Folder';
            app.ComputeGroupFNCheckBox = uicheckbox(app.GroupFNTab_2);
            app.ComputeGroupFNCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComputeGroupFNCheckBoxValueChanged, true);
            app.ComputeGroupFNCheckBox.Text = 'Compute Group FN';
            app.ComputeGroupFNCheckBox.Value = true;
            app.LoadGroupFNFolderEditField = uieditfield(app.GroupFNTab_2, 'text');
            app.LoadGroupFNFolderEditField.ValueChangedFcn = createCallbackFcn(app, @LoadGroupFNFolderEditFieldValueChanged, true);
            app.LoadGroupFNFolderEditField.Enable = 'off';
            app.BrowseGroupFNButton = uibutton(app.GroupFNTab_2, 'push');
            app.BrowseGroupFNButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseGroupFNButtonPushed, true);
            app.BrowseGroupFNButton.Enable = 'off';
            app.BrowseGroupFNButton.Text = 'Browse';
            app.RepetitionEditFieldLabel = uilabel(app.GroupFNTab_2);
            app.RepetitionEditFieldLabel.Text = 'Repetition';
            app.RepetitionEditField = uieditfield(app.GroupFNTab_2, 'numeric');
            app.RepetitionEditField.Limits = [1 10000];
            app.RepetitionEditField.RoundFractionalValues = 'on';
            app.RepetitionEditField.ValueDisplayFormat = '%.0f';
            app.RepetitionEditField.ValueChangedFcn = createCallbackFcn(app, @RepetitionEditFieldValueChanged, true);
            app.RepetitionEditField.Value = 10;
            app.FileSelectionEditField = uieditfield(app.GroupFNTab_2, 'numeric');
            app.FileSelectionEditField.Limits = [1 10000];
            app.FileSelectionEditField.RoundFractionalValues = 'on';
            app.FileSelectionEditField.ValueDisplayFormat = '%.0f';
            app.FileSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @FileSelectionEditFieldValueChanged, true);
            app.FileSelectionEditField.Value = 5;
            app.BootstrapLabel = uilabel(app.GroupFNTab_2);
            app.BootstrapLabel.Text = 'Bootstrap';
            app.Image6_2 = uiimage(app.GroupFNTab_2);
            app.Image6_2.ImageClickedFcn = createCallbackFcn(app, @Image6_2Clicked, true);
            app.FileSelectionEditFieldLabel = uilabel(app.GroupFNTab_2);
            app.FileSelectionEditFieldLabel.Text = 'File Selection';
            app.PersonalizedFNTab_2 = uitab(app.ComputeFNTabGroup);
            app.PersonalizedFNTab_2.Title = 'Personalized FN';
            app.GrayBox1_6 = uilabel(app.PersonalizedFNTab_2);
            app.GrayBox1_6.Text = '';
            app.etaEditFieldLabel_3 = uilabel(app.PersonalizedFNTab_2);
            app.etaEditFieldLabel_3.Text = 'alphaS21';
            app.alphaS21EditField = uieditfield(app.PersonalizedFNTab_2, 'numeric');
            app.alphaS21EditField.Limits = [0.0001 10000];
            app.alphaS21EditField.ValueChangedFcn = createCallbackFcn(app, @alphaS21EditFieldValueChanged, true);
            app.alphaS21EditField.Value = 2;
            app.spaREditField_3Label = uilabel(app.PersonalizedFNTab_2);
            app.spaREditField_3Label.Text = 'spaR';
            app.spaREditField_3 = uieditfield(app.PersonalizedFNTab_2, 'numeric');
            app.spaREditField_3.Limits = [0.0001 10000];
            app.spaREditField_3.ValueChangedFcn = createCallbackFcn(app, @spaREditField_3ValueChanged, true);
            app.spaREditField_3.Value = 1;
            app.vxIEditField_3Label = uilabel(app.PersonalizedFNTab_2);
            app.vxIEditField_3Label.Text = 'vxI';
            app.vxIEditField_3 = uieditfield(app.PersonalizedFNTab_2, 'numeric');
            app.vxIEditField_3.Limits = [0 10000];
            app.vxIEditField_3.ValueChangedFcn = createCallbackFcn(app, @vxIEditField_3ValueChanged, true);
            app.ardEditField_2Label = uilabel(app.PersonalizedFNTab_2);
            app.ardEditField_2Label.Text = 'ard';
            app.ardEditField_2 = uieditfield(app.PersonalizedFNTab_2, 'numeric');
            app.ardEditField_2.Limits = [0 1];
            app.ardEditField_2.ValueChangedFcn = createCallbackFcn(app, @ardEditField_2ValueChanged, true);
            app.etaEditField_4Label = uilabel(app.PersonalizedFNTab_2);
            app.etaEditField_4Label.Text = 'eta';
            app.etaEditField_4 = uieditfield(app.PersonalizedFNTab_2, 'numeric');
            app.etaEditField_4.Limits = [0 1];
            app.etaEditField_4.ValueChangedFcn = createCallbackFcn(app, @etaEditField_4ValueChanged, true);
            app.iterNumEditField_3Label = uilabel(app.PersonalizedFNTab_2);
            app.iterNumEditField_3Label.Text = 'iterNum';
            app.iterNumEditField_3 = uieditfield(app.PersonalizedFNTab_2, 'numeric');
            app.iterNumEditField_3.Limits = [2 10000];
            app.iterNumEditField_3.ValueChangedFcn = createCallbackFcn(app, @iterNumEditField_3ValueChanged, true);
            app.iterNumEditField_3.Value = 30;
            app.alphaLEditField_2Label = uilabel(app.PersonalizedFNTab_2);
            app.alphaLEditField_2Label.Text = 'alphaL';
            app.alphaLEditField_2 = uieditfield(app.PersonalizedFNTab_2, 'numeric');
            app.alphaLEditField_2.Limits = [0.0001 10000];
            app.alphaLEditField_2.ValueChangedFcn = createCallbackFcn(app, @alphaLEditField_2ValueChanged, true);
            app.alphaLEditField_2.Value = 10;
            app.CombinemultiplescansforeachsubjectCheckBox = uicheckbox(app.PersonalizedFNTab_2);
            app.CombinemultiplescansforeachsubjectCheckBox.ValueChangedFcn = createCallbackFcn(app, @CombinemultiplescansforeachsubjectCheckBoxValueChanged, true);
            app.CombinemultiplescansforeachsubjectCheckBox.Text = 'Combine multiple scans for each subject';
            app.Image6_3 = uiimage(app.PersonalizedFNTab_2);
            app.Image6_3.ImageClickedFcn = createCallbackFcn(app, @Image6_3Clicked, true);
            app.ParallelComputationTab = uitab(app.ComputeFNTabGroup);
            app.ParallelComputationTab.Title = 'Parallel Computation';
            app.GrayBox1_4 = uilabel(app.ParallelComputationTab);
            app.GrayBox1_4.Text = '';
            app.ComputerCoreEditField = uieditfield(app.ParallelComputationTab, 'numeric');
            app.ComputerCoreEditField.Limits = [2 10000];
            app.ComputerCoreEditField.Editable = 'off';
            app.ComputerCoreEditField.Value = 8;
            app.ThiscomputerLabel = uilabel(app.ParallelComputationTab);
            app.ThiscomputerLabel.Text = 'This computer';
            app.CoreLabel = uilabel(app.ParallelComputationTab);
            app.CoreLabel.Text = 'Core';
            app.ComputerRAMEditField = uieditfield(app.ParallelComputationTab, 'numeric');
            app.ComputerRAMEditField.Limits = [2 10000];
            app.ComputerRAMEditField.Editable = 'off';
            app.ComputerRAMEditField.Value = 16;
            app.GBRAMLabel = uilabel(app.ParallelComputationTab);
            app.GBRAMLabel.Text = 'GB RAM';
            app.GrayBox1_5 = uilabel(app.ParallelComputationTab);
            app.GrayBox1_5.Text = '';
            app.ParallelizationCheckBox = uicheckbox(app.ParallelComputationTab);
            app.ParallelizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParallelizationCheckBoxValueChanged, true);
            app.ParallelizationCheckBox.Text = 'Parallelization';
            app.ofThreadEditFieldLabel = uilabel(app.ParallelComputationTab);
            app.ofThreadEditFieldLabel.Enable = 'off';
            app.ofThreadEditFieldLabel.Text = '# of Thread';
            app.ofThreadEditField = uieditfield(app.ParallelComputationTab, 'numeric');
            app.ofThreadEditField.Limits = [1 10000];
            app.ofThreadEditField.RoundFractionalValues = 'on';
            app.ofThreadEditField.ValueDisplayFormat = '%.0f';
            app.ofThreadEditField.ValueChangedFcn = createCallbackFcn(app, @ofThreadEditFieldValueChanged, true);
            app.ofThreadEditField.Enable = 'off';
            app.ofThreadEditField.Value = 1;
            app.MemoryGBUsageEstimationEditFieldLabel = uilabel(app.ParallelComputationTab);
            app.MemoryGBUsageEstimationEditFieldLabel.Text = 'Memory (GB) Usage Estimation';
            app.MemoryGBUsageEstimationEditField = uieditfield(app.ParallelComputationTab, 'numeric');
            app.MemoryGBUsageEstimationEditField.Limits = [2 10000];
            app.MemoryGBUsageEstimationEditField.Editable = 'off';
            app.MemoryGBUsageEstimationEditField.Value = 10;
            app.LoadNMFResultButton = uibutton(app.FNComputationTab, 'push');
            app.LoadNMFResultButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNMFResultButtonPushed, true);
            app.LoadNMFResultButton.Text = 'Load NMF Result';
            app.GroupFNButton_2 = uibutton(app.FNComputationTab, 'push');
            app.GroupFNButton_2.ButtonPushedFcn = createCallbackFcn(app, @GroupFNButton_2Pushed, true);
            app.GroupFNButton_2.Text = ' Group FN';
            app.PersonalizedFNButton_2 = uibutton(app.FNComputationTab, 'push');
            app.PersonalizedFNButton_2.ButtonPushedFcn = createCallbackFcn(app, @PersonalizedFNButton_2Pushed, true);
            app.PersonalizedFNButton_2.Text = ' Personalized FN';
            app.ParallelComputationButton = uibutton(app.FNComputationTab, 'push');
            app.ParallelComputationButton.ButtonPushedFcn = createCallbackFcn(app, @ParallelComputationButtonPushed, true);
            app.ParallelComputationButton.Text = ' Parallel Computation';
            app.GroupFNTab = uitab(app.TabGroup);
            app.GroupFNTab.Title = 'Group FN';
            app.GroupFNTab.ButtonDownFcn = createCallbackFcn(app, @GroupFNTabButtonDown, true);
            app.UIAxes = uiaxes(app.GroupFNTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Visible = 'off';
            app.SelectAllButton = uibutton(app.GroupFNTab, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Enable = 'off';
            app.SelectAllButton.Text = 'Select All';
            app.FNListBoxLabel = uilabel(app.GroupFNTab);
            app.FNListBoxLabel.Enable = 'off';
            app.FNListBoxLabel.Text = 'FN';
            app.FNListBox = uilistbox(app.GroupFNTab);
            app.FNListBox.Items = {'1', '2', '3', '4'};
            app.FNListBox.Multiselect = 'on';
            app.FNListBox.ValueChangedFcn = createCallbackFcn(app, @FNListBoxValueChanged, true);
            app.FNListBox.Enable = 'off';
            app.FNListBox.Value = {'1'};
            app.Slider = uislider(app.GroupFNTab);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Visible = 'off';
            app.Slider.Value = 1;
            app.SaveFigureButton = uibutton(app.GroupFNTab, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Enable = 'off';
            app.SaveFigureButton.Text = 'Save Figure';
            app.Image4_3 = uiimage(app.GroupFNTab);
            app.Image4_3.ImageClickedFcn = createCallbackFcn(app, @Image4_3Clicked, true);
            app.Image4_3.Enable = 'off';
            app.Slider_3 = uislider(app.GroupFNTab);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.MajorTicks = [];
            app.Slider_3.MajorTickLabels = {};
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.MinorTicks = [];
            app.Slider_3.Visible = 'off';
            app.Slider_3.Value = 1;
            app.InflatedBrainCheckBox = uicheckbox(app.GroupFNTab);
            app.InflatedBrainCheckBox.ValueChangedFcn = createCallbackFcn(app, @InflatedBrainCheckBoxValueChanged, true);
            app.InflatedBrainCheckBox.Visible = 'off';
            app.InflatedBrainCheckBox.Text = 'Inflated Brain';
            app.PlayImage = uiimage(app.GroupFNTab);
            app.PlayImage.ImageClickedFcn = createCallbackFcn(app, @PlayImageClicked, true);
            app.PlayImage.Visible = 'off';
            app.DisplayButtonGroup = uibuttongroup(app.GroupFNTab);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroupSelectionChanged, true);
            app.DisplayButtonGroup.Title = 'Display';
            app.DisplayButtonGroup.Visible = 'off';
            app.View5Button = uiradiobutton(app.DisplayButtonGroup);
            app.View5Button.Text = '5 View';
            app.View5Button.Value = true;
            app.SurfaceInteractiveButton = uiradiobutton(app.DisplayButtonGroup);
            app.SurfaceInteractiveButton.Text = 'Interactive';
            app.DisplayVolumeButtonGroup = uibuttongroup(app.GroupFNTab);
            app.DisplayVolumeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayVolumeButtonGroupSelectionChanged, true);
            app.DisplayVolumeButtonGroup.Title = 'Display';
            app.DisplayVolumeButtonGroup.Visible = 'off';
            app.View3Button = uiradiobutton(app.DisplayVolumeButtonGroup);
            app.View3Button.Text = '3 View';
            app.View3Button.Value = true;
            app.VolumeInteractiveButton = uiradiobutton(app.DisplayVolumeButtonGroup);
            app.VolumeInteractiveButton.Text = 'Interactive';
            app.DefaultViewCenterButton = uibutton(app.GroupFNTab, 'push');
            app.DefaultViewCenterButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultViewCenterButtonPushed, true);
            app.DefaultViewCenterButton.Visible = 'off';
            app.DefaultViewCenterButton.Text = 'Default View Center';
            app.PersonalizedFNTab = uitab(app.TabGroup);
            app.PersonalizedFNTab.Title = 'Personalized FN';
            app.PersonalizedFNTab.ButtonDownFcn = createCallbackFcn(app, @PersonalizedFNTabButtonDown, true);
            app.UIAxes_2 = uiaxes(app.PersonalizedFNTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.UIAxes_2.Visible = 'off';
            app.SelectAllButton_2 = uibutton(app.PersonalizedFNTab, 'push');
            app.SelectAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButton_2Pushed, true);
            app.SelectAllButton_2.Enable = 'off';
            app.SelectAllButton_2.Text = 'Select All';
            app.Slider_2 = uislider(app.PersonalizedFNTab);
            app.Slider_2.Limits = [0 1];
            app.Slider_2.MajorTicks = [];
            app.Slider_2.MajorTickLabels = {};
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.MinorTicks = [];
            app.Slider_2.Visible = 'off';
            app.Slider_2.Value = 1;
            app.SaveFigureButton_2 = uibutton(app.PersonalizedFNTab, 'push');
            app.SaveFigureButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButton_2Pushed, true);
            app.SaveFigureButton_2.Enable = 'off';
            app.SaveFigureButton_2.Text = 'Save Figure';
            app.SubjectListBoxLabel = uilabel(app.PersonalizedFNTab);
            app.SubjectListBoxLabel.Enable = 'off';
            app.SubjectListBoxLabel.Text = 'Subject';
            app.SubjectListBox = uilistbox(app.PersonalizedFNTab);
            app.SubjectListBox.Items = {};
            app.SubjectListBox.ValueChangedFcn = createCallbackFcn(app, @SubjectListBoxValueChanged, true);
            app.SubjectListBox.Enable = 'off';
            app.SubjectListBox.Value = {};
            app.FNListBox_2Label = uilabel(app.PersonalizedFNTab);
            app.FNListBox_2Label.Enable = 'off';
            app.FNListBox_2Label.Text = 'FN';
            app.FNListBox_2 = uilistbox(app.PersonalizedFNTab);
            app.FNListBox_2.Items = {'1', '2', '3', '4'};
            app.FNListBox_2.Multiselect = 'on';
            app.FNListBox_2.ValueChangedFcn = createCallbackFcn(app, @FNListBox_2ValueChanged, true);
            app.FNListBox_2.Enable = 'off';
            app.FNListBox_2.Value = {'1'};
            app.Image4_4 = uiimage(app.PersonalizedFNTab);
            app.Image4_4.ImageClickedFcn = createCallbackFcn(app, @Image4_4Clicked, true);
            app.Image4_4.Enable = 'off';
            app.Slider_4 = uislider(app.PersonalizedFNTab);
            app.Slider_4.Limits = [0 1];
            app.Slider_4.MajorTicks = [];
            app.Slider_4.MajorTickLabels = {};
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.MinorTicks = [];
            app.Slider_4.Visible = 'off';
            app.Slider_4.Value = 1;
            app.PlayImage_2 = uiimage(app.PersonalizedFNTab);
            app.PlayImage_2.ImageClickedFcn = createCallbackFcn(app, @PlayImage_2Clicked, true);
            app.PlayImage_2.Visible = 'off';
            app.DefaultViewCenterButton_2 = uibutton(app.PersonalizedFNTab, 'push');
            app.DefaultViewCenterButton_2.ButtonPushedFcn = createCallbackFcn(app, @DefaultViewCenterButton_2Pushed, true);
            app.DefaultViewCenterButton_2.Visible = 'off';
            app.DefaultViewCenterButton_2.Text = 'Default View Center';
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.UIAxes_3 = uiaxes(app.StatisticsTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.Visible = 'off';
            app.Slider_5 = uislider(app.StatisticsTab);
            app.Slider_5.Limits = [0 1];
            app.Slider_5.MajorTicks = [];
            app.Slider_5.MajorTickLabels = {};
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.MinorTicks = [];
            app.Slider_5.Visible = 'off';
            app.Slider_5.Value = 1;
            app.Slider_6 = uislider(app.StatisticsTab);
            app.Slider_6.Limits = [0 1];
            app.Slider_6.MajorTicks = [];
            app.Slider_6.MajorTickLabels = {};
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.Slider_6.MinorTicks = [];
            app.Slider_6.Visible = 'off';
            app.Slider_6.Value = 1;
            app.PlayImage_3 = uiimage(app.StatisticsTab);
            app.PlayImage_3.ImageClickedFcn = createCallbackFcn(app, @PlayImage_3Clicked, true);
            app.PlayImage_3.Visible = 'off';
            app.StatisticsTabGroup = uitabgroup(app.StatisticsTab);
            app.StatisticsTab_2 = uitab(app.StatisticsTabGroup);
            app.StatisticsTab_2.Title = 'Statistics';
            app.BrowseBehaviorButton = uibutton(app.StatisticsTab_2, 'push');
            app.BrowseBehaviorButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseBehaviorButtonPushed, true);
            app.BrowseBehaviorButton.Enable = 'off';
            app.BrowseBehaviorButton.Text = 'Browse';
            app.TestMethodListBoxLabel = uilabel(app.StatisticsTab_2);
            app.TestMethodListBoxLabel.Enable = 'off';
            app.TestMethodListBoxLabel.Text = 'Test Method';
            app.TestMethodListBox = uilistbox(app.StatisticsTab_2);
            app.TestMethodListBox.Items = {'1 sample ttest', ''};
            app.TestMethodListBox.ValueChangedFcn = createCallbackFcn(app, @TestMethodListBoxValueChanged, true);
            app.TestMethodListBox.Enable = 'off';
            app.TestMethodListBox.Value = '1 sample ttest';
            app.FDRCheckBox = uicheckbox(app.StatisticsTab_2);
            app.FDRCheckBox.ValueChangedFcn = createCallbackFcn(app, @FDRCheckBoxValueChanged, true);
            app.FDRCheckBox.Enable = 'off';
            app.FDRCheckBox.Text = 'FDR';
            app.pvalueEditFieldLabel = uilabel(app.StatisticsTab_2);
            app.pvalueEditFieldLabel.Enable = 'off';
            app.pvalueEditFieldLabel.Text = 'pvalue';
            app.pvalueEditField = uieditfield(app.StatisticsTab_2, 'numeric');
            app.pvalueEditField.Limits = [1e-11 0.99999];
            app.pvalueEditField.ValueDisplayFormat = '%.10f';
            app.pvalueEditField.ValueChangedFcn = createCallbackFcn(app, @pvalueEditFieldValueChanged, true);
            app.pvalueEditField.Enable = 'off';
            app.pvalueEditField.Value = 0.05;
            app.ComputeStatisticsButton = uibutton(app.StatisticsTab_2, 'push');
            app.ComputeStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeStatisticsButtonPushed, true);
            app.ComputeStatisticsButton.Enable = 'off';
            app.ComputeStatisticsButton.Text = 'Compute';
            app.BehaviorFileLabel = uilabel(app.StatisticsTab_2);
            app.BehaviorFileLabel.Text = 'Behavior File';
            app.Image4_5 = uiimage(app.StatisticsTab_2);
            app.BehaviorFileEditField = uieditfield(app.StatisticsTab_2, 'text');
            app.BehaviorFileEditField.ValueChangedFcn = createCallbackFcn(app, @BehaviorFileEditFieldValueChanged, true);
            app.BehaviorFileEditField.Enable = 'off';
            app.TestResultListBoxLabel = uilabel(app.StatisticsTab_2);
            app.TestResultListBoxLabel.Enable = 'off';
            app.TestResultListBoxLabel.Text = 'Test Result';
            app.TestResultListBox = uilistbox(app.StatisticsTab_2);
            app.TestResultListBox.Items = {};
            app.TestResultListBox.ValueChangedFcn = createCallbackFcn(app, @TestResultListBoxValueChanged, true);
            app.TestResultListBox.Enable = 'off';
            app.TestResultListBox.Value = {};
            app.DisplayTab = uitab(app.StatisticsTabGroup);
            app.DisplayTab.Title = 'Display';
            app.SelectAllButton_3 = uibutton(app.DisplayTab, 'push');
            app.SelectAllButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButton_3Pushed, true);
            app.SelectAllButton_3.Enable = 'off';
            app.SelectAllButton_3.Text = 'Select All';
            app.FNListBox_3 = uilistbox(app.DisplayTab);
            app.FNListBox_3.Items = {'1', '2', '3', '4'};
            app.FNListBox_3.Multiselect = 'on';
            app.FNListBox_3.ValueChangedFcn = createCallbackFcn(app, @FNListBox_3ValueChanged, true);
            app.FNListBox_3.Enable = 'off';
            app.FNListBox_3.Value = {'1'};
            app.FNListBox_3Label = uilabel(app.DisplayTab);
            app.FNListBox_3Label.Enable = 'off';
            app.FNListBox_3Label.Text = 'FN';
            app.InflatedBrainCheckBox_2 = uicheckbox(app.DisplayTab);
            app.InflatedBrainCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @InflatedBrainCheckBox_2ValueChanged, true);
            app.InflatedBrainCheckBox_2.Visible = 'off';
            app.InflatedBrainCheckBox_2.Text = 'Inflated Brain';
            app.SaveFigureButton_3 = uibutton(app.DisplayTab, 'push');
            app.SaveFigureButton_3.Enable = 'off';
            app.SaveFigureButton_3.Text = 'Save Figure';
            app.DisplayButtonGroup_2 = uibuttongroup(app.DisplayTab);
            app.DisplayButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroup_2SelectionChanged, true);
            app.DisplayButtonGroup_2.Title = 'Display';
            app.DisplayButtonGroup_2.Visible = 'off';
            app.View5Button_2 = uiradiobutton(app.DisplayButtonGroup_2);
            app.View5Button_2.Text = '5 View';
            app.View5Button_2.Value = true;
            app.InteractiveButton = uiradiobutton(app.DisplayButtonGroup_2);
            app.InteractiveButton.Text = 'Interactive';
            app.DisplayVolumeButtonGroup_2 = uibuttongroup(app.DisplayTab);
            app.DisplayVolumeButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @DisplayVolumeButtonGroup_2SelectionChanged, true);
            app.DisplayVolumeButtonGroup_2.Title = 'Display';
            app.DisplayVolumeButtonGroup_2.Visible = 'off';
            app.View3Button_2 = uiradiobutton(app.DisplayVolumeButtonGroup_2);
            app.View3Button_2.Text = '3 View';
            app.View3Button_2.Value = true;
            app.VolumeInteractiveButton_2 = uiradiobutton(app.DisplayVolumeButtonGroup_2);
            app.VolumeInteractiveButton_2.Text = 'Interactive';
            app.DefaultViewCenterButton_3 = uibutton(app.StatisticsTab, 'push');
            app.DefaultViewCenterButton_3.ButtonPushedFcn = createCallbackFcn(app, @DefaultViewCenterButton_3Pushed, true);
            app.DefaultViewCenterButton_3.Visible = 'off';
            app.DefaultViewCenterButton_3.Text = 'Default View Center';
            app.QualityControlTab = uitab(app.TabGroup);
            app.QualityControlTab.Title = 'Quality Control';
            app.QualityControlTab.ButtonDownFcn = createCallbackFcn(app, @QualityControlTabButtonDown, true);
            app.UIAxes_QC = uiaxes(app.QualityControlTab);
            app.UIAxes_QC.XTick = [];
            app.UIAxes_QC.YTick = [];
            app.UIAxes_QC_2 = uiaxes(app.QualityControlTab);
            app.UIAxes_QC_2.XTick = [];
            app.UIAxes_QC_2.YTick = [];
            app.UIAxes_QC_3 = uiaxes(app.QualityControlTab);
            app.UIAxes_QC_3.XTick = [];
            app.UIAxes_QC_3.YTick = [];
            app.SubjectListBox_2Label = uilabel(app.QualityControlTab);
            app.SubjectListBox_2Label.Enable = 'off';
            app.SubjectListBox_2Label.Text = 'Subject';
            app.SubjectListBox_2 = uilistbox(app.QualityControlTab);
            app.SubjectListBox_2.Items = {};
            app.SubjectListBox_2.ValueChangedFcn = createCallbackFcn(app, @SubjectListBox_2ValueChanged, true);
            app.SubjectListBox_2.Enable = 'off';
            app.SubjectListBox_2.Value = {};
            app.SummaryTextArea_2Label = uilabel(app.QualityControlTab);
            app.SummaryTextArea_2Label.Enable = 'off';
            app.SummaryTextArea_2Label.Text = 'Summary';
            app.SummaryTextArea_2 = uitextarea(app.QualityControlTab);
            app.SummaryTextArea_2.Enable = 'off';
            app.SortSubjectCheckBox = uicheckbox(app.QualityControlTab);
            app.SortSubjectCheckBox.ValueChangedFcn = createCallbackFcn(app, @SortSubjectCheckBoxValueChanged, true);
            app.SortSubjectCheckBox.Enable = 'off';
            app.SortSubjectCheckBox.Text = 'Sort Subject';
            app.SortSubjectCheckBox.Value = true;
            app.GroupFNLabel = uilabel(app.QualityControlTab);
            app.GroupFNLabel.Visible = 'off';
            app.GroupFNLabel.Text = 'Group FN';
            app.PersonalizedFNLabel = uilabel(app.QualityControlTab);
            app.PersonalizedFNLabel.Visible = 'off';
            app.PersonalizedFNLabel.Text = 'Personalized FN';
            app.GroupFNButton = uibutton(app.pNetUIFigure, 'push');
            app.GroupFNButton.ButtonPushedFcn = createCallbackFcn(app, @GroupFNButtonPushed, true);
            app.GroupFNButton.Text = ' Group FN';
            app.PersonalizedFNButton = uibutton(app.pNetUIFigure, 'push');
            app.PersonalizedFNButton.ButtonPushedFcn = createCallbackFcn(app, @PersonalizedFNButtonPushed, true);
            app.PersonalizedFNButton.Text = ' Personalized FN';
            app.StatisticsButton = uibutton(app.pNetUIFigure, 'push');
            app.StatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticsButtonPushed, true);
            app.StatisticsButton.Text = ' Statistics';
            % Show the figure after all components are created
            app.pNetUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pNet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.pNetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.pNetUIFigure)
        end
    end
end